classdef SATMO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Lamp                            matlab.ui.control.Lamp
        RunSATMOButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        AnalysisInputsTab               matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        PlotPanel_AnglesOverTime        matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        SimulationDurationSEditField    matlab.ui.control.NumericEditField
        SimulationDurationLabel         matlab.ui.control.Label
        PrimaryBodyJ2ConstantEditField  matlab.ui.control.NumericEditField
        J2ConstantLabel                 matlab.ui.control.Label
        DarkSideIRPlanetshineHotWm2EditField  matlab.ui.control.NumericEditField
        DarkSideIRPlanetshineWm2Label   matlab.ui.control.Label
        SunSideIRPlanetshineHotWm2EditField  matlab.ui.control.NumericEditField
        SunSideIRPlanetshineWm2Label    matlab.ui.control.Label
        AlbedoHotEditField              matlab.ui.control.NumericEditField
        AlbedoLabel                     matlab.ui.control.Label
        SolarFluxHotWm2EditField        matlab.ui.control.NumericEditField
        SolarFluxLabel                  matlab.ui.control.Label
        PrimaryBodyEquatorInclinationDegEditField  matlab.ui.control.NumericEditField
        EquatorInclinationLabel         matlab.ui.control.Label
        PrimaryBodyMassKgEditField      matlab.ui.control.NumericEditField
        MassLabel_7                     matlab.ui.control.Label
        PrimaryBodyRadiusKmEditField    matlab.ui.control.NumericEditField
        RadiusLabel                     matlab.ui.control.Label
        TimeStepSSpinner                matlab.ui.control.Spinner
        TimeStepLabel                   matlab.ui.control.Label
        OrbitAltitudeKmEditField        matlab.ui.control.NumericEditField
        OrbitAltitudeLabel              matlab.ui.control.Label
        PrimaryBodyLabel                matlab.ui.control.Label
        AnalysisModeButtonGroup         matlab.ui.container.ButtonGroup
        SpecificButton                  matlab.ui.control.RadioButton
        GenericButton                   matlab.ui.control.RadioButton
        PrimaryBodyDropDown             matlab.ui.control.DropDown
        BetaAngleSweepPanel             matlab.ui.container.Panel
        GetEclipseFractionsButton       matlab.ui.control.StateButton
        DummyBox_6                      matlab.ui.control.NumericEditField
        DummyBox_5                      matlab.ui.control.NumericEditField
        degLabel_13                     matlab.ui.control.Label
        degLabel_12                     matlab.ui.control.Label
        degLabel_11                     matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        DummyBox_1                      matlab.ui.control.DatePicker
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        DummyBox_4                      matlab.ui.control.NumericEditField
        DummyBox_3                      matlab.ui.control.NumericEditField
        DummyBox_2                      matlab.ui.control.NumericEditField
        LampEclipseFracsOverBetaAngle   matlab.ui.control.Lamp
        IncrementBetaAngleDegEditField  matlab.ui.control.NumericEditField
        IntervalLabel                   matlab.ui.control.Label
        FinalBetaAngleDegEditField      matlab.ui.control.NumericEditField
        UpperLabel                      matlab.ui.control.Label
        LowerBoundBetaAngleLabel        matlab.ui.control.Label
        InitialBetaAngleDegEditField    matlab.ui.control.NumericEditField
        EquatorInclinationEditFieldLabel_2  matlab.ui.control.Label
        degLabel_6                      matlab.ui.control.Label
        CriticalBetaAngle_deg           matlab.ui.control.NumericEditField
        sLabel_4                        matlab.ui.control.Label
        OrbitAltitudeEditFieldLabel_2   matlab.ui.control.Label
        SatelliteOrbitalPeriod_s        matlab.ui.control.NumericEditField
        kgLabel_3                       matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        Wm2Label_3                      matlab.ui.control.Label
        Wm2Label_2                      matlab.ui.control.Label
        Wm2Label                        matlab.ui.control.Label
        kmLabel_2                       matlab.ui.control.Label
        kmLabel                         matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        SatelliteOrbitalParametersPanel  matlab.ui.container.Panel
        SpecificBetaAngleColdDegEditField  matlab.ui.control.NumericEditField
        ColdLabel_9                     matlab.ui.control.Label
        SpecificBetaAngleHotDegEditField  matlab.ui.control.NumericEditField
        BetaAngleLabel                  matlab.ui.control.Label
        BetaAngleEvolutionSpinner       matlab.ui.control.Spinner
        TrackBetaAngleOverLabel         matlab.ui.control.Label
        RAANEditField                   matlab.ui.control.NumericEditField
        RAANLabel                       matlab.ui.control.Label
        InclinationEditField            matlab.ui.control.NumericEditField
        OrbitInclinationLabel           matlab.ui.control.Label
        EpochUTCDatePicker              matlab.ui.control.DatePicker
        EpochUTCLabel                   matlab.ui.control.Label
        LampBetaEvolution               matlab.ui.control.Lamp
        EpochHourEditField              matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        degLabel_4                      matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        hLabel                          matlab.ui.control.Label
        calendardaysLabel               matlab.ui.control.Label
        degLabel_3                      matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        HotLabel                        matlab.ui.control.Label
        GetBetaEvolutionButton          matlab.ui.control.StateButton
        EpochSecondEditField            matlab.ui.control.NumericEditField
        EpochMinuteEditField            matlab.ui.control.NumericEditField
        DarkSideIRPlanetshineColdWm2EditField  matlab.ui.control.NumericEditField
        SunSideIRPlanetshineColdWm2EditField  matlab.ui.control.NumericEditField
        AlbedoColdEditField             matlab.ui.control.NumericEditField
        ColdLabel_2                     matlab.ui.control.Label
        HotLabel_2                      matlab.ui.control.Label
        SolarFluxColdWm2EditField       matlab.ui.control.NumericEditField
        eclipseFractionPlot             matlab.ui.control.UIAxes
        anglesOverTime_RAANPlot         matlab.ui.control.UIAxes
        anglesOverTime_SunlitOrbitPlot  matlab.ui.control.UIAxes
        SatelliteDataTab                matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        FillMatrixOffDiagonalsLabel     matlab.ui.control.Label
        OffDiagonalsEditField           matlab.ui.control.NumericEditField
        CopyPropertiesfromZenithFacetoRemainingFacesButton  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        ZenithTab                       matlab.ui.container.Tab
        EffectiveSurfacePropertiesPanel_Zenith  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_Zenith  matlab.ui.control.EditField
        RatioEditFieldLabel             matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_Zenith  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel      matlab.ui.control.Label
        RatioEffectiveColdEditField_Zenith  matlab.ui.control.EditField
        ColdLabel_10                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_Zenith  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_Zenith  matlab.ui.control.NumericEditField
        HotLabel_9                      matlab.ui.control.Label
        EmissivityEffectiveHotEditField_Zenith  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_9      matlab.ui.control.Label
        HeaterPropertiesPanel_Zenith    matlab.ui.container.Panel
        GridLayout3_Zenith              matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_Zenith  matlab.ui.control.Switch
        HeaterPowerEditField_Zenith     matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel       matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel  matlab.ui.control.Label
        TempThresholdCLabel             matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_Zenith  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_Zenith  matlab.ui.control.NumericEditField
        SolarPanel_Zenith               matlab.ui.container.Panel
        GridLayout2_Zenith              matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_Zenith  matlab.ui.container.ButtonGroup
        FaceFixedButton_Zenith          matlab.ui.control.RadioButton
        NoneButton_Zenith               matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_Zenith  matlab.ui.control.NumericEditField
        AreaLabel                       matlab.ui.control.Label
        SolarPanelEmissivityEditField_Zenith  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label      matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_Zenith  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label    matlab.ui.control.Label
        SolarPanelEfficiencyEditField_Zenith  matlab.ui.control.NumericEditField
        EfficiencyLabel                 matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SatelliteFacePanel_Zenith       matlab.ui.container.Panel
        GridLayout_Zenith               matlab.ui.container.GridLayout
        AbsorptivityEditField_Zenith    matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel      matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_Zenith  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel          matlab.ui.control.Label
        InitialTemperatureHotCEditField_Zenith  matlab.ui.control.NumericEditField
        InitialTemperatureLabel         matlab.ui.control.Label
        EmissivityEditField_Zenith      matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel        matlab.ui.control.Label
        SpecificHeatJpkgKEditField_Zenith  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel      matlab.ui.control.Label
        Aream2EditField_Zenith          matlab.ui.control.NumericEditField
        AreaEditFieldLabel              matlab.ui.control.Label
        FaceMasskgEditField_Zenith      matlab.ui.control.NumericEditField
        MassLabel                       matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        JkgCLabel                       matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_Zenith  matlab.ui.control.NumericEditField
        ShadowLabel                     matlab.ui.control.Label
        SunlightLabel                   matlab.ui.control.Label
        ColdLabel_3                     matlab.ui.control.Label
        HotLabel_3                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_Zenith  matlab.ui.control.NumericEditField
        NadirTab                        matlab.ui.container.Tab
        EffectiveSurfacePropertiesPanel_Nadir  matlab.ui.container.Panel
        GridLayout7_2                   matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_Nadir  matlab.ui.control.EditField
        RatioEditFieldLabel_12          matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_Nadir  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel_12   matlab.ui.control.Label
        RatioEffectiveColdEditField_Nadir  matlab.ui.control.EditField
        ColdLabel_16                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_Nadir  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_Nadir  matlab.ui.control.NumericEditField
        HotLabel_15                     matlab.ui.control.Label
        EmissivityEffectiveHotEditField_Nadir  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_15     matlab.ui.control.Label
        HeaterPropertiesPanel_Nadir     matlab.ui.container.Panel
        GridLayout3_Nadir               matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_Nadir  matlab.ui.control.Switch
        HeaterPowerEditField_Nadir      matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel_2     matlab.ui.control.Label
        WLabel_4                        matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel_2  matlab.ui.control.Label
        TempThresholdCLabel_2           matlab.ui.control.Label
        CLabel_4                        matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_Nadir  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel_2  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel_2  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_Nadir  matlab.ui.control.NumericEditField
        SolarPanel_Nadir                matlab.ui.container.Panel
        GridLayout2_Nadir               matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_Nadir  matlab.ui.container.ButtonGroup
        FaceFixedButton_Nadir           matlab.ui.control.RadioButton
        NoneButton_Nadir                matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_Nadir  matlab.ui.control.NumericEditField
        AreaLabel_2                     matlab.ui.control.Label
        SolarPanelEmissivityEditField_Nadir  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label_2    matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_Nadir  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label_2  matlab.ui.control.Label
        SolarPanelEfficiencyEditField_Nadir  matlab.ui.control.NumericEditField
        PanelEfficiencyLabel            matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SatelliteFacePanel_Nadir        matlab.ui.container.Panel
        GridLayout_Nadir                matlab.ui.container.GridLayout
        AbsorptivityEditField_Nadir     matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel_2    matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_Nadir  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel_2        matlab.ui.control.Label
        InitialTemperatureHotCEditField_Nadir  matlab.ui.control.NumericEditField
        InitialTemperatureLabel_2       matlab.ui.control.Label
        EmissivityEditField_Nadir       matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel_2      matlab.ui.control.Label
        SpecificHeatJpkgKEditField_Nadir  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel_2    matlab.ui.control.Label
        Aream2EditField_Nadir           matlab.ui.control.NumericEditField
        AreaEditFieldLabel_2            matlab.ui.control.Label
        FaceMasskgEditField_Nadir       matlab.ui.control.NumericEditField
        MassLabel_2                     matlab.ui.control.Label
        WLabel_3                        matlab.ui.control.Label
        CLabel_3                        matlab.ui.control.Label
        JkgCLabel_2                     matlab.ui.control.Label
        m2Label_3                       matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_Nadir  matlab.ui.control.NumericEditField
        ShadowLabel_2                   matlab.ui.control.Label
        SunlightLabel_2                 matlab.ui.control.Label
        ColdLabel_4                     matlab.ui.control.Label
        HotLabel_4                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_Nadir  matlab.ui.control.NumericEditField
        ForwardTab                      matlab.ui.container.Tab
        SatelliteFacePanel_Forward      matlab.ui.container.Panel
        GridLayout_Forward              matlab.ui.container.GridLayout
        AbsorptivityEditField_Forward   matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel_3    matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_Forward  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel_3        matlab.ui.control.Label
        InitialTemperatureHotCEditField_Forward  matlab.ui.control.NumericEditField
        InitialTemperatureLabel_3       matlab.ui.control.Label
        EmissivityEditField_Forward     matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel_3      matlab.ui.control.Label
        SpecificHeatJpkgKEditField_Forward  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel_3    matlab.ui.control.Label
        Aream2EditField_Forward         matlab.ui.control.NumericEditField
        AreaEditFieldLabel_3            matlab.ui.control.Label
        FaceMasskgEditField_Forward     matlab.ui.control.NumericEditField
        MassLabel_3                     matlab.ui.control.Label
        WLabel_5                        matlab.ui.control.Label
        CLabel_5                        matlab.ui.control.Label
        JkgCLabel_3                     matlab.ui.control.Label
        m2Label_5                       matlab.ui.control.Label
        kgLabel_5                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_Forward  matlab.ui.control.NumericEditField
        ShadowLabel_3                   matlab.ui.control.Label
        SunlightLabel_3                 matlab.ui.control.Label
        ColdLabel_5                     matlab.ui.control.Label
        HotLabel_5                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_Forward  matlab.ui.control.NumericEditField
        EffectiveSurfacePropertiesPanel_Forward  matlab.ui.container.Panel
        GridLayout7_3                   matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_Forward  matlab.ui.control.EditField
        RatioEditFieldLabel_13          matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_Forward  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel_13   matlab.ui.control.Label
        RatioEffectiveColdEditField_Forward  matlab.ui.control.EditField
        ColdLabel_17                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_Forward  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_Forward  matlab.ui.control.NumericEditField
        HotLabel_16                     matlab.ui.control.Label
        EmissivityEffectiveHotEditField_Forward  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_16     matlab.ui.control.Label
        HeaterPropertiesPanel_Forward   matlab.ui.container.Panel
        GridLayout3_Forward             matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_Forward  matlab.ui.control.Switch
        HeaterPowerEditField_Forward    matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel_3     matlab.ui.control.Label
        WLabel_6                        matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel_3  matlab.ui.control.Label
        TempThresholdCLabel_3           matlab.ui.control.Label
        CLabel_6                        matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_Forward  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel_3  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel_3  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_Forward  matlab.ui.control.NumericEditField
        SolarPanel_Forward              matlab.ui.container.Panel
        GridLayout2_Forward             matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_Forward  matlab.ui.container.ButtonGroup
        FaceFixedButton_Forward         matlab.ui.control.RadioButton
        NoneButton_Forward              matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_Forward  matlab.ui.control.NumericEditField
        AreaLabel_3                     matlab.ui.control.Label
        SolarPanelEmissivityEditField_Forward  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label_3    matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_Forward  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label_3  matlab.ui.control.Label
        SolarPanelEfficiencyEditField_Forward  matlab.ui.control.NumericEditField
        PanelEfficiencyLabel_2          matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        AftTab                          matlab.ui.container.Tab
        EffectiveSurfacePropertiesPanel_Aft  matlab.ui.container.Panel
        GridLayout7_4                   matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_Aft  matlab.ui.control.EditField
        RatioEditFieldLabel_14          matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_Aft  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel_14   matlab.ui.control.Label
        RatioEffectiveColdEditField_Aft  matlab.ui.control.EditField
        ColdLabel_18                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_Aft  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_Aft  matlab.ui.control.NumericEditField
        HotLabel_17                     matlab.ui.control.Label
        EmissivityEffectiveHotEditField_Aft  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_17     matlab.ui.control.Label
        HeaterPropertiesPanel_Aft       matlab.ui.container.Panel
        GridLayout3_Aft                 matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_Aft  matlab.ui.control.Switch
        HeaterPowerEditField_Aft        matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel_4     matlab.ui.control.Label
        WLabel_8                        matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel_4  matlab.ui.control.Label
        TempThresholdCLabel_4           matlab.ui.control.Label
        CLabel_8                        matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_Aft  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel_4  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel_4  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_Aft  matlab.ui.control.NumericEditField
        SolarPanel_Aft                  matlab.ui.container.Panel
        GridLayout2_Aft                 matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_Aft  matlab.ui.container.ButtonGroup
        FaceFixedButton_Aft             matlab.ui.control.RadioButton
        NoneButton_Aft                  matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_Aft  matlab.ui.control.NumericEditField
        AreaLabel_4                     matlab.ui.control.Label
        SolarPanelEmissivityEditField_Aft  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label_4    matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_Aft  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label_4  matlab.ui.control.Label
        SolarPanelEfficiencyEditField_Aft  matlab.ui.control.NumericEditField
        PanelEfficiencyLabel_3          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        SatelliteFacePanel_Aft          matlab.ui.container.Panel
        GridLayout_Aft                  matlab.ui.container.GridLayout
        AbsorptivityEditField_Aft       matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel_4    matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_Aft  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel_4        matlab.ui.control.Label
        InitialTemperatureHotCEditField_Aft  matlab.ui.control.NumericEditField
        InitialTemperatureLabel_4       matlab.ui.control.Label
        EmissivityEditField_Aft         matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel_4      matlab.ui.control.Label
        SpecificHeatJpkgKEditField_Aft  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel_4    matlab.ui.control.Label
        Aream2EditField_Aft             matlab.ui.control.NumericEditField
        AreaEditFieldLabel_4            matlab.ui.control.Label
        FaceMasskgEditField_Aft         matlab.ui.control.NumericEditField
        MassLabel_4                     matlab.ui.control.Label
        WLabel_7                        matlab.ui.control.Label
        CLabel_7                        matlab.ui.control.Label
        JkgCLabel_4                     matlab.ui.control.Label
        m2Label_7                       matlab.ui.control.Label
        kgLabel_6                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_Aft  matlab.ui.control.NumericEditField
        ShadowLabel_4                   matlab.ui.control.Label
        SunlightLabel_4                 matlab.ui.control.Label
        ColdLabel_6                     matlab.ui.control.Label
        HotLabel_6                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_Aft  matlab.ui.control.NumericEditField
        NorthTab                        matlab.ui.container.Tab
        EffectiveSurfacePropertiesPanel_North  matlab.ui.container.Panel
        GridLayout7_5                   matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_North  matlab.ui.control.EditField
        RatioEditFieldLabel_15          matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_North  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel_15   matlab.ui.control.Label
        RatioEffectiveColdEditField_North  matlab.ui.control.EditField
        ColdLabel_19                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_North  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_North  matlab.ui.control.NumericEditField
        HotLabel_18                     matlab.ui.control.Label
        EmissivityEffectiveHotEditField_North  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_18     matlab.ui.control.Label
        HeaterPropertiesPanel_North     matlab.ui.container.Panel
        GridLayout3_North               matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_North  matlab.ui.control.Switch
        HeaterPowerEditField_North      matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel_5     matlab.ui.control.Label
        WLabel_10                       matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel_5  matlab.ui.control.Label
        TempThresholdCLabel_5           matlab.ui.control.Label
        CLabel_10                       matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_North  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel_5  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel_5  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_North  matlab.ui.control.NumericEditField
        SolarPanel_North                matlab.ui.container.Panel
        GridLayout2_North               matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_North  matlab.ui.container.ButtonGroup
        FaceFixedButton_North           matlab.ui.control.RadioButton
        NoneButton_North                matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_North  matlab.ui.control.NumericEditField
        AreaLabel_5                     matlab.ui.control.Label
        SolarPanelEmissivityEditField_North  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label_5    matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_North  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label_5  matlab.ui.control.Label
        SolarPanelEfficiencyEditField_North  matlab.ui.control.NumericEditField
        PanelEfficiencyLabel_4          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        SatelliteFacePanel_North        matlab.ui.container.Panel
        GridLayout_North                matlab.ui.container.GridLayout
        AbsorptivityEditField_North     matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel_5    matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_North  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel_5        matlab.ui.control.Label
        InitialTemperatureHotCEditField_North  matlab.ui.control.NumericEditField
        InitialTemperatureLabel_5       matlab.ui.control.Label
        EmissivityEditField_North       matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel_5      matlab.ui.control.Label
        SpecificHeatJpkgKEditField_North  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel_5    matlab.ui.control.Label
        Aream2EditField_North           matlab.ui.control.NumericEditField
        AreaEditFieldLabel_5            matlab.ui.control.Label
        FaceMasskgEditField_North       matlab.ui.control.NumericEditField
        MassLabel_5                     matlab.ui.control.Label
        WLabel_9                        matlab.ui.control.Label
        CLabel_9                        matlab.ui.control.Label
        JkgCLabel_5                     matlab.ui.control.Label
        m2Label_9                       matlab.ui.control.Label
        kgLabel_7                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_North  matlab.ui.control.NumericEditField
        ShadowLabel_6                   matlab.ui.control.Label
        SunlightLabel_5                 matlab.ui.control.Label
        ColdLabel_7                     matlab.ui.control.Label
        HotLabel_7                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_North  matlab.ui.control.NumericEditField
        SouthTab                        matlab.ui.container.Tab
        EffectiveSurfacePropertiesPanel_South  matlab.ui.container.Panel
        GridLayout7_6                   matlab.ui.container.GridLayout
        RatioEffectiveHotEditField_South  matlab.ui.control.EditField
        RatioEditFieldLabel_16          matlab.ui.control.Label
        AbsorptivityEffectiveHotEditField_South  matlab.ui.control.NumericEditField
        EffectiveAbsorptivityLabel_16   matlab.ui.control.Label
        RatioEffectiveColdEditField_South  matlab.ui.control.EditField
        ColdLabel_20                    matlab.ui.control.Label
        AbsorptivityEffectiveColdEditField_South  matlab.ui.control.NumericEditField
        EmissivityEffectiveColdEditField_South  matlab.ui.control.NumericEditField
        HotLabel_19                     matlab.ui.control.Label
        EmissivityEffectiveHotEditField_South  matlab.ui.control.NumericEditField
        EffectiveEmissivityLabel_19     matlab.ui.control.Label
        HeaterPropertiesPanel_South     matlab.ui.container.Panel
        GridLayout3_South               matlab.ui.container.GridLayout
        BangBangHeaterControlSwitch_South  matlab.ui.control.Switch
        HeaterPowerEditField_South      matlab.ui.control.NumericEditField
        HeaterPowerEditFieldLabel_6     matlab.ui.control.Label
        WLabel_12                       matlab.ui.control.Label
        BangBangHeaterControlSwitchLabel_6  matlab.ui.control.Label
        TempThresholdCLabel_6           matlab.ui.control.Label
        CLabel_12                       matlab.ui.control.Label
        TemperatureThresholdHeaterONEditField_South  matlab.ui.control.NumericEditField
        BangBangHeaterControlSwitchOFFLabel_6  matlab.ui.control.Label
        BangBangHeaterControlSwitchONLabel_6  matlab.ui.control.Label
        TemperatureThresholdHeaterOFFEditField_South  matlab.ui.control.NumericEditField
        SolarPanel_South                matlab.ui.container.Panel
        GridLayout2_South               matlab.ui.container.GridLayout
        SolarPanelMountingButtonGroup_South  matlab.ui.container.ButtonGroup
        FaceFixedButton_South           matlab.ui.control.RadioButton
        NoneButton_South                matlab.ui.control.RadioButton
        SolarPanelCoveragePercentEditField_South  matlab.ui.control.NumericEditField
        AreaLabel_6                     matlab.ui.control.Label
        SolarPanelEmissivityEditField_South  matlab.ui.control.NumericEditField
        EmissivityEditField_2Label_6    matlab.ui.control.Label
        SolarPanelAbsorptivityEditField_South  matlab.ui.control.NumericEditField
        AbsorptivityEditField_2Label_6  matlab.ui.control.Label
        SolarPanelEfficiencyEditField_South  matlab.ui.control.NumericEditField
        PanelEfficiencyLabel_5          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        SatelliteFacePanel_South        matlab.ui.container.Panel
        GridLayout_South                matlab.ui.container.GridLayout
        AbsorptivityEditField_South     matlab.ui.control.NumericEditField
        AbsorptivityEditFieldLabel_6    matlab.ui.control.Label
        InternalHeatLoadSunlightWEditField_South  matlab.ui.control.NumericEditField
        InternalHeatLoadWLabel_6        matlab.ui.control.Label
        InitialTemperatureHotCEditField_South  matlab.ui.control.NumericEditField
        InitialTemperatureLabel_6       matlab.ui.control.Label
        EmissivityEditField_South       matlab.ui.control.NumericEditField
        EmissivityEditFieldLabel_6      matlab.ui.control.Label
        SpecificHeatJpkgKEditField_South  matlab.ui.control.NumericEditField
        SpecificHeatEditFieldLabel_6    matlab.ui.control.Label
        Aream2EditField_South           matlab.ui.control.NumericEditField
        AreaEditFieldLabel_6            matlab.ui.control.Label
        FaceMasskgEditField_South       matlab.ui.control.NumericEditField
        MassLabel_6                     matlab.ui.control.Label
        WLabel_11                       matlab.ui.control.Label
        CLabel_11                       matlab.ui.control.Label
        JkgCLabel_6                     matlab.ui.control.Label
        m2Label_11                      matlab.ui.control.Label
        kgLabel_8                       matlab.ui.control.Label
        InternalHeatLoadShadowWEditField_South  matlab.ui.control.NumericEditField
        ShadowLabel_5                   matlab.ui.control.Label
        SunlightLabel_6                 matlab.ui.control.Label
        ColdLabel_8                     matlab.ui.control.Label
        HotLabel_8                      matlab.ui.control.Label
        InitialTemperatureColdCEditField_South  matlab.ui.control.NumericEditField
        ContactConductanceMatrixWCPanel  matlab.ui.container.Panel
        ContactConductanceUITable       matlab.ui.control.Table
        HeatFluxesTab                   matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        PlotPanel_TotalHeatFlux_Generic  matlab.ui.container.Panel
        PlotPanel_IndividualHeatFlux_Specific  matlab.ui.container.Panel
        PlotPanel_TotalHeatFlux_Specific  matlab.ui.container.Panel
        TemperaturesTab                 matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        MiniumMaximumSatelliteFaceTemperautresCPanel  matlab.ui.container.Panel
        Table_MinMaxTemp                matlab.ui.control.Table
        PlotPanel_Temperatures          matlab.ui.container.Panel
        SolarPowerTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        PlotPanel_SolarPanelPower_Generic  matlab.ui.container.Panel
        Plot_SolarPanelPower_Specific   matlab.ui.control.UIAxes
    end

    % This material is based upon work supported by the National Science Foundation (NSF) Graduate Research Fellowship Program under Grant No. DGE-2039655.
    % Any opinions, findings, and conclusions or recommendations expressed
    % in this material are those of the author(s) and do not necessarily reflect the views of NSF.
    properties (Access = public)
        ephemStart_UTC % ephemeris start date
        ephemStop_UTC % ephemeris stop date
    end
    methods (Access = public)
        %% Calculations
        function [satOrbit,body,simDuration_s, timeStep_s, specificBetaAngles_deg, genericBetaAngles_deg, sat, solarPanels] = getUserInputs(app)
            % Simulation Data
            satOrbit.analysisType = app.AnalysisModeButtonGroup.SelectedObject.Text;
            simDuration_s = app.SimulationDurationSEditField.Value;
            timeStep_s = app.TimeStepSSpinner.Value;

            % Satellite Data
            satOrbit.body = app.PrimaryBodyDropDown.Value;
            satOrbit.h_m = app.OrbitAltitudeKmEditField.Value * 1000;

            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    genericBetaAngles_deg = app.InitialBetaAngleDegEditField.Value : app.IncrementBetaAngleDegEditField.Value : app.FinalBetaAngleDegEditField.Value;
                    satOrbit.betaAnglesHot_deg = genericBetaAngles_deg;
                    satOrbit.betaAnglesCold_deg = genericBetaAngles_deg; % hot and cold beta angles are equivalent for "generic" Analysis mode
                    specificBetaAngles_deg = [];
                case "SPECIFIC"
                    specificBetaAngles_deg = [app.SpecificBetaAngleHotDegEditField.Value, app.SpecificBetaAngleColdDegEditField.Value];
                    satOrbit.epoch_jd = juliandate(datetime(year(app.EpochUTCDatePicker.Value), month(app.EpochUTCDatePicker.Value), day(app.EpochUTCDatePicker.Value), app.EpochHourEditField.Value, app.EpochMinuteEditField.Value, app.EpochSecondEditField.Value));
                    satOrbit.inc_deg = mod(app.InclinationEditField.Value, 180);
                    satOrbit.RAAN_deg = mod(app.RAANEditField.Value, 360);
                    satOrbit.num24HrPds = app.BetaAngleEvolutionSpinner.Value;
                    satOrbit.betaAnglesHot_deg = app.SpecificBetaAngleHotDegEditField.Value;
                    satOrbit.betaAnglesCold_deg = app.SpecificBetaAngleColdDegEditField.Value;
                    genericBetaAngles_deg = [];
            end
            faces = {'Zenith', 'Nadir','Forward', 'Aft', 'North', 'South'}; % faces to copy to

            for cSet = 1:2
                for j = 1:length(faces)
                    sat(cSet,j).face = faces{j};
                    switch cSet
                        case 1
                            sat(cSet,j).Case = 'Hot';
                            sat(cSet,j).Tinitial_K = app.(['InitialTemperatureHotCEditField_' faces{j}]).Value + 273.15;
                            sat(cSet,j).absorptivity = app.(['AbsorptivityEffectiveHotEditField_' faces{j}]).Value;
                            sat(cSet,j).emissivity =   app.(['EmissivityEffectiveHotEditField_' faces{j}]).Value;
                        case 2
                            sat(cSet,j).Case = 'Cold';
                            sat(cSet,j).Tinitial_K = app.(['InitialTemperatureColdCEditField_' faces{j}]).Value + 273.15;
                            sat(cSet,j).absorptivity = app.(['AbsorptivityEffectiveColdEditField_' faces{j}]).Value;
                            sat(cSet,j).emissivity =   app.(['EmissivityEffectiveColdEditField_' faces{j}]).Value;
                    end
                    sat(cSet,j).m_kg = app.(['FaceMasskgEditField_' faces{j}]).Value;
                    sat(cSet,j).A_m2 = app.(['Aream2EditField_' faces{j}]).Value;
                    sat(cSet,j).C_JpkgK = app.(['SpecificHeatJpkgKEditField_' faces{j}]).Value;

                    sat(cSet,j).qElectronicsSun_W = app.(['InternalHeatLoadSunlightWEditField_' faces{j}]).Value;
                    sat(cSet,j).qElectronicsShadow_W = app.(['InternalHeatLoadShadowWEditField_' faces{j}]).Value;
                    sat(cSet,j).heater_W = app.(['HeaterPowerEditField_' faces{j}]).Value;
                    switch upper(app.(['BangBangHeaterControlSwitch_' faces{j}]).Value)
                        case "ON"
                            sat(cSet,j).includeHeaterControl = 1;
                        case "OFF"
                            sat(cSet,j).includeHeaterControl = 0;
                    end
                    sat(cSet,j).onHeaterTempThreshold_K = app.(['TemperatureThresholdHeaterONEditField_' faces{j}]).Value + 273.15;
                    sat(cSet,j).offHeaterTempThreshold_K = app.(['TemperatureThresholdHeaterOFFEditField_' faces{j}]).Value + 273.15;

                    % Get contact conductance values
                    % the contactCond_WpK field contains a vector of conduction values that one panel has relative to the others in the form [Zenith, Nadir, Forward, Aft, North, South].
                    % For instance, the Zenith panel does not have physically touch itself nor the Nadir panel, but it does touch all other panels.
                    sat(cSet,j).contactCond_WpK = [app.ContactConductanceUITable.Data(j,:)];
                end
            end

            % Solar Panel Data
            for i = 1:length(faces)
                switch upper(app.(['SolarPanelMountingButtonGroup_' faces{i}]).SelectedObject.Text)
                    case "NONE"
                        solarPanels(i).presentOnFace = 0;
                    case "BODY-FIXED"
                        solarPanels(i).presentOnFace = 1;
                end
                solarPanels(i).A_m2 = ((app.(['SolarPanelCoveragePercentEditField_' faces{i}]).Value)/100)*sat(cSet,j).A_m2;
                solarPanels(i).efficiency = app.(['SolarPanelEfficiencyEditField_' faces{i}]).Value;
                solarPanels(i).absorptivity = app.(['SolarPanelAbsorptivityEditField_' faces{i}]).Value;
                solarPanels(i).emissivity = app.(['SolarPanelEmissivityEditField_' faces{i}]).Value;
            end

            % Planet Data
            body.name = app.PrimaryBodyDropDown.Value;
            body.radius_m = app.PrimaryBodyRadiusKmEditField.Value * 1000;
            body.mass_kg = app.PrimaryBodyMassKgEditField.Value;
            body.equatorInc_deg = app.PrimaryBodyEquatorInclinationDegEditField.Value;
            body.J2 = app.PrimaryBodyJ2ConstantEditField.Value;
            body.Gs_Wpm2 = [app.SolarFluxHotWm2EditField.Value, app.SolarFluxColdWm2EditField.Value];
            body.albedo = [app.AlbedoHotEditField.Value, app.AlbedoColdEditField.Value];
            body.IRsunSide_Wpm2 = [app.SunSideIRPlanetshineHotWm2EditField.Value, app.SunSideIRPlanetshineColdWm2EditField.Value];
            body.IRdarkSide_Wpm2 = [app.DarkSideIRPlanetshineHotWm2EditField.Value, app.DarkSideIRPlanetshineColdWm2EditField.Value];
        end

        function getSatOrbitalPeriodAndCritBetaAngle(app)
            G_m3pkgs2 = 6.67430E-11; % Gravitational constant
            app.SatelliteOrbitalPeriod_s.Value = 2*pi*sqrt((((app.PrimaryBodyRadiusKmEditField.Value + app.OrbitAltitudeKmEditField.Value)*1000)^3) / (G_m3pkgs2*app.PrimaryBodyMassKgEditField.Value)); % satellite orbital period
            app.CriticalBetaAngle_deg.Value = asind(app.PrimaryBodyRadiusKmEditField.Value / (app.PrimaryBodyRadiusKmEditField.Value + app.OrbitAltitudeKmEditField.Value));
        end

        function getEffectiveSurfaceProperties(app, face)
            buttonGroup = app.(['SolarPanelMountingButtonGroup_' face]);
            switch upper(buttonGroup.SelectedObject.Text)
                case "NONE"
                    app.(['AbsorptivityEffectiveHotEditField_' face]).Value = app.(['AbsorptivityEditField_' face]).Value;
                    app.(['EmissivityEffectiveHotEditField_' face]).Value   = app.(['EmissivityEditField_' face]).Value;
                    app.(['AbsorptivityEffectiveColdEditField_' face]).Value = app.(['AbsorptivityEditField_' face]).Value;
                    app.(['EmissivityEffectiveColdEditField_' face]).Value   = app.(['EmissivityEditField_' face]).Value;
                case "BODY-FIXED"
                    areaRatio = ((app.(['SolarPanelCoveragePercentEditField_' face]).Value)/100); % of face covered by solar panel
                    if areaRatio < 1
                        app.(['AbsorptivityEffectiveHotEditField_' face]).Value = ((1 - areaRatio) * app.(['AbsorptivityEditField_' face]).Value) + (areaRatio * app.(['SolarPanelAbsorptivityEditField_' face]).Value);
                        app.(['EmissivityEffectiveHotEditField_' face]).Value   = ((1 - areaRatio) * app.(['EmissivityEditField_' face]).Value) + (areaRatio * app.(['SolarPanelEmissivityEditField_' face]).Value);
                        app.(['AbsorptivityEffectiveColdEditField_' face]).Value = ((1 - areaRatio) * app.(['AbsorptivityEditField_' face]).Value) + (areaRatio * (app.(['SolarPanelAbsorptivityEditField_' face]).Value - app.(['SolarPanelEfficiencyEditField_' face]).Value));
                        app.(['EmissivityEffectiveColdEditField_' face]).Value = app.(['EmissivityEffectiveHotEditField_' face]).Value;
                    else
                        app.(['AbsorptivityEffectiveHotEditField_' face]).Value = app.(['SolarPanelAbsorptivityEditField_' face]).Value;
                        app.(['EmissivityEffectiveHotEditField_' face]).Value   = app.(['SolarPanelEmissivityEditField_' face]).Value;
                        app.(['AbsorptivityEffectiveColdEditField_' face]).Value = app.(['SolarPanelAbsorptivityEditField_' face]).Value - app.(['SolarPanelEfficiencyEditField_' face]).Value;
                        app.(['EmissivityEffectiveColdEditField_' face]).Value = app.(['EmissivityEffectiveHotEditField_' face]).Value;
                    end
            end
            app.(['RatioEffectiveHotEditField_' face]).Value = num2str(app.(['AbsorptivityEffectiveHotEditField_' face]).Value / app.(['EmissivityEffectiveHotEditField_' face]).Value);
            app.(['RatioEffectiveColdEditField_' face]).Value = num2str(app.(['AbsorptivityEffectiveColdEditField_' face]).Value / app.(['EmissivityEffectiveColdEditField_' face]).Value);
        end

        function getSolarPanelType(app, face)
            % Enable / disable solar panel inputs
            switch upper(app.(['SolarPanelMountingButtonGroup_' face]).SelectedObject.Text)
                case "NONE"
                    app.(['SolarPanelCoveragePercentEditField_' face]).Enable = 'off';
                    app.(['SolarPanelEfficiencyEditField_' face]).Enable = 'off';
                    app.(['SolarPanelAbsorptivityEditField_' face]).Enable = 'off';
                    app.(['SolarPanelEmissivityEditField_' face]).Enable = 'off';
                case "BODY-FIXED"
                    app.(['SolarPanelCoveragePercentEditField_' face]).Enable = 'on';
                    app.(['SolarPanelEfficiencyEditField_' face]).Enable = 'on';
                    app.(['SolarPanelAbsorptivityEditField_' face]).Enable = 'on';
                    app.(['SolarPanelEmissivityEditField_' face]).Enable = 'on';
            end
            getEffectiveSurfaceProperties(app, face)
        end

        function getAnalysisType(app)
            analysisType = app.AnalysisModeButtonGroup.SelectedObject.Text;
            switch upper(analysisType)
                case "GENERIC"
                    app.BetaAngleSweepPanel.Visible = 'on';
                    app.SatelliteOrbitalParametersPanel.Visible = 'off';
                case "SPECIFIC"
                    app.BetaAngleSweepPanel.Visible = 'off';
                    app.SatelliteOrbitalParametersPanel.Visible = 'on';
            end
        end

        function getHeaterControlState(app, face)
            % Enable / disable temperature controller threshold inputs
            heaterControlState = app.(['BangBangHeaterControlSwitch_' face]).Value;
            switch upper(heaterControlState)
                case 'ON'
                    app.(['TemperatureThresholdHeaterONEditField_' face]).Enable = 'on';
                    app.(['TemperatureThresholdHeaterOFFEditField_' face]).Enable = 'on';
                case 'OFF'
                    app.(['TemperatureThresholdHeaterONEditField_' face]).Enable = 'off';
                    app.(['TemperatureThresholdHeaterOFFEditField_' face]).Enable = 'off';
            end
        end

        function [Fsol_zenith, Falb_zenith, Fsol_nadir, Falb_nadir, Fsol_forward, Falb_forward, Fsol_aft, Falb_aft, Fsol_north, Falb_north, Fsol_south, Falb_south] = getScaleFactors(app, orbitAngleVec_deg, betaAngle_deg, angle1_deg, angle2_deg)
            % Precompute for efficiency
            cosOrbitAngle_deg = cosd(orbitAngleVec_deg);
            sinOrbitAngle_deg = sind(orbitAngleVec_deg);
            cosBetaAngle_deg = cosd(betaAngle_deg);
            sinBetaAngle_deg = sind(betaAngle_deg);
            cosZenithAngle_deg = cosOrbitAngle_deg .* cosBetaAngle_deg;
            zenithAngle_deg = acosd(cosZenithAngle_deg);

            % Initialize outputs as zeros. If the conditions are met below, these values will be overwritten
            Fsol_zenith = zeros(size(orbitAngleVec_deg));
            Fsol_nadir = Fsol_zenith; Fsol_forward = Fsol_zenith; Fsol_aft = Fsol_zenith;
            Fsol_north = Fsol_zenith; Fsol_south = Fsol_zenith;
            Falb_nadir = Fsol_zenith; Falb_forward = Fsol_zenith; Falb_aft = Fsol_zenith;
            Falb_north = Fsol_zenith; Falb_south = Fsol_zenith;
            Falb_zenith = Fsol_zenith; % Always 0

            % Solar scaling factors
            Fsol_zenith(orbitAngleVec_deg <= 90 | orbitAngleVec_deg >= 270) = cosZenithAngle_deg(orbitAngleVec_deg <=90 | orbitAngleVec_deg >= 270);

            Fsol_nadir((90 <= orbitAngleVec_deg & orbitAngleVec_deg <= angle1_deg) | (angle2_deg <= orbitAngleVec_deg & orbitAngleVec_deg <= 270)) = ...
                -cosZenithAngle_deg(( 90 <= orbitAngleVec_deg & orbitAngleVec_deg <= angle1_deg) | (angle2_deg <= orbitAngleVec_deg & orbitAngleVec_deg <= 270));

            Fsol_forward(angle2_deg <= orbitAngleVec_deg & orbitAngleVec_deg <= 360) = ...
                -sinOrbitAngle_deg(angle2_deg <= orbitAngleVec_deg & orbitAngleVec_deg <= 360) .* cosBetaAngle_deg;

            Fsol_aft(0 <= orbitAngleVec_deg & orbitAngleVec_deg <= angle1_deg) = ...
                sinOrbitAngle_deg(0 <= orbitAngleVec_deg & orbitAngleVec_deg <= angle1_deg) .* cosBetaAngle_deg;

            northSouthSolarMask = (0 <= orbitAngleVec_deg & orbitAngleVec_deg <= angle1_deg) | (angle2_deg <= orbitAngleVec_deg & orbitAngleVec_deg <= 360);

            if betaAngle_deg < 0
                Fsol_south(northSouthSolarMask) = abs(sinBetaAngle_deg);
            elseif betaAngle_deg > 0
                Fsol_north(northSouthSolarMask) = abs(sinBetaAngle_deg);
            end

            % Albedo scaling factors
            albedoMask = zenithAngle_deg <= 90 | zenithAngle_deg >= 270;
            Falb_nadir(albedoMask) = cosZenithAngle_deg(albedoMask);
            Falb_forward = Falb_nadir;  Falb_aft = Falb_nadir;
            Falb_north = Falb_nadir;  Falb_south = Falb_nadir;
        end

        function sat = getUpdatedTemp(app, i, j, sat, cSet, faceNum, Fsol, Falb, FF, IR_Wpm2, qElectronicsSunOrShadow_W, Gs_Wpm2, body, sigma_Wpm2K4, Tspace_K, timeStep_s)
            qIR_W = FF * sat(cSet,faceNum).emissivity * sat(cSet,faceNum).A_m2 * IR_Wpm2;
            qSolar_W = Fsol * sat(cSet,faceNum).absorptivity * sat(cSet,faceNum).A_m2 * Gs_Wpm2;
            qAlbedo_W = FF * Falb * sat(cSet,faceNum).absorptivity * body.albedo(cSet) * sat(cSet,faceNum).A_m2 * Gs_Wpm2;

            qOut_W = sigma_Wpm2K4 * sat(cSet,faceNum).A_m2 * sat(cSet,faceNum).emissivity * (sat(cSet,faceNum).tempMat_K(i,j)^4 - Tspace_K^4);
            % Check if heaters need controlled ON or OFF
            if sat(cSet,faceNum).includeHeaterControl == 0
                heaterState = 1; % default to allow sat(cSet,panNum).heater_W to run with constant original user heater input values
            else % perform heater control based on temp thresholds
                if sat(cSet,faceNum).tempMat_K(i,j) <= sat(cSet,faceNum).onHeaterTempThreshold_K
                    heaterState = 1; % turn individual heater ON
                    sat(cSet,faceNum).heaterStateMemory = 1; % store ON state
                elseif sat(cSet,faceNum).tempMat_K(i,j) >= sat(cSet,faceNum).offHeaterTempThreshold_K
                    heaterState = 0; % turn individual heater OFF
                    sat(cSet,faceNum).heaterStateMemory = 0; % store OFF state
                else % control heaters based on memory
                    heaterState = sat(cSet,faceNum).heaterStateMemory; % maintain previous state
                end
            end
            qGen_W = (heaterState*sat(cSet,faceNum).heater_W) + sat(cSet,faceNum).(qElectronicsSunOrShadow_W); %qElectronics made proportional to the area of the panel

            temps_K = [sat(cSet,1).tempMat_K(i,j), sat(cSet,2).tempMat_K(i,j), sat(cSet,3).tempMat_K(i,j), sat(cSet,4).tempMat_K(i,j), sat(cSet,5).tempMat_K(i,j), sat(cSet,6).tempMat_K(i,j)];
            qCond_W = sum(sat(cSet,faceNum).contactCond_WpK .* (temps_K - sat(cSet,faceNum).tempMat_K(i,j)));

            % Advance solution
            sat(cSet,faceNum).tempMat_K(i,j+1) = sat(cSet,faceNum).tempMat_K(i,j) + ((timeStep_s/(sat(cSet,faceNum).m_kg*sat(cSet,faceNum).C_JpkgK))*(qAlbedo_W + qIR_W + qSolar_W + qCond_W + qGen_W - qOut_W));
            % Store info for plotting
            sat(cSet,faceNum).qSolarMat_W(i,j) = qSolar_W; sat(cSet,faceNum).qAlbedoMat_W(i,j) = qAlbedo_W; sat(cSet,faceNum).qIRMat_W(i,j) = qIR_W;
        end

        function RAANdot_degps = getRAANdot(app, satOrbit, body, G_m3pkgs2)
            % Assumes 2-body orbital dynamics
            % Ascending node will precess westward when 0° < satOrbInc_deg < 90°, or
            % eastward when 90° < satOrbInc_deg < 180°. This is taken care of by the
            % cosd() term and the logic that follows.
            % There are assumed to be no nodal precessions for polar orbits
            if 0 < satOrbit.inc_deg && satOrbit.inc_deg < 180
                semimajorAxis_m = body.radius_m + satOrbit.h_m; % for a circular satellite orbit
                semilatusRectum_m = semimajorAxis_m; % p = a(1-e^2) = a for a circular sateliite orbit
                RAANdot_radps = -1.5 * body.J2 * (body.radius_m / (semilatusRectum_m))^2 * cosd(satOrbit.inc_deg) * sqrt((G_m3pkgs2*body.mass_kg) / ((semimajorAxis_m)^3));
                RAANdot_degps = rad2deg(RAANdot_radps);
            elseif satOrbit.inc_deg == 0 || satOrbit.inc_deg == 180
                RAANdot_degps = 0; % RAAN is undefined for equatorial orbits, so nodal precesion term is N/A
            end
        end

        function [satOrbit] = getAnglesOverTime(app, satOrbit,body)
            % Calculate Solar Beta Angle, Eclipse Fraction, and Solar Position as a
            % function of Date. Requires internet connection to access JPL Horizons System

            % Extract Satellite Orbital Data for Code Performance
            h_m = satOrbit.h_m; orbRAAN_deg = satOrbit.RAAN_deg; orbInc_deg = satOrbit.inc_deg; num24HrPds = satOrbit.num24HrPds; epoch_jd = satOrbit.epoch_jd;
            dataInterval_hr = 6; % how often to update data; must be an integer evenly divisible by 24
            orbRAANshift_deg = satOrbit.RAANdot_degps * 3600 * dataInterval_hr;

            % Caclulate Critical Beta Angle
            criticalBetaAngle_deg = asind(body.radius_m / (body.radius_m + h_m));

            %% Set Parameters to Calculate Apparent Solar Longitude from JPL Horizons System https://ssd.jpl.nasa.gov/horizons/
            url = 'https://ssd.jpl.nasa.gov/api/horizons.api';
            switch upper(body.name)
                case 'MERCURY'
                    command = "'199'";
                case 'VENUS'
                    command = "'299'";
                case 'EARTH'
                    command = "'399'";
                case 'MOON'
                    command = "'301'";
                case 'MARS'
                    command = "'499'";
                case 'JUPITER'
                    command = "'599'";
                case 'SATURN'
                    command = "'699'";
                case 'URANUS'
                    command = "'799'";
                case 'NEPTUNE'
                    command = "'899'";
                case 'PLUTO'
                    command = "'999'";

                otherwise
                    error('This type of analysis does not exist for body "%s"',body.name)
            end
            center = "'500@10'"; % The sun
            start_time = sprintf("'JD %.15f'", epoch_jd); % Format correctly with 'JD '
            stop_time = sprintf("'JD %.15f'", epoch_jd + num24HrPds); % For the API to work, stop_time must != start_time
            step_size = sprintf("'%d h'", dataInterval_hr);
            quantities = "'44'"; % Indicator for apparent solar longitude
            ang_format = "'DEG'"; % Ensure angles are in degrees

            %% Fetch Horizons Web Data
            horizonsDataRaw = splitlines(webread(url, 'format', 'text', 'COMMAND', command, 'OBJ_DATA', "NO",...
                'MAKE_EPHEM', "YES", 'EPHEM_TYPE', "OBSERVER", 'CENTER', center,...
                'START_TIME', start_time, 'STOP_TIME', stop_time, 'STEP_SIZE', step_size,...
                'QUANTITIES', quantities, 'ANG_FORMAT', ang_format));

            % Discard unnecessary data
            startEphemIdx = find(strcmp(horizonsDataRaw, '$$SOE'));
            stopEphemIdx = find(strcmp(horizonsDataRaw, '$$EOE'));
            horizonsDataTrimmed = horizonsDataRaw(startEphemIdx + 1 : stopEphemIdx - 1);

            % Initialize and Pre-Allocate Memory
            solLonVec_deg = zeros(1,length(horizonsDataTrimmed));
            solDecVec_deg = solLonVec_deg; betaAngleVec_deg = solLonVec_deg; eclipseFracVec = solLonVec_deg;
            julianEpochVec_jd = solLonVec_deg; solRAVec_deg = solLonVec_deg; orbRAANVec_deg = solLonVec_deg;

            shadowFracVec_zenith = solLonVec_deg;  shadowFracVec_nadir = solLonVec_deg;
            shadowFracVec_forward = solLonVec_deg;  shadowFracVec_aft  = solLonVec_deg;
            shadowFracVec_north = solLonVec_deg;  shadowFracVec_south = solLonVec_deg;

            angleIncrement_deg = 0.01; % choose something small for increased accuracy
            fullOrbitRange_deg = 0 : angleIncrement_deg : 360 - angleIncrement_deg;


            %% Calculate Data Over Time
            for i = 1:length(horizonsDataTrimmed)
                julianEpochVec_jd(i) = epoch_jd;
                orbRAANVec_deg(i) = orbRAAN_deg; % Store data
                splitLine = split(strtrim(horizonsDataTrimmed{i})); % parse Horizons data
                solLonVec_deg(i) = str2double(splitLine{end});

                % JPL defines the value as "The apparent target-centered longitude of the Sun (""apparent L_s"") as seen at the target when the light recorded
                % by the observer at print-time reflected off the target. It is referred to a coordinate system where the x-axis is
                % the equinox direction defined by the target's instantaneous IAU2015 pole direction and heliocentric orbit plane at reflection time,
                % and is measured positively in an eastward direction (counter-clockwise around the positive pole of the solar system angular momentum vector)."

                solRAVec_deg(i) = mod(atan2d(cosd(body.equatorInc_deg)*sind(solLonVec_deg(i)), cosd(solLonVec_deg(i))),360); % Right ascension of the sun https://aa.usno.navy.mil/faq/sun_approx
                solDecVec_deg(i) = body.equatorInc_deg*sind(solRAVec_deg(i)); % Thermal Desktop's approximation for solar declination relative to planet equatorial plane

                % Calculate Solar Beta Angle
                betaAngleVec_deg(i) = asind((cosd(solDecVec_deg(i))*sind(orbInc_deg)*sind(orbRAAN_deg - solRAVec_deg(i))) + (sind(solDecVec_deg(i))*cosd(orbInc_deg)));
                if betaAngleVec_deg(i) < criticalBetaAngle_deg
                    eclipseFracVec(i) = (1/180) .* acosd(sqrt((h_m.^2) + (2.*body.radius_m.*h_m)) ./ ((body.radius_m + h_m).*cosd(betaAngleVec_deg(i)))); % orbit eclipse fraction
                    shadowOrbitEntryAngle_deg = 180 - asind(sqrt((cosd(betaAngleVec_deg(i))^-2) * (((body.radius_m/(body.radius_m+h_m))^2) - sind(betaAngleVec_deg(i))^2))); % true for low, circular orbits. Measured from orbit noon
                    shadowOrbitExitAngle_deg = shadowOrbitEntryAngle_deg + (2*(180-shadowOrbitEntryAngle_deg));
                    angle1_deg = shadowOrbitEntryAngle_deg; % passed into getViewFactor()
                    angle2_deg = shadowOrbitExitAngle_deg;
                else
                    eclipseFracVec(i) = 0; % satellite in constant sunlight
                    angle1_deg = 180; % these allow the conditionals in getViewFactor() to hold when there is no eclipse
                    angle2_deg = 180;
                end

                % Calculate the Fraction of Orbit each FACE Spends in Shadow
                [Fsol_zenith, ~, Fsol_nadir, ~, Fsol_forward, ~, Fsol_aft, ~, Fsol_north, ~, Fsol_south, ~] = getScaleFactors(app, fullOrbitRange_deg, betaAngleVec_deg(i), angle1_deg, angle2_deg);

                shadowFracVec_zenith(i) = getFaceShadowFraction(app, Fsol_zenith);  shadowFracVec_nadir(i) = getFaceShadowFraction(app, Fsol_nadir);
                shadowFracVec_forward(i) = getFaceShadowFraction(app, Fsol_forward);  shadowFracVec_aft(i) = getFaceShadowFraction(app, Fsol_aft);
                shadowFracVec_north(i) = getFaceShadowFraction(app, Fsol_north);  shadowFracVec_south(i) = getFaceShadowFraction(app, Fsol_south);

                % Update Values for Next Iteration
                epoch_jd = epoch_jd + (dataInterval_hr/24); % Update date
                orbRAAN_deg = mod(orbRAAN_deg + orbRAANshift_deg, 360); % Update RAAN per J2 effect. Thermal Desktop does not model J2 effect
            end


            %% Store Data
            satOrbit.dataOverDates.calendarDateVec = datetime(julianEpochVec_jd,'ConvertFrom','juliandate');
            satOrbit.dataOverDates.orbRAANVec_deg = orbRAANVec_deg;
            satOrbit.dataOverDates.solDecVec_deg = solDecVec_deg;
            satOrbit.dataOverDates.solRAVec_deg = solRAVec_deg;
            satOrbit.dataOverDates.betaAngleVec_deg = betaAngleVec_deg;
            satOrbit.dataOverDates.eclipseFracVec = eclipseFracVec;
            satOrbit.dataOverDates.shadowFracVec_zenith = shadowFracVec_zenith;
            satOrbit.dataOverDates.shadowFracVec_nadir = shadowFracVec_nadir;
            satOrbit.dataOverDates.shadowFracVec_forward = shadowFracVec_forward;
            satOrbit.dataOverDates.shadowFracVec_aft = shadowFracVec_aft;
            satOrbit.dataOverDates.shadowFracVec_north = shadowFracVec_north;
            satOrbit.dataOverDates.shadowFracVec_south = shadowFracVec_south;
        end

        function [shadowFrac] = getFaceShadowFraction(app, Fsol) % helper function to calculate the fraction of an orbit that each face spends in shadow
            shadowFrac = sum(Fsol == 0) ./ length(Fsol); % when the solar flux view factor is 0, a face is not in sunlight
        end

        function getPrimaryBodyParams(app, body)
            % Planetary Data mostly from Gilmore spacecraft thermal control handbook, Thermal Desktop, and NASA Planetary Facts Sheets https://nssdc.gsfc.nasa.gov/planetary/factsheet/index.html
            % equatorInc_deg = the axis of body rotation tilt relative to its ecliptic plane (inclination of equator relative to its ecliptic plane)
            bodyData = struct( ...
                'Mercury', struct( ...
                'radius_km', 2440.500, 'mass_kg', 3.301E+23, 'equatorInc_deg', 0.034, ...
                'J2', 0.0000503, 'GsHot_Wm2', 14462, 'GsCold_Wm2', 6278, 'albedoHot', 0.12, ...
                'albedoCold', 0.12, 'IRsunSideHot_Wm2', 12700, 'IRsunSideCold_Wm2', 5500, ...
                'IRdarkSideHot_Wm2', 6, 'IRdarkSideCold_Wm2', 6, 'ephemStart_UTC', datetime(-9998,3,20,0,0,0), 'ephemStop_UTC', datetime(9999,12,31,12,0,0)), ...
                ...
                'Venus', struct( ...
                'radius_km', 6051.800, 'mass_kg', 4.8673E+24, 'equatorInc_deg', 2.64, ...
                'J2', 0.000004458, 'GsHot_Wm2', 2759, 'GsCold_Wm2', 2650, 'albedoHot', 0.82, ...
                'albedoCold', 0.78, 'IRsunSideHot_Wm2', 153, 'IRsunSideCold_Wm2', 153, ...
                'IRdarkSideHot_Wm2', 153, 'IRdarkSideCold_Wm2', 153, 'ephemStart_UTC', datetime(-9998,3,20,0,0,0), 'ephemStop_UTC', datetime(9999,12,31,12,0,0)), ...
                ...
                'Earth', struct( ...
                'radius_km', 6378.137, 'mass_kg', 5.9722E+24, 'equatorInc_deg', 23.44, ...
                'J2', 0.00108263, 'GsHot_Wm2', 1414, 'GsCold_Wm2', 1322, 'albedoHot', 0.4, ...
                'albedoCold', 0.2, 'IRsunSideHot_Wm2', 275, 'IRsunSideCold_Wm2', 218, ...
                'IRdarkSideHot_Wm2', 275, 'IRdarkSideCold_Wm2', 218, 'ephemStart_UTC', datetime(-9998,3,20,0,0,0), 'ephemStop_UTC', datetime(9999,12,31,12,0,0)), ...
                ...
                'Moon', struct( ...
                'radius_km', 1738.100, 'mass_kg', 7.346E+22, 'equatorInc_deg', 1.54, ...
                'J2', 0.0002027, 'GsHot_Wm2', 1421, 'GsCold_Wm2', 1316, 'albedoHot', 0.073, ...
                'albedoCold', 0.073, 'IRsunSideHot_Wm2', 1314, 'IRsunSideCold_Wm2', 1226, ...
                'IRdarkSideHot_Wm2', 5.2, 'IRdarkSideCold_Wm2', 5.2, 'ephemStart_UTC', datetime(-9998,3,20,0,0,0), 'ephemStop_UTC', datetime(9999,12,31,12,0,0)), ...
                ...
                'Mars', struct( ...
                'radius_km', 3396.200, 'mass_kg', 6.4169E+23, 'equatorInc_deg', 25.19, ...
                'J2', 0.00196045, 'GsHot_Wm2', 717, 'GsCold_Wm2', 493, 'albedoHot', 0.29, ...
                'albedoCold', 0.29, 'IRsunSideHot_Wm2', 470, 'IRsunSideCold_Wm2', 315, ...
                'IRdarkSideHot_Wm2', 470, 'IRdarkSideCold_Wm2', 315, 'ephemStart_UTC', datetime(1600,01,01,0,0,0), 'ephemStop_UTC', datetime(2600,1,2,0,0,0)), ...
                ...
                'Jupiter', struct( ...
                'radius_km', 71492.000, 'mass_kg', 1.89813E+27, 'equatorInc_deg', 3.13, ...
                'J2', 0.014736, 'GsHot_Wm2', 56, 'GsCold_Wm2', 46, 'albedoHot', 0.343, ...
                'albedoCold', 0.343, 'IRsunSideHot_Wm2', 13.7, 'IRsunSideCold_Wm2', 13.4, ...
                'IRdarkSideHot_Wm2', 13.7, 'IRdarkSideCold_Wm2', 13.4, 'ephemStart_UTC', datetime(1600,1,1,0,0,0), 'ephemStop_UTC', datetime(2200,1,10,0,0,0)), ...
                ...
                'Saturn', struct( ...
                'radius_km', 60268.000, 'mass_kg', 5.6832E+26, 'equatorInc_deg', 26.73, ...
                'J2', 0.016298, 'GsHot_Wm2', 16.8, 'GsCold_Wm2', 13.6, 'albedoHot', 0.342, ...
                'albedoCold', 0.342, 'IRsunSideHot_Wm2', 4.7, 'IRsunSideCold_Wm2', 4.5, ...
                'IRdarkSideHot_Wm2', 4.7, 'IRdarkSideCold_Wm2', 4.5, 'ephemStart_UTC', datetime(1749,12,30,0,0,0), 'ephemStop_UTC', datetime(2250,1,06,0,0,0)), ...
                ...
                'Uranus', struct( ...
                'radius_km', 25559.000, 'mass_kg', 8.6811E+25, 'equatorInc_deg', 97.77, ...
                'J2', 0.00334343, 'GsHot_Wm2', 4.09, 'GsCold_Wm2', 3.39, 'albedoHot', 0.343, ...
                'albedoCold', 0.343, 'IRsunSideHot_Wm2', 0.72, 'IRsunSideCold_Wm2', 0.55, ...
                'IRdarkSideHot_Wm2', 0.72, 'IRdarkSideCold_Wm2', 0.55, 'ephemStart_UTC', datetime(1600,01,04,0,0,0), 'ephemStop_UTC', datetime(2399,12,17,0,0,0)), ...
                ...
                'Neptune', struct( ...
                'radius_km', 24764.000, 'mass_kg', 1.02409E+26, 'equatorInc_deg', 28.32, ...
                'J2', 0.003411, 'GsHot_Wm2', 1.54, 'GsCold_Wm2', 1.49, 'albedoHot', 0.282, ...
                'albedoCold', 0.282, 'IRsunSideHot_Wm2', 0.52, 'IRsunSideCold_Wm2', 0.52, ...
                'IRdarkSideHot_Wm2', 0.52, 'IRdarkSideCold_Wm2', 0.52, 'ephemStart_UTC', datetime(1600,1,4,0,0,0), 'ephemStop_UTC', datetime(2400,01,02,0,0,0)), ...
                ...
                'Pluto', struct( ...
                'radius_km', 1188.000, 'mass_kg', 1.303E+22, 'equatorInc_deg', 119.51, ...
                'J2', 0.0, 'GsHot_Wm2', 1.56, 'GsCold_Wm2', 0.56, 'albedoHot', 0.47, ...
                'albedoCold', 0.47, 'IRsunSideHot_Wm2', 0.8, 'IRsunSideCold_Wm2', 0.3, ...
                'IRdarkSideHot_Wm2', 0.8, 'IRdarkSideCold_Wm2', 0.3, 'ephemStart_UTC', datetime(1800,01,2,0,0,0), 'ephemStop_UTC', datetime(2199,12,30,0,0,0)));
            populateBody = bodyData.(body);


            app.PrimaryBodyRadiusKmEditField.Value = populateBody.radius_km;
            app.PrimaryBodyMassKgEditField.Value = populateBody.mass_kg;
            app.PrimaryBodyEquatorInclinationDegEditField.Value = populateBody.equatorInc_deg;
            app.PrimaryBodyJ2ConstantEditField.Value = populateBody.J2;
            app.SolarFluxHotWm2EditField.Value = populateBody.GsHot_Wm2;  app.SolarFluxColdWm2EditField.Value = populateBody.GsCold_Wm2;
            app.AlbedoHotEditField.Value = populateBody.albedoHot;    app.AlbedoColdEditField.Value = populateBody.albedoCold;
            app.SunSideIRPlanetshineHotWm2EditField.Value = populateBody.IRsunSideHot_Wm2;    app.SunSideIRPlanetshineColdWm2EditField.Value = populateBody.IRsunSideCold_Wm2;
            app.DarkSideIRPlanetshineHotWm2EditField.Value = populateBody.IRdarkSideHot_Wm2;  app.DarkSideIRPlanetshineColdWm2EditField.Value = populateBody.IRdarkSideCold_Wm2;
            app.ephemStart_UTC.Value = populateBody.ephemStart_UTC;  app.ephemStop_UTC.Value = populateBody.ephemStop_UTC;
        end

        %% Plots
        function plotEclipseFractionOverBetaAngle(app, satOrbit, body, lw)
            ax = app.eclipseFractionPlot;
            betaAngles_deg = -90:0.01:90;
            eclipseFracs = (1/180) .* acosd(sqrt((satOrbit.h_m.^2) + (2.*[body.radius_m].*satOrbit.h_m)) ./ (([body.radius_m] + satOrbit.h_m) .* cosd(betaAngles_deg))); % eclipse Fraction

            defColor = get(groot, 'defaultAxesXColor'); % change color based on system settings
            plot(ax,betaAngles_deg, real(eclipseFracs), 'LineWidth', lw, 'Color', defColor);
            grid(ax,"on")
            xlabel(ax, 'Beta Angle (deg)'); xlim(ax, [-90,90]); set(ax, 'xtick', -90:15:90)
            ylabel(ax, 'Eclipse Fraction'); ylim(ax, [0, 0.5])
            title(ax, sprintf('%.2f-km Circular %s Orbit', satOrbit.h_m/1000, satOrbit.body));
            app.eclipseFractionPlot.Visible = "on";
        end

        function plotAnglesOverTime(app, sat, satOrbit, markerOrLineSize)
            %% Extract Necessary Data
            calendarDateVec = satOrbit.dataOverDates.calendarDateVec; % dates on which the analysis is conducted
            orbRAANVec_deg = satOrbit.dataOverDates.orbRAANVec_deg;
            solDecVec_deg = satOrbit.dataOverDates.solDecVec_deg;
            solRAVec_deg = satOrbit.dataOverDates.solRAVec_deg;
            betaAngleVec_deg = satOrbit.dataOverDates.betaAngleVec_deg;
            eclipseFracVec = satOrbit.dataOverDates.eclipseFracVec;
            faceNames = {'zenith', 'nadir', 'forward', 'aft', 'north','south'};
            orangeColor = [0.8500, 0.3250, 0.0980];
            defColor = get(groot, 'defaultAxesXColor'); % light / dark mode compatability

            if isscalar(betaAngleVec_deg) % one-day analysis
                lineStyle1 = {'Color', defColor, 'MarkerStyle', '*', 'markerSize', markerOrLineSize};
                lineStyle2 = {'Color', defColor, 'MarkerStyle', 's', 'markerSize', markerOrLineSize};
                lineStyle3 = {'Color', orangeColor, 'markerSize', markerOrLineSize};

            else % multi-day analysis
                lineStyle1 = {'Color', defColor, 'LineWidth', markerOrLineSize};
                lineStyle2 = {'Color', defColor, 'LineStyle', '--', 'LineWidth', markerOrLineSize};
                lineStyle3 = {'Color', orangeColor, 'LineWidth',  markerOrLineSize};
            end


            %% Beta Angle and % of Orbit in Sunlight Over Time
            t = tiledlayout(app.PlotPanel_AnglesOverTime, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
            app.PlotPanel_AnglesOverTime.Title = sprintf('%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°', satOrbit.h_m/1000, satOrbit.body, satOrbit.inc_deg, satOrbit.RAAN_deg);
            for i = [1 3 5 2 4 6]
                ax_a(i) = nexttile(t);
                fieldname = ['shadowFracVec_' faceNames{i}];
                yyaxis(ax_a(i), 'left');
                p1 = plot(ax_a(i), calendarDateVec, betaAngleVec_deg, lineStyle1{:});
                ylabel(ax_a(i), 'Beta Angle (deg)');
                ylim(ax_a(i), [-90 90]); yticks(ax_a(i), -90:15:90);
                ax_a(i).YColor = get(groot,'defaultAxesYColor');

                yyaxis(ax_a(i), 'right');
                p2 = plot(ax_a(i), calendarDateVec, (real(1 - satOrbit.dataOverDates.(fieldname))), lineStyle3{:});
                ylabel(ax_a(i), 'Sunlight Fraction');
                ylim(ax_a(i), [0 1]); yticks(ax_a(i), 0:0.1:1);
                ax_a(i).YColor = orangeColor;

                xlim(ax_a(i), [calendarDateVec(1) calendarDateVec(end)]);
                title(ax_a(i), sprintf(sat(1, i).face));

                if i == 3
                    legend(ax_a(i), [p1, p2], {'Beta Angle', 'Sunlit Panel'}, 'Location', 'northoutside', 'Orientation', 'horizontal')
                end
                grid(ax_a(i), 'on')
            end
            app.PlotPanel_AnglesOverTime.Visible = "on";

            %% Satellite RAAN and Solar Right Ascension
            ax_b = app.anglesOverTime_RAANPlot;
            plot(ax_b, calendarDateVec, orbRAANVec_deg, lineStyle1{:}); hold(ax_b, 'on')
            plot(ax_b, calendarDateVec, solRAVec_deg, lineStyle2{:});
            if ~isscalar(betaAngleVec_deg)
                xlim(ax_b, [calendarDateVec(1) calendarDateVec(end)]);
            end
            grid(ax_b, 'on')
            ylabel(ax_b, 'Angle (deg)'); ylim(ax_b, [0 360]); yticks(ax_b, 0:30:360); ytickformat(ax_b, '%.2f');
            legend(ax_b, {'Satellite Orbit RAAN','Solar Right Ascension'}, 'Location', 'northoutside', 'Orientation', 'horizontal');
            hold(ax_b, 'off')
            app.anglesOverTime_RAANPlot.Visible = "on";

            %% Beta Angle, Solar Declination, and % of Orbit in Sunlight
            ax_c = app.anglesOverTime_SunlitOrbitPlot;
            yyaxis(ax_c, 'left')
            plot(ax_c, calendarDateVec, betaAngleVec_deg, lineStyle1{:}); hold(ax_c, 'on')
            plot(ax_c, calendarDateVec, solDecVec_deg, lineStyle2{:})
            ylabel(ax_c, 'Angle (deg)'); ylim(ax_c, [-90 90]); yticks(ax_c, -90:15:90);
            hold(ax_c, 'off')
            set(ax_c, 'YColor', defColor)
            yyaxis(ax_c, 'right')
            plot(ax_c, calendarDateVec, (real(1-eclipseFracVec)), lineStyle3{:})
            if ~isscalar(betaAngleVec_deg)
                xlim(ax_c, [calendarDateVec(1) calendarDateVec(end)]);
            end
            ylabel(ax_c, 'Sunlight Fraction'); ylim(ax_c, [0 1]); yticks(ax_c, 0:0.1:1);
            set(ax_c, 'YColor', orangeColor)
            legend(ax_c, {'Beta Angle','Solar Declination','Orbit'}, 'Location', 'northoutside', 'Orientation', 'horizontal')
            grid(ax_c, 'on')
            app.anglesOverTime_SunlitOrbitPlot.Visible = "on";
        end

        function plotAbsorbedHeatFlux(app, sat, satOrbit, specificBetaAngles_deg, betaAngles_deg, numContourLevels, lw)
            maskFirstOrbit = 0 <= satOrbit.angularPositions_deg & satOrbit.angularPositions_deg <= 360;

            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    %% Total Incident Heat Flux Absorbed
                    t = tiledlayout(app.PlotPanel_TotalHeatFlux_Generic, 4, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
                    app.PlotPanel_TotalHeatFlux_Generic.Title = sprintf('Net Heat Flux Absorbed from Solar + Albedo + IR Planetshine Contributions (%.2f-km Circular %s Orbit)', satOrbit.h_m/1000, satOrbit.body);

                    % Set Axis Limits
                    qMin_Wpm2 = inf;
                    qMax_Wpm2 = -inf;
                    for cSet = 1:2
                        for i = 1:6
                            qTotMin_Wpm2 = min(min((sat(cSet,i).qSolarMat_W(:,maskFirstOrbit) + sat(cSet,i).qAlbedoMat_W(:,maskFirstOrbit) + sat(cSet,i).qIRMat_W(:,maskFirstOrbit)) ./ sat(cSet,i).A_m2));
                            qTotMax_Wpm2 = max(max((sat(cSet,i).qSolarMat_W(:,maskFirstOrbit) + sat(cSet,i).qAlbedoMat_W(:,maskFirstOrbit) + sat(cSet,i).qIRMat_W(:,maskFirstOrbit)) ./ sat(cSet,i).A_m2));

                            % Update global min/max
                            qMin_Wpm2 = min([qMin_Wpm2, qTotMin_Wpm2]);
                            qMax_Wpm2 = max([qMax_Wpm2, qTotMax_Wpm2]);
                        end
                    end

                    contourLevels = linspace(qMin_Wpm2,qMax_Wpm2,numContourLevels);
                    cSet = [1 1 1 2 2 2 1 1 1 2 2 2];
                    tile = [1 3 5 1 3 5 2 4 6 2 4 6];
                    for i = 1:length(tile)
                        switch cSet(i)
                            case 1
                                caseType = "(Hot)";
                            case 2
                                caseType = "(Cold)";
                        end
                        ax(tile(i)) = nexttile(t);
                        qTot_Wpm2 = (sat(cSet(i),tile(i)).qSolarMat_W(:,maskFirstOrbit) + sat(cSet(i),tile(i)).qAlbedoMat_W(:,maskFirstOrbit) + sat(cSet(i),tile(i)).qIRMat_W(:,maskFirstOrbit)) ./ sat(cSet(i),tile(i)).A_m2;
                        %contourf(ax(tile(i)), satOrbit.angularPositions_deg(maskFirstOrbit), betaAngles_deg, qTot_Wpm2,'LineStyle','none');
                        contourf(ax(tile(i)), satOrbit.angularPositions_deg(maskFirstOrbit), betaAngles_deg, qTot_Wpm2, contourLevels,'LineStyle','none'); % This adds custom contour levels

                        clim(ax(tile(i)),[qMin_Wpm2 qMax_Wpm2]);
                        xlim(ax(tile(i)), [0, 360]); xticks(ax(tile(i)), 0:45:360);
                        ylim(ax(tile(i)), [min(betaAngles_deg) max(betaAngles_deg)]);
                        yticks(ax(tile(i)), linspace(min(betaAngles_deg), max(betaAngles_deg), 7))
                        title(ax(tile(i)), sprintf(sat(cSet(i),tile(i)).face + " " + caseType))
                        grid(ax(tile(i)), 'on')

                        % Only label x-axis for bottom row of plots
                        if i == 10 || i == 11 || i == 12
                            xlabel(ax(tile(i)), 'Orbit Angle (deg)');
                        end
                        % Only label y-axis for left column of plots
                        if  i == 1 || i == 4 || i == 7 || i == 10
                            ylabel(ax(tile(i)), 'Beta Angle (deg)');
                        end
                        colormap(ax(tile(i)), jet);

                        if i == 2
                            cb = colorbar(ax(tile(i)));
                            cb.Location = 'northoutside';
                            set(cb, 'Ticks', linspace(qMin_Wpm2, qMax_Wpm2, 5));
                            cb.TickLabels = arrayfun(@(x) sprintf('%.2f', x), cb.Ticks, 'UniformOutput', false);
                            cb.Label.String = 'Net Absorbed Heat Flux (W/m^2)';
                        end
                    end
                    app.PlotPanel_TotalHeatFlux_Generic.Visible = "on";


                case "SPECIFIC"
                    lineStyle1 = {'-','LineWidth',lw}; lineStyle2 = {'--','LineWidth',lw}; lineStyle3 = {':','LineWidth',lw};
                    hotColor = [1 0 0]; % red
                    coldColor = [0 0 1]; % blue
                    caseType1 = sprintf(' (β =  %.2f°)',specificBetaAngles_deg(1));
                    caseType2 = sprintf(' (β =  %.2f°)',specificBetaAngles_deg(2));

                    %% Individual Heat Flux Absorbed
                    ta = tiledlayout(app.PlotPanel_IndividualHeatFlux_Specific, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
                    app.PlotPanel_IndividualHeatFlux_Specific.Title = sprintf('Net Heat Flux Absorbed (%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°)',satOrbit.h_m/1000,satOrbit.body,satOrbit.inc_deg,satOrbit.RAAN_deg);
                    for i = [1 3 5 2 4 6]
                        ax_a(i) = nexttile(ta);
                        p1 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(1,i).qSolarMat_W(:,maskFirstOrbit)/sat(1,i).A_m2, lineStyle1{:}, 'Color', hotColor); hold(ax_a(i), 'on')
                        p2 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(1,i).qAlbedoMat_W(:,maskFirstOrbit)/sat(1,i).A_m2, lineStyle2{:}, 'Color', hotColor);
                        p3 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(1,i).qIRMat_W(:,maskFirstOrbit)/sat(1,i).A_m2, lineStyle3{:}, 'Color', hotColor);

                        p4 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(2,i).qSolarMat_W(:,maskFirstOrbit)/sat(2,i).A_m2, lineStyle1{:}, 'Color', coldColor);
                        p5 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(2,i).qAlbedoMat_W(:,maskFirstOrbit)/sat(2,i).A_m2, lineStyle2{:}, 'Color', coldColor);
                        p6 = plot(ax_a(i), satOrbit.angularPositions_deg(maskFirstOrbit), sat(2,i).qIRMat_W(:,maskFirstOrbit)/sat(2,i).A_m2, lineStyle3{:}, 'Color', coldColor);

                        xlim(ax_a(i), [0,360]); xticks(ax_a(i), 0:90:360);
                        title(ax_a(i), sprintf(sat(1,i).face))

                        if i == 2 || i == 4 || i == 6
                            xlabel(ax_a(i), 'Orbit Angle (deg)')
                        end
                        if i == 1 || i == 2
                            ylabel(ax_a(i), 'Net Absorbed Heat Flux (W/m^2)')
                        end
                        set(ax_a(i), 'XMinorTick', 'on', 'YMinorTick', 'on', 'TickDir', 'out');
                        grid(ax_a(i), 'on')

                        if i == 3
                            legend(ax_a(i), [p1 p2 p3 p4 p5 p6], {['Solar' caseType1], ['Albedo' caseType1], ['IR Planetshine' caseType1], ['Solar' caseType2], ['Albedo' caseType2], ['IR Planetshine' caseType2]}, 'Location', 'northoutside', 'Orientation', 'horizontal', 'NumColumns', 3);
                        end
                    end

                    linkaxes(ax_a, 'xy');
                    app.PlotPanel_IndividualHeatFlux_Specific.Visible = "on";


                    %% Total Incident Heat Flux Absorbed
                    tb = tiledlayout(app.PlotPanel_TotalHeatFlux_Specific, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
                    app.PlotPanel_TotalHeatFlux_Specific.Title = sprintf('Net Heat Flux Absorbed from Solar + Albedo + IR Planetshine Contributions (%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°)',satOrbit.h_m/1000,satOrbit.body,satOrbit.inc_deg,satOrbit.RAAN_deg);

                    for i = [1 3 5 2 4 6] % [Zenith, Nadir, Forward, Aft, North, South]
                        ax_b(i) = nexttile(tb);
                        qIncidentTotalHot_Wpm2 = (sat(1,i).qSolarMat_W(:,maskFirstOrbit) + sat(1,i).qAlbedoMat_W(:,maskFirstOrbit) + sat(1,i).qIRMat_W(:,maskFirstOrbit)) ./ sat(1,i).A_m2;
                        qIncidentTotalCold_Wpm2 = (sat(2,i).qSolarMat_W(:,maskFirstOrbit) + sat(2,i).qAlbedoMat_W(:,maskFirstOrbit) + sat(2,i).qIRMat_W(:,maskFirstOrbit)) ./ sat(2,i).A_m2;

                        p1 = plot(ax_b(i), satOrbit.angularPositions_deg(maskFirstOrbit), qIncidentTotalHot_Wpm2, lineStyle1{:}, 'Color', hotColor); hold(ax_b(i),'on')
                        p2 = plot(ax_b(i), satOrbit.angularPositions_deg(maskFirstOrbit), qIncidentTotalCold_Wpm2, lineStyle1{:}, 'Color', coldColor);
                        xlim(ax_b(i), [0,360]); xticks(ax_b(i), 0:90:360);
                        title(ax_b(i), sprintf(sat(1,i).face))

                        if i == 2 || i == 4 || i == 6
                            xlabel(ax_b(i), 'Orbit Angle (deg)')
                        end
                        if i == 1 || i == 2
                            ylabel(ax_b(i), 'Net Absorbed Heat Flux (W/m^2)')
                        end
                        if i == 3
                            legend(ax_b(i), [p1 p2], {['Solar + Albedo + IR' caseType1],['Solar + Albedo + IR' caseType2]},'Location', 'northoutside', 'Orientation', 'vertical');
                        end
                        set(ax_b(i), 'XMinorTick', 'on', 'YMinorTick', 'on', 'TickDir', 'out');
                        grid(ax_b(i), 'on')
                    end

                    linkaxes(ax_b, 'xy');

                    app.PlotPanel_TotalHeatFlux_Specific.Visible = "on";

            end
        end

        function plotTemps(app, sat, satOrbit, specificBetaAngles_deg, betaAngles_deg, numContourLevels, lw)

            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    app.PlotPanel_Temperatures.Title = sprintf('Satellite Face Temperatures (%.2f-km Circular %s Orbit)', satOrbit.h_m/1000, satOrbit.body);
                    t = tiledlayout(app.PlotPanel_Temperatures, 4, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
                    minTemp_C = min(min([sat.tempMat_K])) - 273.15;
                    maxTemp_C = max(max([sat.tempMat_K])) - 273.15;
                    contourLevels = linspace(minTemp_C,maxTemp_C,numContourLevels);

                    cSet = [1 1 1 2 2 2 1 1 1 2 2 2];
                    tile = [1 3 5 1 3 5 2 4 6 2 4 6];
                    for i = 1:length(tile)
                        switch cSet(i)
                            case 1
                                caseType = "(Hot)";
                            case 2
                                caseType = "(Cold)";
                        end
                        ax(tile(i)) = nexttile(t);
                        contourf(ax(tile(i)),satOrbit.times_s / 3600, betaAngles_deg, sat(cSet(i),tile(i)).tempMat_K - 273.15, contourLevels, 'LineStyle', 'none');

                        clim(ax(tile(i)), [minTemp_C maxTemp_C]);
                        xlim(ax(tile(i)), [0, satOrbit.times_s(end) /3600]);
                        ylim(ax(tile(i)), [min(betaAngles_deg) max(betaAngles_deg)]); yticks(ax(tile(i)), min(betaAngles_deg):30:max(betaAngles_deg))
                        title(ax(tile(i)), sprintf(sat(cSet(i),tile(i)).face + " " + caseType))
                        grid(ax(tile(i)), 'on')

                        % Only label x-axis for bottom row of plots
                        if i == 10 || i == 11 || i == 12
                            xlabel(ax(tile(i)), 'Time (hr)');
                        end
                        % Only label y-axis for left column of plots
                        if  i == 1 || i == 4 || i == 7 || i == 10
                            ylabel(ax(tile(i)), 'Beta Angle (deg)');
                        end
                        colormap(ax(tile(i)), jet);

                        if i == 2
                            cb = colorbar(ax(tile(i)));
                            cb.Location = 'northoutside';
                            set(cb, 'Ticks', linspace(minTemp_C, maxTemp_C, 5));
                            cb.TickLabels = arrayfun(@(x) sprintf('%.2f', x), cb.Ticks, 'UniformOutput', false);
                            cb.Label.String = 'Temperature (°C)';
                        end
                    end


                case "   "
                    app.PlotPanel_Temperatures.Title = sprintf('%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°',satOrbit.h_m/1000,satOrbit.body,satOrbit.inc_deg,satOrbit.RAAN_deg);
                    t = tiledlayout(app.PlotPanel_Temperatures, 2, 3, 'TileSpacing', 'compact', 'Padding', 'compact');
                    caseType1 = sprintf('β = %.2f°', specificBetaAngles_deg(1));
                    ls1 = {'LineWidth', lw, 'color', 'r'};
                    caseType2 = sprintf('β = %.2f°', specificBetaAngles_deg(2));
                    ls2 = {'LineWidth', lw, 'color', 'b'};

                    for i = [1 3 5 2 4 6]
                        ax(i) = nexttile(t);
                        p1 = plot(ax(i), satOrbit.times_s / 3600, sat(1, i).tempMat_K - 273.15, ls1{:});
                        hold(ax(i), 'on');
                        p2 = plot(ax(i), satOrbit.times_s / 3600, sat(2, i).tempMat_K - 273.15, ls2{:});
                        title(ax(i), sprintf(sat(1,i).face))
                        xlim(ax(i), [0, satOrbit.times_s(end)/3600]);
                        grid(ax(i), 'on');

                        if i == 2 || i == 4 || i == 6
                            xlabel(ax(i), 'Time (hr)')
                        end
                        if i == 1 || i == 2
                            ylabel(ax(i), 'Temperature (°C)')
                        end
                        if i == 3
                            legend(ax(i), [p1 p2], caseType1, caseType2,'Location', 'northoutside', 'Orientation', 'horizontal')
                        end
                    end
                    linkaxes(ax, 'xy');


                case "SPECIFIC"
                    app.PlotPanel_Temperatures.Title = sprintf('Satellite Face Temperatures (%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°)',satOrbit.h_m/1000,satOrbit.body,satOrbit.inc_deg,satOrbit.RAAN_deg);
                    t = tiledlayout(app.PlotPanel_Temperatures, 2, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
                    caseType1 = sprintf('β = %.2f°', specificBetaAngles_deg(1));
                    ls1 = {'LineWidth', lw};
                    caseType2 = sprintf('β = %.2f°', specificBetaAngles_deg(2));

                    for cSet = 1:2
                        switch cSet
                            case 1
                                caseType = caseType1;
                            case 2
                                caseType = caseType2;
                        end
                        ax(cSet) = nexttile(t);

                        p1 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 1).tempMat_K - 273.15, ls1{:});
                        hold(ax(cSet), 'on');
                        p2 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 2).tempMat_K - 273.15, ls1{:});
                        p3 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 3).tempMat_K - 273.15, ls1{:});
                        p4 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 4).tempMat_K - 273.15, ls1{:});
                        p5 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 5).tempMat_K - 273.15, ls1{:});
                        p6 = plot(ax(cSet), satOrbit.times_s / 3600, sat(cSet, 6).tempMat_K - 273.15, ls1{:});

                        title(ax(cSet), caseType);
                        xlim(ax(cSet), [0, satOrbit.times_s(end)/3600]);
                        ylabel(ax(cSet), 'Temperature (°C)')
                        grid(ax(cSet), 'on');

                        if cSet == 2
                            xlabel(ax(cSet), 'Time (hr)')
                        end
                        if cSet == 1
                            panelNames = {sat(1,:).face};
                            legend(ax(cSet), [p1 p2 p3 p4 p5 p6], panelNames{:}, 'Location', 'northoutside', 'Orientation', 'horizontal')
                        end
                    end
                    linkaxes(ax, 'xy');
            end
            app.PlotPanel_Temperatures.Visible = "on";
        end

        function plotSolarPanelPower(app, solarPanels, sat, satOrbit, betaAngles_deg, specificBetaAngles_deg, numContourLevels, lw)
            lineStyle = {'-', 'LineWidth', lw};
            hotColor = [1 0 0]; % red
            coldColor = [0 0 1]; % blue

            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    plotTitle = sprintf('Electrical Power Output by Solar Panels (%.2f-km Circular %s Orbit)', satOrbit.h_m/1000, satOrbit.body);
                    solarPowerHot_W = zeros(length(betaAngles_deg),1); % initilization
                    solarPowerCold_W = zeros(length(betaAngles_deg),1);
                case "SPECIFIC"
                    plotTitle = sprintf('Electrical Power Output by Solar Panels (%.2f-km Circular %s Orbit, i = %.2f°, Ω₀ = %.2f°)',satOrbit.h_m/1000,satOrbit.body,satOrbit.inc_deg,satOrbit.RAAN_deg);
                    caseType1 = sprintf('β =  %.2f°',specificBetaAngles_deg(1));
                    caseType2 = sprintf('β =  %.2f°',specificBetaAngles_deg(2));
                    solarPowerHot_W = 0; % initilization
                    solarPowerCold_W = 0;
            end

            for i = 1:6
                solarPowerHot_W = solarPowerHot_W + (solarPanels(i).presentOnFace * ((sat(1,i).qSolarMat_W./(sat(1,i).A_m2*sat(1,i).absorptivity)) * solarPanels(i).A_m2 * solarPanels(i).efficiency));
                solarPowerCold_W = solarPowerCold_W + (solarPanels(i).presentOnFace * ((sat(2,i).qSolarMat_W./(sat(2,i).A_m2*sat(2,i).absorptivity)) * solarPanels(i).A_m2 * solarPanels(i).efficiency));
                % values are corrected by dividing out the original face area and original face (effective) absorptibity values (measured with respect to the entire surface values)
            end


            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    minSolarPower_W = min([min(min(solarPowerHot_W)) min(min(solarPowerCold_W))]);
                    maxSolarPower_W = max([max(max(solarPowerHot_W)) max(max(solarPowerCold_W))]);
                    contourLevels = linspace(minSolarPower_W, maxSolarPower_W, numContourLevels);

                    t = tiledlayout(app.PlotPanel_SolarPanelPower_Generic, 2, 1, 'TileSpacing', 'compact', 'Padding', 'compact');
                    app.PlotPanel_SolarPanelPower_Generic.Title = plotTitle;
                    cSet = [1 2];
                    tile = [1 2];
                    for i = 1:length(tile)
                        switch cSet(i)
                            case 1
                                caseType = "Hot";
                                solarPower_W = solarPowerHot_W;
                            case 2
                                caseType = "Cold";
                                solarPower_W = solarPowerCold_W;
                        end
                        ax(tile(i)) = nexttile(t);
                        contourf(ax(tile(i)), satOrbit.angularPositions_deg, betaAngles_deg, solarPower_W, contourLevels, 'LineStyle', 'none');
                        clim(ax(tile(i)), [minSolarPower_W maxSolarPower_W]);
                        ylabel(ax(tile(i)), 'Beta Angle (deg)');
                        xlim(ax(tile(i)), [0,360]); xticks(ax(tile(i)), 0:45:360);
                        title(ax(tile(i)), caseType)
                        set(ax(tile(i)), 'XMinorTick', 'on', 'YMinorTick', 'on', 'TickDir', 'out');
                        ylim(ax(tile(i)), [min(betaAngles_deg) max(betaAngles_deg)]); yticks(ax(tile(i)), min(betaAngles_deg):15:max(betaAngles_deg))
                        grid(ax(tile(i)), 'on');

                        % Only label x-axis for bottom plot
                        if i == 2
                            xlabel(ax(tile(i)), 'Orbit Angle (deg)');
                        end

                        colormap(ax(tile(i)), jet);
                        if i == 1
                            cb = colorbar(ax(tile(i)));
                            cb.Location = 'northoutside';
                            set(cb, 'Ticks', linspace(minSolarPower_W, maxSolarPower_W, 5));
                            cb.TickLabels = arrayfun(@(x) sprintf('%.2f', x), cb.Ticks, 'UniformOutput', false);
                            cb.Label.String = 'Solar Panel Power Output (W)';
                        end

                    end
                    app.PlotPanel_SolarPanelPower_Generic.Visible = "on";


                case "SPECIFIC"
                    ax = app.Plot_SolarPanelPower_Specific;
                    plot(ax, satOrbit.angularPositions_deg, solarPowerHot_W, lineStyle{:}, 'Color', hotColor); hold(ax, 'on')
                    plot(ax, satOrbit.angularPositions_deg, solarPowerCold_W, lineStyle{:}, 'Color', coldColor);
                    legend(ax, caseType1, caseType2,'Location','northoutside', 'Orientation', 'horizontal');
                    ylabel(ax, 'Solar Panel Power Output (W)')
                    xlabel(ax, 'Orbit Angle (deg)'); xlim(ax, [0,360]); xticks(ax, 0:45:360);
                    set(ax, 'XMinorTick', 'on', 'YMinorTick', 'on', 'TickDir', 'out');
                    grid(ax, 'on')
                    title(ax, plotTitle)
                    app.Plot_SolarPanelPower_Specific.Visible = "on";
                    %% Orbit Average Solar Power
                    maskFirstOrbit = 0 <= satOrbit.angularPositions_deg & satOrbit.angularPositions_deg <= 360;
                    firstOrbitAngles_deg = satOrbit.angularPositions_deg(maskFirstOrbit);
                    orbitAverageSolarPowerHot_W = mean(solarPowerHot_W(maskFirstOrbit));
                    %orbitAverageSolarPowerHot_W =
                    %trapz(satOrbit.angularPositions_deg(maskFirstOrbit),
                    %solarPowerHot_W(maskFirstOrbit)) /
                    %(firstOrbitAngles_deg(end) - firstOrbitAngles_deg(1)); % works with non-uniformly spaced orbit angles
                    orbitAverageSolarPowerCold_W = mean(solarPowerCold_W(maskFirstOrbit));

            end
        end

        %% Tables
        function getMinMaxTempTable(app,sat,satOrbit,genericBetaAngles_deg,specificBetaAngles_deg)
            % Extract the Min & Max Temp for Each Beta Angle Analysis

            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    Beta_deg = genericBetaAngles_deg';
                    minMaxZenith_degC = [min([sat(:,1).tempMat_K],[],2) max([sat(:,1).tempMat_K],[],2)] - 273.15;
                    minMaxNadir_degC = [min([sat(:,2).tempMat_K],[],2) max([sat(:,2).tempMat_K],[],2)] - 273.15;
                    minMaxForward_degC = [min([sat(:,3).tempMat_K],[],2) max([sat(:,3).tempMat_K],[],2)] - 273.15;
                    minMaxAft_degC = [min([sat(:,4).tempMat_K],[],2) max([sat(:,4).tempMat_K],[],2)] - 273.15;
                    minMaxNorth_degC = [min([sat(:,5).tempMat_K],[],2) max([sat(:,5).tempMat_K],[],2)] - 273.15;
                    minMaxSouth_degC = [min([sat(:,6).tempMat_K],[],2) max([sat(:,6).tempMat_K],[],2)] - 273.15;

                case "SPECIFIC"
                    Beta_deg = specificBetaAngles_deg';
                    minMaxZenith_degC = [min([sat(1,1).tempMat_K],[],2) max([sat(1,1).tempMat_K],[],2); min([sat(2,1).tempMat_K],[],2) max([sat(2,1).tempMat_K],[],2)] - 273.15;
                    minMaxNadir_degC = [min([sat(1,2).tempMat_K],[],2) max([sat(1,2).tempMat_K],[],2); min([sat(2,2).tempMat_K],[],2) max([sat(2,2).tempMat_K],[],2)] - 273.15;
                    minMaxForward_degC = [min([sat(1,3).tempMat_K],[],2) max([sat(1,3).tempMat_K],[],2); min([sat(2,3).tempMat_K],[],2) max([sat(2,3).tempMat_K],[],2)] - 273.15;
                    minMaxAft_degC = [min([sat(1,4).tempMat_K],[],2) max([sat(1,4).tempMat_K],[],2); min([sat(2,4).tempMat_K],[],2) max([sat(2,4).tempMat_K],[],2)] - 273.15;
                    minMaxNorth_degC = [min([sat(1,5).tempMat_K],[],2) max([sat(1,5).tempMat_K],[],2); min([sat(2,5).tempMat_K],[],2) max([sat(2,5).tempMat_K],[],2)] - 273.15;
                    minMaxSouth_degC = [min([sat(1,6).tempMat_K],[],2) max([sat(1,6).tempMat_K],[],2); min([sat(2,6).tempMat_K],[],2) max([sat(2,6).tempMat_K],[],2)] - 273.15;
            end
            % Create Min/Max Temp Table/Matrix
            tableData = num2cell([Beta_deg, minMaxZenith_degC, minMaxNadir_degC, minMaxForward_degC, minMaxAft_degC, minMaxNorth_degC, minMaxSouth_degC]);
            headers = {'Beta Angle (deg)', 'Min. Zenith','Max. Zenith', 'Min. Nadir', 'Max. Nadir', 'Min. Forward','Max. Forward', 'Min. Aft', 'Max. Aft', 'Min. North', 'Max. North' 'Min. South','Max. South'};
            app.Table_MinMaxTemp.ColumnName = headers;
            app.Table_MinMaxTemp.Data = tableData;
            app.MiniumMaximumSatelliteFaceTemperautresCPanel.Visible = "on";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app.UIFigure.WindowState = 'maximized';

            app.EpochUTCDatePicker.Value = datetime('now');
            getPrimaryBodyParams(app, app.PrimaryBodyDropDown.Value)

            % Create contact conductance table/matrix
            satFaces = {'Zenith','Nadir','Forward','Aft','North','South'};
            app.ContactConductanceUITable.Data = zeros(numel(satFaces),numel(satFaces));
            app.ContactConductanceUITable.ColumnName = satFaces;
            app.ContactConductanceUITable.RowName = satFaces;
            FillMatrixOffDiagonals(app)

            % Orbital period and critical beta angle
            getSatOrbitalPeriodAndCritBetaAngle(app)

            % Initial population of simulation duration and critical beta angle
            app.SpecificBetaAngleHotDegEditField.Value = app.CriticalBetaAngle_deg.Value;
            %app.SimulationDurationSEditField.Value = round(app.SatelliteOrbitalPeriod_s.Value * 5);

            % Effective surface properties
            getEffectiveSurfaceProperties(app, 'Zenith')
            getEffectiveSurfaceProperties(app, 'Nadir')
            getEffectiveSurfaceProperties(app, 'Forward')
            getEffectiveSurfaceProperties(app, 'Aft')
            getEffectiveSurfaceProperties(app, 'North')
            getEffectiveSurfaceProperties(app, 'South')
            getSatOrbitalPeriodAndCritBetaAngle(app)

            % Visibility
            app.SatelliteOrbitalParametersPanel.Visible = 'off';
            app.PlotPanel_AnglesOverTime.Visible = "off";
            app.anglesOverTime_RAANPlot.Visible = "off";
            app.anglesOverTime_SunlitOrbitPlot.Visible = "off";
            app.eclipseFractionPlot.Visible = "off";
            app.PlotPanel_IndividualHeatFlux_Specific.Visible = "off";
            app.PlotPanel_TotalHeatFlux_Generic.Visible = "off";
            app.PlotPanel_TotalHeatFlux_Specific.Visible = "off";
            app.PlotPanel_Temperatures.Visible = "off";
            app.PlotPanel_SolarPanelPower_Generic.Visible = "off";
            app.Plot_SolarPanelPower_Specific.Visible = "off";
            app.MiniumMaximumSatelliteFaceTemperautresCPanel.Visible = "off";
            app.SatelliteOrbitalPeriod_s.Enable = 'off';
            app.CriticalBetaAngle_deg.Enable = 'off';
            faces = {'Zenith', 'Nadir', 'Forward', 'Aft', 'North', 'South'};
            for i = 1:length(faces)
                % Effective surface properties
                app.(['AbsorptivityEffectiveHotEditField_' faces{i}]).Enable = 'off';
                app.(['EmissivityEffectiveHotEditField_' faces{i}]).Enable = 'off';
                app.(['RatioEffectiveHotEditField_' faces{i}]).Enable = 'off';
                app.(['AbsorptivityEffectiveColdEditField_' faces{i}]).Enable = 'off';
                app.(['EmissivityEffectiveColdEditField_' faces{i}]).Enable = 'off';
                app.(['RatioEffectiveColdEditField_' faces{i}]).Enable = 'off';

                % Bang-bang controls
                switch upper(app.(['BangBangHeaterControlSwitch_' faces{i}]).Value)
                    case 'ON'
                        app.(['TemperatureThresholdHeaterONEditField_' faces{i}]).Enable = 'on';
                        app.(['TemperatureThresholdHeaterOFFEditField_' faces{i}]).Enable = 'on';
                    case 'OFF'
                        app.(['TemperatureThresholdHeaterONEditField_' faces{i}]).Enable = 'off';
                        app.(['TemperatureThresholdHeaterOFFEditField_' faces{i}]).Enable = 'off';
                end

                % Solar panels
                getSolarPanelType(app, faces{i})

                % Analysis Type
                getAnalysisType(app)
            end

            % Lamp Indicators
            app.Lamp.Color = 'g'; % simulation ready to be run
            app.LampBetaEvolution.Color = 'g';
            app.LampEclipseFracsOverBetaAngle.Color = 'g';

        end

        % Value changed function: BetaAngleEvolutionSpinner, 
        % ...and 7 other components
        function PrimaryBodyOrTimingValueChanged(app, event)
            prevRadius_km = app.PrimaryBodyRadiusKmEditField.Value;
            prevMass_kg = app.PrimaryBodyMassKgEditField.Value;
            prevPrimaryBodyEquatorInclinationDeg = app.PrimaryBodyEquatorInclinationDegEditField.Value;
            prevPrimaryBodyJ2Constant = app.PrimaryBodyJ2ConstantEditField.Value;
            prevSolarFluxHot_Wm2   = app.SolarFluxHotWm2EditField.Value;
            prevSolarFluxCold_Wm2  = app.SolarFluxColdWm2EditField.Value;
            prevAlbedoHot = app.AlbedoHotEditField.Value;
            prevAlbedoCold = app.AlbedoColdEditField.Value;
            prevSunSideIRHot_Wm2 = app.SunSideIRPlanetshineHotWm2EditField.Value;
            prevSunSideIRCold_Wm2 = app.SunSideIRPlanetshineColdWm2EditField.Value;
            prevDarkSideIRHot_Wm2  = app.DarkSideIRPlanetshineHotWm2EditField.Value;
            prevDarkSideIRCold_Wm2 = app.DarkSideIRPlanetshineColdWm2EditField.Value;
            ephemExceeded = false; %initialize

            body = app.PrimaryBodyDropDown.Value;
            switch event.Source
                case app.PrimaryBodyDropDown
                    getPrimaryBodyParams(app, body);
            end

            % Check for invalid inputs
            if app.TimeStepSSpinner.Value > app.SimulationDurationSEditField.Value % reject
                uialert(app.UIFigure, 'Time step must be <= simulation duration', 'Invalid input')
                event.Source.Value = event.PreviousValue;
            end

            simStart_UTC = datetime(year(app.EpochUTCDatePicker.Value), month(app.EpochUTCDatePicker.Value), day(app.EpochUTCDatePicker.Value), app.EpochHourEditField.Value, app.EpochMinuteEditField.Value, app.EpochSecondEditField.Value);
            simEnd_UTC = simStart_UTC + seconds(app.SimulationDurationSEditField.Value + app.TimeStepSSpinner.Value);
            betaEvolutionEnd_UTC = simStart_UTC + days(app.BetaAngleEvolutionSpinner.Value);
            if ~(app.ephemStart_UTC.Value <= simStart_UTC && simEnd_UTC <= app.ephemStop_UTC.Value)
                uialert(app.UIFigure, ...
                    sprintf(['Input simulation timing parameters fall outside the available ephemeris range for %s.\n\n' ...
                    'Ephemeris Avail. (UTC):  %s to %s\n' ...
                    'Requested Start (UTC):  %s\n' ...
                    'Requested End (UTC):  %s'], ...
                    body, app.ephemStart_UTC.Value, app.ephemStop_UTC.Value, simStart_UTC, simEnd_UTC), 'Invalid input');
                event.Source.Value = event.PreviousValue;
                ephemExceeded = true;

            elseif ~(app.ephemStart_UTC.Value <= simStart_UTC && betaEvolutionEnd_UTC <= app.ephemStop_UTC.Value)
                uialert(app.UIFigure, ...
                    sprintf(['Input beta evolution timing parameters fall outside the available ephemeris range for %s.\n\n' ...
                    'Ephemeris Avail. (UTC):  %s to %s\n' ...
                    'Requested Start (UTC):  %s\n' ...
                    'Requested End (UTC):  %s'], ...
                    body, app.ephemStart_UTC.Value, app.ephemStop_UTC.Value, simStart_UTC, betaEvolutionEnd_UTC), 'Invalid input');
                event.Source.Value = event.PreviousValue;
                ephemExceeded = true;
            end

            if ephemExceeded % revert to previous primary body values in ephem error
                switch event.Source
                    case app.PrimaryBodyDropDown
                        app.PrimaryBodyRadiusKmEditField.Value = prevRadius_km;
                        app.PrimaryBodyMassKgEditField.Value = prevMass_kg;
                        app.PrimaryBodyEquatorInclinationDegEditField.Value = prevPrimaryBodyEquatorInclinationDeg;
                        app.PrimaryBodyJ2ConstantEditField.Value = prevPrimaryBodyJ2Constant;
                        app.SolarFluxHotWm2EditField.Value = prevSolarFluxHot_Wm2;
                        app.SolarFluxColdWm2EditField.Value = prevSolarFluxCold_Wm2;
                        app.AlbedoHotEditField.Value = prevAlbedoHot;
                        app.AlbedoColdEditField.Value = prevAlbedoCold;
                        app.SunSideIRPlanetshineHotWm2EditField.Value = prevSunSideIRHot_Wm2;
                        app.SunSideIRPlanetshineColdWm2EditField.Value = prevSunSideIRCold_Wm2;
                        app.DarkSideIRPlanetshineHotWm2EditField.Value = prevDarkSideIRHot_Wm2;
                        app.DarkSideIRPlanetshineColdWm2EditField.Value = prevDarkSideIRCold_Wm2;
                        getSatOrbitalPeriodAndCritBetaAngle(app);
                end
            end
        end

        % Button pushed function: RunSATMOButton
        function RunSATMOButtonValueChanged(app, event)
            %% Show Status of Code
            app.Lamp.Color = 'r'; % simulation in progress
            app.LampBetaEvolution.Color = 'r';
            app.LampEclipseFracsOverBetaAngle.Color = 'r';

            %% Clear / Hide Plots
            cla(app.anglesOverTime_RAANPlot, 'reset'); app.anglesOverTime_RAANPlot.Visible = "off";
            cla(app.anglesOverTime_SunlitOrbitPlot, 'reset'); app.anglesOverTime_SunlitOrbitPlot.Visible = "off";
            app.PlotPanel_AnglesOverTime.Visible = "off";
            cla(app.eclipseFractionPlot); app.eclipseFractionPlot.Visible = "off";

            app.PlotPanel_IndividualHeatFlux_Specific.Visible = "off";
            app.PlotPanel_TotalHeatFlux_Generic.Visible = "off";
            app.PlotPanel_TotalHeatFlux_Specific.Visible = "off";
            app.PlotPanel_Temperatures.Visible = "off";
            app.PlotPanel_SolarPanelPower_Generic.Visible = "off";
            cla(app.Plot_SolarPanelPower_Specific, 'reset'); app.Plot_SolarPanelPower_Specific.Visible = "off";
            app.MiniumMaximumSatelliteFaceTemperautresCPanel.Visible = "off";
            drawnow; % force UI refresh

            %% Retrieve User Inputs
            [satOrbit,body,simDuration_s, timeStep_s, specificBetaAngles_deg, genericBetaAngles_deg, sat, solarPanels] = getUserInputs(app);


            %% CONSTANTS
            sigma_Wpm2K4 = 5.6704E-8; % Stefan-Boltzmann constant
            G_m3pkgs2 = 6.67430E-11; % gravitational constant
            Tspace_K = 2.73; % temperature of space


            %% Run Analysis for Hot and Cold Case
            for cSet = 1:2 % hot-case = 1, cold-case = 2
                % Set Analysis Type Parameters
                switch upper(satOrbit.analysisType)
                    case "GENERIC"
                        betaAngles_deg = genericBetaAngles_deg;
                    case "SPECIFIC"
                        betaAngles_deg = specificBetaAngles_deg(cSet);
                end

                % Retrieve Solar Constant
                Gs_Wpm2 = body.Gs_Wpm2(cSet);

                % Surface view factors [https://tfaws.nasa.gov/wp-content/uploads/On-Orbit_Thermal_Environments_TFAWS_2014.pdf]
                % These assume a low body orbit, such that the planet is uniformly illuminated beneath the panels
                FF_par = (body.radius_m / (body.radius_m + satOrbit.h_m))^2; % form factor due to orbit altitude distance
                FF_perp = (1/360) * (180 - 2*asind(sqrt(1 - (body.radius_m/(body.radius_m + satOrbit.h_m))^2)) - (180/pi)*sind(2*asind(sqrt(1 - (body.radius_m/(body.radius_m + satOrbit.h_m))^2)))); % form factor due to orbit altitude distance and orientation relative to the nadir pointing vector
                %FF_perp = (1/(2*pi)) * (pi - (2*asin(sqrt(1 - ((body.radius_m/(body.radius_m + satOrbit.h_m))^2)))) - sin((2*asin(sqrt(1 - ((body.radius_m/(body.radius_m + satOrbit.h_m))^2))))));  % radian formulation
                % Preliminary Calculations
                satOrbit.criticalBetaAngle_deg = asind(body.radius_m / (body.radius_m + satOrbit.h_m));
                satOrbit.period_s = 2*pi*sqrt(((body.radius_m + satOrbit.h_m)^3) / (G_m3pkgs2*body.mass_kg)); % satellite orbital period
                satOrbit.angularVelocity_degps = 360 / satOrbit.period_s; % satellite orbital angular velocity, assuming a circular orbit

                % Pre-Allocate Memory
                satOrbit.times_s = 0:timeStep_s:simDuration_s;
                satOrbit.angularPositions_deg = satOrbit.times_s*satOrbit.angularVelocity_degps; % measured from solar noon
                temps_K = zeros(length(betaAngles_deg), length(satOrbit.times_s));
                for j = 1:6
                    sat(cSet,j).tempMat_K = temps_K;
                    sat(cSet,j).tempMat_K(:,1) =  sat(cSet,j).Tinitial_K;
                    sat(cSet,j).qSolarMat_W  = temps_K; sat(cSet,j).qAlbedoMat_W = temps_K; sat(cSet,j).qIRMat_W = temps_K; sat(cSet,j).qOutMat_W = temps_K;
                    sat(cSet,j).heaterStateMemory = 0; % default to off
                end


                %% Run Analaysis at Beta Angle Inputs
                for i = 1:length(betaAngles_deg)
                    betaAngle_i_deg = betaAngles_deg(i);

                    if abs(betaAngle_i_deg) < satOrbit.criticalBetaAngle_deg
                        fe = (1/180) .* acosd(sqrt((satOrbit.h_m^2) + (2.*body.radius_m.*satOrbit.h_m)) ./ ((body.radius_m + satOrbit.h_m).*cosd(betaAngle_i_deg))); % eclipse fraction
                        shadowOrbitEntryAngle_deg = 180 - asind(sqrt((cosd(betaAngle_i_deg)^-2) * (((body.radius_m/(body.radius_m+satOrbit.h_m))^2) - sind(betaAngle_i_deg)^2))); % true for low, circular orbits. measured from orbit noon
                        %shadowOrbitExitAngle_deg = shadowOrbitEntryAngle_deg + (2*(180-shadowOrbitEntryAngle_deg));
                        shadowOrbitExitAngle_deg = 360 - shadowOrbitEntryAngle_deg;
                        angle1_deg = shadowOrbitEntryAngle_deg; % passed into getViewFactor()
                        angle2_deg = shadowOrbitExitAngle_deg;
                    else
                        fe = 0; % satellite is not eclipsed by Earth
                        angle1_deg = 180; % these allow the conditionals in getViewFactor() to hold when there is no eclipse
                        angle2_deg = 180;
                    end

                    simLength = length(satOrbit.times_s);
                    orbitAngle_i_deg = 0; % initialize orbit angle (from solar noon)


                    %% Run Analysis for User-Specified Duration at Each Beta Angle
                    for j = 1:simLength
                        % Calculate view/scaling factors
                        [Fsol_zenith, Falb_zenith, Fsol_nadir, Falb_nadir, Fsol_forward, Falb_forward, Fsol_aft, Falb_aft, Fsol_north, Falb_north, Fsol_south, Falb_south] = getScaleFactors(app,orbitAngle_i_deg, betaAngle_i_deg, angle1_deg, angle2_deg); % check if sat is in sun-side or dark-side

                        % Check if sat is directly in sunlight or shadow
                        if (orbitAngle_i_deg <= angle1_deg || orbitAngle_i_deg >= angle2_deg) && fe > 0 % sunlight
                            qElectronicsSunOrShadow_W = 'qElectronicsSun_W';
                        else % shadow
                            qElectronicsSunOrShadow_W = 'qElectronicsShadow_W';
                        end

                        % Check if sat is on sun-half or shadow-half of
                        % planet. Thermal Desktop method is not a step function but interpolation as the satellite approaches the terminator.
                        ZenithAngle_deg = acosd(cosd(betaAngle_i_deg)*cosd(orbitAngle_i_deg));
                        if ZenithAngle_deg <= 90 || ZenithAngle_deg >= 270 % on sun-half of planet
                            IR_Wpm2 = body.IRsunSide_Wpm2(cSet);
                        else
                            IR_Wpm2 = body.IRdarkSide_Wpm2(cSet); % shadow-half of planet
                        end
                        for faceNum = 1:6
                            faceName = sat(cSet,faceNum).face;
                            switch upper(faceName)
                                case 'ZENITH'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_zenith,Falb_zenith,0,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                                case 'NADIR'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_nadir,Falb_nadir,FF_par,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                                case 'FORWARD'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_forward,Falb_forward,FF_perp,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                                case 'AFT'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_aft,Falb_aft,FF_perp,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                                case 'NORTH'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_north,Falb_north,FF_perp,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                                case 'SOUTH'
                                    sat = getUpdatedTemp(app, i,j,sat,cSet,faceNum,Fsol_south,Falb_south,FF_perp,IR_Wpm2,qElectronicsSunOrShadow_W,Gs_Wpm2,body,sigma_Wpm2K4,Tspace_K,timeStep_s);
                            end
                        end
                        orbitAngle_i_deg = mod(orbitAngle_i_deg + (satOrbit.angularVelocity_degps*timeStep_s), 360); % update orbit angle and wrap it after each full orbit
                    end
                end
            end
            % Delete final (extra) temperature value to avoid array dimension mismatches when plotting
            for i = 1:numel(sat)
                sat(i).tempMat_K(:,end) = [];
            end

            % Generate Beta Angle and % of Orbit in Sunlight Values Over Time for specific Analysis Type
            switch upper(satOrbit.analysisType)
                case "SPECIFIC"
                    % Calculate RAAN Rate of Change due to J2 Effect
                    satOrbit.RAANdot_degps = getRAANdot(app, satOrbit,body,G_m3pkgs2);
                    % Calculate Solar Beta Angle, Eclipse Fraction, and Solar Position as a function of Date
                    [satOrbit] = getAnglesOverTime(app, satOrbit,body);
            end


            %% Plotting
            numContourLevels = 16; % for plots
            lw = 2;
            markerOrLineSize = 2;
            switch upper(satOrbit.analysisType)
                case "SPECIFIC"
                    plotAnglesOverTime(app, sat, satOrbit, markerOrLineSize);
                    cla(app.eclipseFractionPlot); app.eclipseFractionPlot.Visible = "off";
                case "GENERIC"
                    cla(app.anglesOverTime_SunlitOrbitPlot, 'reset'); app.anglesOverTime_SunlitOrbitPlot.Visible = "off";
                    cla(app.anglesOverTime_RAANPlot, 'reset'); app.anglesOverTime_RAANPlot.Visible = "off";
                    app.PlotPanel_AnglesOverTime.Visible = "off";
                    cla(app.eclipseFractionPlot); app.eclipseFractionPlot.Visible = "off";
                    plotEclipseFractionOverBetaAngle(app, satOrbit, body, lw);
            end
            plotAbsorbedHeatFlux(app, sat, satOrbit, specificBetaAngles_deg, betaAngles_deg, numContourLevels, lw)
            plotTemps(app, sat, satOrbit, specificBetaAngles_deg, betaAngles_deg, numContourLevels, lw)
            if sum([solarPanels.A_m2]) ~= 0 && sum([solarPanels.presentOnFace]) ~= 0 && sum([solarPanels.efficiency]) ~= 0 && sum([solarPanels.absorptivity]) ~= 0
                plotSolarPanelPower(app, solarPanels, sat, satOrbit, betaAngles_deg, specificBetaAngles_deg, numContourLevels, lw)
            end


            %% Min/Max Tables
            getMinMaxTempTable(app,sat,satOrbit,genericBetaAngles_deg,specificBetaAngles_deg);

            %% Change Lamp Color
            app.Lamp.Color = 'g'; % simulation complete and ready to be re-run
            app.LampBetaEvolution.Color = 'g';
            app.LampEclipseFracsOverBetaAngle.Color = 'g';
        end

        % Selection changed function: AnalysisModeButtonGroup
        function AnalysisModeButtonGroupSelectionChanged(app, event)
            getAnalysisType(app)
        end

        % Cell edit callback: ContactConductanceUITable
        function ContactConductanceUITableCellEdit(app, event)
            % Check for invalid data entry
            userInput = str2double(event.EditData);
            row = event.Indices(1);
            col = event.Indices(2);
            if isnan(userInput) || userInput < 0 % reject
                uialert(app.UIFigure, 'Value must be a number >= 0', 'Invalid Input');
                app.ContactConductanceUITable.Data(row,col) = event.PreviousData;
            end
        end

        % Value changed function: OffDiagonalsEditField
        function FillMatrixOffDiagonals(app, event)
            % Fills the contact conductance matrix off-diagonal blocks w/
            % user input
            fillMatMask = logical([0 0 1 1 1 1;...
                0 0 1 1 1 1;...
                1 1 0 0 1 1;...
                1 1 0 0 1 1;...
                1 1 1 1 0 0;...
                1 1 1 1 0 0]);
            app.ContactConductanceUITable.Data(fillMatMask) = app.OffDiagonalsEditField.Value;
        end

        % Selection changed function: SolarPanelMountingButtonGroup_Aft, 
        % ...and 5 other components
        function SolarPanelMountingButtonGroupSelectionChanged(app, event)
            % Enable / Disable Solar Panel Inputs
            getSolarPanelType(app, 'Zenith')
            getSolarPanelType(app, 'Nadir')
            getSolarPanelType(app, 'Forward')
            getSolarPanelType(app, 'Aft')
            getSolarPanelType(app, 'North')
            getSolarPanelType(app, 'South')
        end

        % Value changed function: BangBangHeaterControlSwitch_Aft, 
        % ...and 5 other components
        function BangBangHeaterControlSwitchValueChanged(app, event)
            getHeaterControlState(app, 'Zenith')
            getHeaterControlState(app, 'Nadir')
            getHeaterControlState(app, 'Forward')
            getHeaterControlState(app, 'Aft')
            getHeaterControlState(app, 'North')
            getHeaterControlState(app, 'South')
        end

        % Value changed function: AbsorptivityEditField_Aft, 
        % ...and 41 other components
        function SurfacePropsValueChanged(app, event)
            faces = {'Zenith', 'Nadir', 'Forward', 'Aft', 'North', 'South'};
            for i = 1:length(faces)
                solarPanelEffField = app.(['SolarPanelEfficiencyEditField_' faces{i}]);
                solarPanelAbsField = app.(['SolarPanelAbsorptivityEditField_' faces{i}]);
                switch event.Source
                    case {solarPanelEffField, solarPanelAbsField}
                        % Check for invalid inputs
                        if solarPanelEffField.Value > solarPanelAbsField.Value % reject
                            uialert(app.UIFigure, 'Panel efficiency must be <= panel absorptivity', 'Invalid input')
                            event.Source.Value = event.PreviousValue;
                        end
                end
                getEffectiveSurfaceProperties(app, faces{i});

            end
        end

        % Button pushed function: 
        % CopyPropertiesfromZenithFacetoRemainingFacesButton
        function CopyPropertiesfromZenithFacetoRemainingFacesButtonPushed(app, event)
            faces = {'Nadir','Forward', 'Aft', 'North', 'South'}; % faces to copy to
            for i = 1:length(faces)
                app.(['FaceMasskgEditField_' faces{i}]).Value = app.FaceMasskgEditField_Zenith.Value;
                app.(['Aream2EditField_' faces{i}]).Value = app.Aream2EditField_Zenith.Value;
                app.(['SpecificHeatJpkgKEditField_' faces{i}]).Value = app.SpecificHeatJpkgKEditField_Zenith.Value;
                app.(['AbsorptivityEditField_' faces{i}]).Value = app.AbsorptivityEditField_Zenith.Value;
                app.(['EmissivityEditField_' faces{i}]).Value = app.EmissivityEditField_Zenith.Value;
                app.(['InitialTemperatureHotCEditField_' faces{i}]).Value = app.InitialTemperatureHotCEditField_Zenith.Value;
                app.(['InitialTemperatureColdCEditField_' faces{i}]).Value = app.InitialTemperatureColdCEditField_Zenith.Value;
                app.(['InternalHeatLoadSunlightWEditField_' faces{i}]).Value = app.InternalHeatLoadSunlightWEditField_Zenith.Value;
                app.(['InternalHeatLoadShadowWEditField_' faces{i}]).Value = app.InternalHeatLoadShadowWEditField_Zenith.Value;
                app.(['BangBangHeaterControlSwitch_' faces{i}]).Value = app.BangBangHeaterControlSwitch_Zenith.Value;
                app.(['HeaterPowerEditField_' faces{i}]).Value = app.HeaterPowerEditField_Zenith.Value;
                app.(['TemperatureThresholdHeaterONEditField_' faces{i}]).Value = app.TemperatureThresholdHeaterONEditField_Zenith.Value;
                app.(['TemperatureThresholdHeaterOFFEditField_' faces{i}]).Value = app.TemperatureThresholdHeaterOFFEditField_Zenith.Value;
                app.(['SolarPanelMountingButtonGroup_' faces{i}]).SelectedObject = findobj(app.(['SolarPanelMountingButtonGroup_' faces{i}]), 'Text', app.SolarPanelMountingButtonGroup_Zenith.SelectedObject.Text);
                app.(['SolarPanelCoveragePercentEditField_' faces{i}]).Value = app.SolarPanelCoveragePercentEditField_Zenith.Value;
                app.(['SolarPanelEfficiencyEditField_' faces{i}]).Value = app.SolarPanelEfficiencyEditField_Zenith.Value;
                app.(['SolarPanelAbsorptivityEditField_' faces{i}]).Value = app.SolarPanelAbsorptivityEditField_Zenith.Value;
                app.(['SolarPanelEmissivityEditField_' faces{i}]).Value = app.SolarPanelEmissivityEditField_Zenith.Value;
                app.(['EmissivityEffectiveHotEditField_' faces{i}]).Value = app.EmissivityEffectiveHotEditField_Zenith.Value;
                app.(['AbsorptivityEffectiveHotEditField_' faces{i}]).Value = app.AbsorptivityEffectiveHotEditField_Zenith.Value;
                app.(['RatioEffectiveHotEditField_' faces{i}]).Value = app.RatioEffectiveHotEditField_Zenith.Value;
                app.(['EmissivityEffectiveColdEditField_' faces{i}]).Value = app.EmissivityEffectiveColdEditField_Zenith.Value;
                app.(['AbsorptivityEffectiveColdEditField_' faces{i}]).Value = app.AbsorptivityEffectiveColdEditField_Zenith.Value;
                app.(['RatioEffectiveColdEditField_' faces{i}]).Value = app.RatioEffectiveColdEditField_Zenith.Value;
                % Update apperance of entry boxes
                getSolarPanelType(app, faces{i})
                getHeaterControlState(app, faces{i});
            end
        end

        % Callback function: OrbitAltitudeKmEditField, PrimaryBodyDropDown,
        % 
        % ...and 3 other components
        function SatelliteOrbitalPeriod_sValueChanged(app, event)
            getSatOrbitalPeriodAndCritBetaAngle(app);
        end

        % Value changed function: GetBetaEvolutionButton
        function GetBetaEvolutionButtonValueChanged(app, event)
            [satOrbit, body, ~, ~, ~, ~, sat, ~] = getUserInputs(app);
            switch upper(satOrbit.analysisType)
                case "SPECIFIC"
                    cla(app.eclipseFractionPlot); app.eclipseFractionPlot.Visible = "off";
                    cla(app.anglesOverTime_RAANPlot, 'reset'); app.anglesOverTime_RAANPlot.Visible = "off";
                    cla(app.anglesOverTime_SunlitOrbitPlot, 'reset'); app.anglesOverTime_SunlitOrbitPlot.Visible = "off";
                    app.PlotPanel_AnglesOverTime.Visible = "off";
                    app.LampBetaEvolution.Color = 'r';
                    drawnow;
                    markerOrLineSize = 2;
                    G_m3pkgs2 = 6.67430E-11; % Gravitational constant
                    satOrbit.RAANdot_degps = getRAANdot(app, satOrbit, body, G_m3pkgs2);
                    % Calculate RAAN Rate of Change due to J2 Effect
                    satOrbit.RAANdot_degps = getRAANdot(app, satOrbit,body,G_m3pkgs2);
                    % Calculate Solar Beta Angle, Eclipse Fraction, and Solar Position as a function of Date
                    [satOrbit] = getAnglesOverTime(app, satOrbit,body);
                    plotAnglesOverTime(app, sat, satOrbit, markerOrLineSize);
                    app.LampBetaEvolution.Color = 'g';
            end
        end

        % Value changed function: FinalBetaAngleDegEditField, 
        % ...and 2 other components
        function InitialBetaAngleDegEditFieldValueChanged(app, event)
            % Check for invalid inputs
            if app.InitialBetaAngleDegEditField.Value >= app.FinalBetaAngleDegEditField.Value
                uialert(app.UIFigure, 'Beta angle lower bound must be below beta angle upper bound.', 'Invalid Input')
                event.Source.Value = event.PreviousValue;
            elseif  app.IncrementBetaAngleDegEditField.Value > (app.FinalBetaAngleDegEditField.Value - app.InitialBetaAngleDegEditField.Value) || app.IncrementBetaAngleDegEditField.Value <= 0
                uialert(app.UIFigure, 'Beta angle increment must be a positive number within the beta angle upper and lower bounds', 'Invalid Input')
                event.Source.Value = event.PreviousValue;
            elseif mod(abs((app.FinalBetaAngleDegEditField.Value - app.InitialBetaAngleDegEditField.Value) / app.IncrementBetaAngleDegEditField.Value),2) ~=0
                uialert(app.UIFigure, 'Range of beta angle bounds must be evenly divisible by the beta angle increment', 'Invalid Input')
                event.Source.Value = event.PreviousValue;
            end
        end

        % Value changed function: GetEclipseFractionsButton
        function GetEclipseFractionsButtonValueChanged(app, event)
            [satOrbit, body, ~, ~, ~, ~, sat, ~] = getUserInputs(app);
            switch upper(satOrbit.analysisType)
                case "GENERIC"
                    lw = 2;
                    app.LampEclipseFracsOverBetaAngle.Color = 'r';
                    cla(app.anglesOverTime_SunlitOrbitPlot, 'reset'); app.anglesOverTime_SunlitOrbitPlot.Visible = "off";
                    cla(app.anglesOverTime_RAANPlot, 'reset'); app.anglesOverTime_RAANPlot.Visible = "off";
                    app.PlotPanel_AnglesOverTime.Visible = "off";
                    cla(app.eclipseFractionPlot); app.eclipseFractionPlot.Visible = "off";
                    plotEclipseFractionOverBetaAngle(app, satOrbit, body, lw);
                    app.LampEclipseFracsOverBetaAngle.Color = 'g';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisInputsTab = uitab(app.TabGroup);
            app.AnalysisInputsTab.Title = 'Analysis Inputs';
            app.GridLayout9 = uigridlayout(app.AnalysisInputsTab);
            app.anglesOverTime_SunlitOrbitPlot = uiaxes(app.GridLayout9);
            ylabel(app.anglesOverTime_SunlitOrbitPlot, 'Y')
            zlabel(app.anglesOverTime_SunlitOrbitPlot, 'Z')
            app.anglesOverTime_RAANPlot = uiaxes(app.GridLayout9);
            ylabel(app.anglesOverTime_RAANPlot, 'Y')
            zlabel(app.anglesOverTime_RAANPlot, 'Z')
            app.eclipseFractionPlot = uiaxes(app.GridLayout9);
            xlabel(app.eclipseFractionPlot, 'X')
            ylabel(app.eclipseFractionPlot, 'Y')
            zlabel(app.eclipseFractionPlot, 'Z')
            app.Panel = uipanel(app.GridLayout9);
            app.SolarFluxColdWm2EditField = uieditfield(app.Panel, 'numeric');
            app.SolarFluxColdWm2EditField.Limits = [0 Inf];
            app.HotLabel_2 = uilabel(app.Panel);
            app.HotLabel_2.Text = 'Hot';
            app.ColdLabel_2 = uilabel(app.Panel);
            app.ColdLabel_2.Text = 'Cold';
            app.AlbedoColdEditField = uieditfield(app.Panel, 'numeric');
            app.AlbedoColdEditField.Limits = [0 1];
            app.SunSideIRPlanetshineColdWm2EditField = uieditfield(app.Panel, 'numeric');
            app.SunSideIRPlanetshineColdWm2EditField.Limits = [0 Inf];
            app.DarkSideIRPlanetshineColdWm2EditField = uieditfield(app.Panel, 'numeric');
            app.DarkSideIRPlanetshineColdWm2EditField.Limits = [0 Inf];
            app.SatelliteOrbitalParametersPanel = uipanel(app.Panel);
            app.SatelliteOrbitalParametersPanel.Title = 'Satellite Orbital Parameters';
            app.EpochMinuteEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.EpochMinuteEditField.Limits = [0 Inf];
            app.EpochMinuteEditField.AllowEmpty = 'on';
            app.EpochMinuteEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.EpochMinuteEditField.Placeholder = 'min';
            app.EpochSecondEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.EpochSecondEditField.Limits = [0 Inf];
            app.EpochSecondEditField.AllowEmpty = 'on';
            app.EpochSecondEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.EpochSecondEditField.Placeholder = 's';
            app.GetBetaEvolutionButton = uibutton(app.SatelliteOrbitalParametersPanel, 'state');
            app.GetBetaEvolutionButton.ValueChangedFcn = createCallbackFcn(app, @GetBetaEvolutionButtonValueChanged, true);
            app.GetBetaEvolutionButton.Text = 'Get Beta Evolution';
            app.HotLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.HotLabel.Text = 'Hot';
            app.degLabel_2 = uilabel(app.SatelliteOrbitalParametersPanel);
            app.degLabel_2.Text = ' deg';
            app.degLabel_3 = uilabel(app.SatelliteOrbitalParametersPanel);
            app.degLabel_3.Text = ' deg';
            app.calendardaysLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.calendardaysLabel.Text = ' calendar days';
            app.hLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.hLabel.Text = ' h';
            app.sLabel_3 = uilabel(app.SatelliteOrbitalParametersPanel);
            app.sLabel_3.Text = ' s';
            app.degLabel_4 = uilabel(app.SatelliteOrbitalParametersPanel);
            app.degLabel_4.Text = ' deg';
            app.mLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.mLabel.Text = ' m';
            app.EpochHourEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.EpochHourEditField.Limits = [0 Inf];
            app.EpochHourEditField.AllowEmpty = 'on';
            app.EpochHourEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.EpochHourEditField.Placeholder = 'hr';
            app.LampBetaEvolution = uilamp(app.SatelliteOrbitalParametersPanel);
            app.EpochUTCLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.EpochUTCLabel.Text = 'Epoch (UTC) ';
            app.EpochUTCDatePicker = uidatepicker(app.SatelliteOrbitalParametersPanel);
            app.EpochUTCDatePicker.DisplayFormat = 'dd/MMM/uuuu';
            app.EpochUTCDatePicker.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.EpochUTCDatePicker.Value = datetime([2025 12 1]);
            app.OrbitInclinationLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.OrbitInclinationLabel.Text = 'Inclination ';
            app.InclinationEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.InclinationEditField.Limits = [0 180];
            app.InclinationEditField.Value = 51.6;
            app.RAANLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.RAANLabel.Text = 'RAAN ';
            app.RAANEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.RAANEditField.Limits = [0 360];
            app.TrackBetaAngleOverLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.TrackBetaAngleOverLabel.Text = 'Beta Angle Evolution ';
            app.BetaAngleEvolutionSpinner = uispinner(app.SatelliteOrbitalParametersPanel);
            app.BetaAngleEvolutionSpinner.Limits = [1 Inf];
            app.BetaAngleEvolutionSpinner.RoundFractionalValues = 'on';
            app.BetaAngleEvolutionSpinner.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.BetaAngleEvolutionSpinner.Value = 365;
            app.BetaAngleLabel = uilabel(app.SatelliteOrbitalParametersPanel);
            app.BetaAngleLabel.Text = 'Beta Angle ';
            app.SpecificBetaAngleHotDegEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.SpecificBetaAngleHotDegEditField.Limits = [-90 90];
            app.ColdLabel_9 = uilabel(app.SatelliteOrbitalParametersPanel);
            app.ColdLabel_9.Text = 'Cold';
            app.SpecificBetaAngleColdDegEditField = uieditfield(app.SatelliteOrbitalParametersPanel, 'numeric');
            app.SpecificBetaAngleColdDegEditField.Limits = [-90 90];
            app.sLabel = uilabel(app.Panel);
            app.sLabel.Text = ' s';
            app.sLabel_2 = uilabel(app.Panel);
            app.sLabel_2.Text = ' s';
            app.kmLabel = uilabel(app.Panel);
            app.kmLabel.Text = ' km';
            app.kmLabel_2 = uilabel(app.Panel);
            app.kmLabel_2.Text = ' km';
            app.Wm2Label = uilabel(app.Panel);
            app.Wm2Label.Text = 'W/m2';
            app.Wm2Label_2 = uilabel(app.Panel);
            app.Wm2Label_2.Text = 'W/m2';
            app.Wm2Label_3 = uilabel(app.Panel);
            app.Wm2Label_3.Text = 'W/m2';
            app.degLabel = uilabel(app.Panel);
            app.degLabel.Text = 'deg';
            app.kgLabel_3 = uilabel(app.Panel);
            app.kgLabel_3.Text = ' kg';
            app.SatelliteOrbitalPeriod_s = uieditfield(app.Panel, 'numeric');
            app.SatelliteOrbitalPeriod_s.Limits = [0 Inf];
            app.SatelliteOrbitalPeriod_s.ValueDisplayFormat = '%11.8g';
            app.SatelliteOrbitalPeriod_s.ValueChangedFcn = createCallbackFcn(app, @SatelliteOrbitalPeriod_sValueChanged, true);
            app.OrbitAltitudeEditFieldLabel_2 = uilabel(app.Panel);
            app.OrbitAltitudeEditFieldLabel_2.Text = 'Orbit Period';
            app.sLabel_4 = uilabel(app.Panel);
            app.sLabel_4.Text = ' s';
            app.CriticalBetaAngle_deg = uieditfield(app.Panel, 'numeric');
            app.CriticalBetaAngle_deg.Limits = [0 Inf];
            app.CriticalBetaAngle_deg.ValueDisplayFormat = '%11.6g';
            app.degLabel_6 = uilabel(app.Panel);
            app.degLabel_6.Text = ' deg';
            app.EquatorInclinationEditFieldLabel_2 = uilabel(app.Panel);
            app.EquatorInclinationEditFieldLabel_2.Text = 'Critical Beta Angle';
            app.BetaAngleSweepPanel = uipanel(app.Panel);
            app.BetaAngleSweepPanel.Title = 'Beta Angle Sweep';
            app.InitialBetaAngleDegEditField = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.InitialBetaAngleDegEditField.Limits = [-90 90];
            app.InitialBetaAngleDegEditField.ValueChangedFcn = createCallbackFcn(app, @InitialBetaAngleDegEditFieldValueChanged, true);
            app.InitialBetaAngleDegEditField.Value = -90;
            app.LowerBoundBetaAngleLabel = uilabel(app.BetaAngleSweepPanel);
            app.LowerBoundBetaAngleLabel.Text = 'Lower Bound Beta Angle ';
            app.UpperLabel = uilabel(app.BetaAngleSweepPanel);
            app.UpperLabel.Text = 'Upper Bound Beta Angle';
            app.FinalBetaAngleDegEditField = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.FinalBetaAngleDegEditField.Limits = [-90 90];
            app.FinalBetaAngleDegEditField.ValueChangedFcn = createCallbackFcn(app, @InitialBetaAngleDegEditFieldValueChanged, true);
            app.FinalBetaAngleDegEditField.Value = 90;
            app.IntervalLabel = uilabel(app.BetaAngleSweepPanel);
            app.IntervalLabel.Text = 'Increment ';
            app.IncrementBetaAngleDegEditField = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.IncrementBetaAngleDegEditField.ValueChangedFcn = createCallbackFcn(app, @InitialBetaAngleDegEditFieldValueChanged, true);
            app.IncrementBetaAngleDegEditField.Value = 5;
            app.LampEclipseFracsOverBetaAngle = uilamp(app.BetaAngleSweepPanel);
            app.DummyBox_2 = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.DummyBox_2.Editable = 'off';
            app.DummyBox_2.Enable = 'off';
            app.DummyBox_2.Visible = 'off';
            app.DummyBox_2.Placeholder = 'Dummy Box';
            app.DummyBox_3 = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.DummyBox_3.Editable = 'off';
            app.DummyBox_3.Enable = 'off';
            app.DummyBox_3.Visible = 'off';
            app.DummyBox_3.Placeholder = 'Dummy Box';
            app.DummyBox_4 = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.DummyBox_4.Editable = 'off';
            app.DummyBox_4.Enable = 'off';
            app.DummyBox_4.Visible = 'off';
            app.DummyBox_4.Placeholder = 'Dummy Box';
            app.Label_7 = uilabel(app.BetaAngleSweepPanel);
            app.Label_7.Enable = 'off';
            app.Label_7.Visible = 'off';
            app.Label_7.Text = ' -';
            app.Label_8 = uilabel(app.BetaAngleSweepPanel);
            app.Label_8.Enable = 'off';
            app.Label_8.Visible = 'off';
            app.Label_8.Text = ' -';
            app.Label_9 = uilabel(app.BetaAngleSweepPanel);
            app.Label_9.Enable = 'off';
            app.Label_9.Visible = 'off';
            app.Label_9.Text = ' -';
            app.DummyBox_1 = uidatepicker(app.BetaAngleSweepPanel);
            app.DummyBox_1.DisplayFormat = 'dd/MMM/uuuu';
            app.DummyBox_1.Editable = 'off';
            app.DummyBox_1.Enable = 'off';
            app.DummyBox_1.Visible = 'off';
            app.DummyBox_1.Placeholder = 'Dummy';
            app.DummyBox_1.Value = datetime([2025 12 1]);
            app.Label_10 = uilabel(app.BetaAngleSweepPanel);
            app.Label_10.Enable = 'off';
            app.Label_10.Visible = 'off';
            app.Label_10.Text = '-';
            app.degLabel_11 = uilabel(app.BetaAngleSweepPanel);
            app.degLabel_11.Text = ' deg';
            app.degLabel_12 = uilabel(app.BetaAngleSweepPanel);
            app.degLabel_12.Text = ' deg';
            app.degLabel_13 = uilabel(app.BetaAngleSweepPanel);
            app.degLabel_13.Text = ' deg';
            app.DummyBox_5 = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.DummyBox_5.Editable = 'off';
            app.DummyBox_5.Enable = 'off';
            app.DummyBox_5.Visible = 'off';
            app.DummyBox_5.Placeholder = 'Dummy Box';
            app.DummyBox_6 = uieditfield(app.BetaAngleSweepPanel, 'numeric');
            app.DummyBox_6.Editable = 'off';
            app.DummyBox_6.Enable = 'off';
            app.DummyBox_6.Visible = 'off';
            app.DummyBox_6.Placeholder = 'Dummy Box';
            app.GetEclipseFractionsButton = uibutton(app.BetaAngleSweepPanel, 'state');
            app.GetEclipseFractionsButton.ValueChangedFcn = createCallbackFcn(app, @GetEclipseFractionsButtonValueChanged, true);
            app.GetEclipseFractionsButton.Text = 'Get Eclipse Fractions';
            app.PrimaryBodyDropDown = uidropdown(app.Panel);
            app.PrimaryBodyDropDown.Items = {'Mercury', 'Venus', 'Earth', 'Moon', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.PrimaryBodyDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.PrimaryBodyDropDown.ClickedFcn = createCallbackFcn(app, @SatelliteOrbitalPeriod_sValueChanged, true);
            app.PrimaryBodyDropDown.Value = 'Earth';
            app.AnalysisModeButtonGroup = uibuttongroup(app.Panel);
            app.AnalysisModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisModeButtonGroupSelectionChanged, true);
            app.AnalysisModeButtonGroup.Title = 'Analysis Mode';
            app.GenericButton = uiradiobutton(app.AnalysisModeButtonGroup);
            app.GenericButton.Text = 'Generic';
            app.SpecificButton = uiradiobutton(app.AnalysisModeButtonGroup);
            app.SpecificButton.Text = 'Specific';
            app.SpecificButton.Value = true;
            app.PrimaryBodyLabel = uilabel(app.Panel);
            app.PrimaryBodyLabel.Text = 'Primary Body ';
            app.OrbitAltitudeLabel = uilabel(app.Panel);
            app.OrbitAltitudeLabel.Text = 'Orbit Altitude ';
            app.OrbitAltitudeKmEditField = uieditfield(app.Panel, 'numeric');
            app.OrbitAltitudeKmEditField.Limits = [0 Inf];
            app.OrbitAltitudeKmEditField.ValueChangedFcn = createCallbackFcn(app, @SatelliteOrbitalPeriod_sValueChanged, true);
            app.OrbitAltitudeKmEditField.Value = 400;
            app.TimeStepLabel = uilabel(app.Panel);
            app.TimeStepLabel.Text = 'Time Step ';
            app.TimeStepSSpinner = uispinner(app.Panel);
            app.TimeStepSSpinner.Limits = [1e-06 Inf];
            app.TimeStepSSpinner.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.TimeStepSSpinner.Value = 1;
            app.RadiusLabel = uilabel(app.Panel);
            app.RadiusLabel.Text = 'Radius ';
            app.PrimaryBodyRadiusKmEditField = uieditfield(app.Panel, 'numeric');
            app.PrimaryBodyRadiusKmEditField.Limits = [1e-16 Inf];
            app.PrimaryBodyRadiusKmEditField.AllowEmpty = 'on';
            app.PrimaryBodyRadiusKmEditField.ValueChangedFcn = createCallbackFcn(app, @SatelliteOrbitalPeriod_sValueChanged, true);
            app.PrimaryBodyRadiusKmEditField.Value = 1e-16;
            app.MassLabel_7 = uilabel(app.Panel);
            app.MassLabel_7.Text = 'Mass ';
            app.PrimaryBodyMassKgEditField = uieditfield(app.Panel, 'numeric');
            app.PrimaryBodyMassKgEditField.Limits = [1e-16 Inf];
            app.PrimaryBodyMassKgEditField.ValueChangedFcn = createCallbackFcn(app, @SatelliteOrbitalPeriod_sValueChanged, true);
            app.PrimaryBodyMassKgEditField.Value = 1e-16;
            app.EquatorInclinationLabel = uilabel(app.Panel);
            app.EquatorInclinationLabel.Text = 'Equator Inclination ';
            app.PrimaryBodyEquatorInclinationDegEditField = uieditfield(app.Panel, 'numeric');
            app.PrimaryBodyEquatorInclinationDegEditField.Limits = [0 180];
            app.SolarFluxLabel = uilabel(app.Panel);
            app.SolarFluxLabel.Text = 'Solar Flux ';
            app.SolarFluxHotWm2EditField = uieditfield(app.Panel, 'numeric');
            app.SolarFluxHotWm2EditField.Limits = [0 Inf];
            app.AlbedoLabel = uilabel(app.Panel);
            app.AlbedoLabel.Text = 'Albedo Factor ';
            app.AlbedoHotEditField = uieditfield(app.Panel, 'numeric');
            app.AlbedoHotEditField.Limits = [0 1];
            app.SunSideIRPlanetshineWm2Label = uilabel(app.Panel);
            app.SunSideIRPlanetshineWm2Label.Text = 'Sun-Side IR Planetshine ';
            app.SunSideIRPlanetshineHotWm2EditField = uieditfield(app.Panel, 'numeric');
            app.SunSideIRPlanetshineHotWm2EditField.Limits = [0 Inf];
            app.DarkSideIRPlanetshineWm2Label = uilabel(app.Panel);
            app.DarkSideIRPlanetshineWm2Label.Text = 'Dark-Side IR Planetshine ';
            app.DarkSideIRPlanetshineHotWm2EditField = uieditfield(app.Panel, 'numeric');
            app.DarkSideIRPlanetshineHotWm2EditField.Limits = [0 Inf];
            app.J2ConstantLabel = uilabel(app.Panel);
            app.J2ConstantLabel.Text = 'J2 Constant ';
            app.PrimaryBodyJ2ConstantEditField = uieditfield(app.Panel, 'numeric');
            app.PrimaryBodyJ2ConstantEditField.Limits = [0 Inf];
            app.SimulationDurationLabel = uilabel(app.Panel);
            app.SimulationDurationLabel.Text = 'Simulation Duration ';
            app.SimulationDurationSEditField = uieditfield(app.Panel, 'numeric');
            app.SimulationDurationSEditField.Limits = [1 Inf];
            app.SimulationDurationSEditField.RoundFractionalValues = 'on';
            app.SimulationDurationSEditField.ValueDisplayFormat = '%11.6g';
            app.SimulationDurationSEditField.ValueChangedFcn = createCallbackFcn(app, @PrimaryBodyOrTimingValueChanged, true);
            app.SimulationDurationSEditField.Placeholder = '-';
            app.SimulationDurationSEditField.Value = 25000;
            app.PlotPanel_AnglesOverTime = uipanel(app.GridLayout9);
            app.PlotPanel_AnglesOverTime.Title = 'Beta Evolution';
            app.SatelliteDataTab = uitab(app.TabGroup);
            app.SatelliteDataTab.Title = 'Satellite Data';
            app.GridLayout5 = uigridlayout(app.SatelliteDataTab);
            app.ContactConductanceMatrixWCPanel = uipanel(app.GridLayout5);
            app.ContactConductanceMatrixWCPanel.Title = 'Contact Conductance Matrix [W/°C]';
            app.ContactConductanceUITable = uitable(app.ContactConductanceMatrixWCPanel);
            app.ContactConductanceUITable.ColumnName = '';
            app.ContactConductanceUITable.RowName = {};
            app.ContactConductanceUITable.ColumnEditable = [true true true true true true true];
            app.ContactConductanceUITable.CellEditCallback = createCallbackFcn(app, @ContactConductanceUITableCellEdit, true);
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.ZenithTab = uitab(app.TabGroup2);
            app.ZenithTab.Title = 'Zenith';
            app.SatelliteFacePanel_Zenith = uipanel(app.ZenithTab);
            app.SatelliteFacePanel_Zenith.Title = 'Satellite Face';
            app.GridLayout_Zenith = uigridlayout(app.SatelliteFacePanel_Zenith);
            app.InitialTemperatureColdCEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.InitialTemperatureColdCEditField_Zenith.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_Zenith.Value = 20;
            app.HotLabel_3 = uilabel(app.GridLayout_Zenith);
            app.HotLabel_3.Text = 'Hot';
            app.ColdLabel_3 = uilabel(app.GridLayout_Zenith);
            app.ColdLabel_3.Text = 'Cold';
            app.SunlightLabel = uilabel(app.GridLayout_Zenith);
            app.SunlightLabel.Text = 'Sunlight';
            app.ShadowLabel = uilabel(app.GridLayout_Zenith);
            app.ShadowLabel.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.InternalHeatLoadShadowWEditField_Zenith.Limits = [0 Inf];
            app.kgLabel_2 = uilabel(app.GridLayout_Zenith);
            app.kgLabel_2.Text = 'kg';
            app.m2Label = uilabel(app.GridLayout_Zenith);
            app.m2Label.Text = 'm2';
            app.JkgCLabel = uilabel(app.GridLayout_Zenith);
            app.JkgCLabel.Text = 'J/kg/°C';
            app.CLabel = uilabel(app.GridLayout_Zenith);
            app.CLabel.Text = '°C';
            app.WLabel = uilabel(app.GridLayout_Zenith);
            app.WLabel.Text = 'W';
            app.MassLabel = uilabel(app.GridLayout_Zenith);
            app.MassLabel.Text = 'Mass';
            app.FaceMasskgEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.FaceMasskgEditField_Zenith.Limits = [0.001 Inf];
            app.FaceMasskgEditField_Zenith.Value = 0.25;
            app.AreaEditFieldLabel = uilabel(app.GridLayout_Zenith);
            app.AreaEditFieldLabel.Text = 'Area';
            app.Aream2EditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.Aream2EditField_Zenith.Limits = [1e-06 Inf];
            app.Aream2EditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_Zenith.Value = 0.01;
            app.SpecificHeatEditFieldLabel = uilabel(app.GridLayout_Zenith);
            app.SpecificHeatEditFieldLabel.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.SpecificHeatJpkgKEditField_Zenith.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_Zenith.Value = 896;
            app.EmissivityEditFieldLabel = uilabel(app.GridLayout_Zenith);
            app.EmissivityEditFieldLabel.Text = 'Emissivity';
            app.EmissivityEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.EmissivityEditField_Zenith.Limits = [0 1];
            app.EmissivityEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_Zenith.Value = 1;
            app.InitialTemperatureLabel = uilabel(app.GridLayout_Zenith);
            app.InitialTemperatureLabel.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.InitialTemperatureHotCEditField_Zenith.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_Zenith.Value = 20;
            app.InternalHeatLoadWLabel = uilabel(app.GridLayout_Zenith);
            app.InternalHeatLoadWLabel.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.InternalHeatLoadSunlightWEditField_Zenith.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_Zenith.Value = 0.25;
            app.AbsorptivityEditFieldLabel = uilabel(app.GridLayout_Zenith);
            app.AbsorptivityEditFieldLabel.Text = 'Absorptivity';
            app.AbsorptivityEditField_Zenith = uieditfield(app.GridLayout_Zenith, 'numeric');
            app.AbsorptivityEditField_Zenith.Limits = [0 1];
            app.AbsorptivityEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_Zenith.Value = 1;
            app.SolarPanel_Zenith = uipanel(app.ZenithTab);
            app.SolarPanel_Zenith.Title = 'Solar Panel';
            app.GridLayout2_Zenith = uigridlayout(app.SolarPanel_Zenith);
            app.Label = uilabel(app.GridLayout2_Zenith);
            app.Label.Text = '%';
            app.EfficiencyLabel = uilabel(app.GridLayout2_Zenith);
            app.EfficiencyLabel.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_Zenith = uieditfield(app.GridLayout2_Zenith, 'numeric');
            app.SolarPanelEfficiencyEditField_Zenith.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_Zenith.Value = 0.3;
            app.AbsorptivityEditField_2Label = uilabel(app.GridLayout2_Zenith);
            app.AbsorptivityEditField_2Label.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_Zenith = uieditfield(app.GridLayout2_Zenith, 'numeric');
            app.SolarPanelAbsorptivityEditField_Zenith.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_Zenith.Value = 0.902;
            app.EmissivityEditField_2Label = uilabel(app.GridLayout2_Zenith);
            app.EmissivityEditField_2Label.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_Zenith = uieditfield(app.GridLayout2_Zenith, 'numeric');
            app.SolarPanelEmissivityEditField_Zenith.Limits = [0 1];
            app.SolarPanelEmissivityEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_Zenith.Value = 0.908;
            app.AreaLabel = uilabel(app.GridLayout2_Zenith);
            app.AreaLabel.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_Zenith = uieditfield(app.GridLayout2_Zenith, 'numeric');
            app.SolarPanelCoveragePercentEditField_Zenith.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_Zenith.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_Zenith.Value = 90;
            app.SolarPanelMountingButtonGroup_Zenith = uibuttongroup(app.GridLayout2_Zenith);
            app.SolarPanelMountingButtonGroup_Zenith.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_Zenith.Title = 'Mounting';
            app.NoneButton_Zenith = uiradiobutton(app.SolarPanelMountingButtonGroup_Zenith);
            app.NoneButton_Zenith.Text = 'None';
            app.FaceFixedButton_Zenith = uiradiobutton(app.SolarPanelMountingButtonGroup_Zenith);
            app.FaceFixedButton_Zenith.Text = 'Body-Fixed';
            app.FaceFixedButton_Zenith.Value = true;
            app.HeaterPropertiesPanel_Zenith = uipanel(app.ZenithTab);
            app.HeaterPropertiesPanel_Zenith.Title = 'Heater Properties';
            app.GridLayout3_Zenith = uigridlayout(app.HeaterPropertiesPanel_Zenith);
            app.TemperatureThresholdHeaterOFFEditField_Zenith = uieditfield(app.GridLayout3_Zenith, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_Zenith.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_Zenith.Value = 10;
            app.BangBangHeaterControlSwitchONLabel = uilabel(app.GridLayout3_Zenith);
            app.BangBangHeaterControlSwitchONLabel.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel = uilabel(app.GridLayout3_Zenith);
            app.BangBangHeaterControlSwitchOFFLabel.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_Zenith = uieditfield(app.GridLayout3_Zenith, 'numeric');
            app.TemperatureThresholdHeaterONEditField_Zenith.Limits = [-273.15 Inf];
            app.CLabel_2 = uilabel(app.GridLayout3_Zenith);
            app.CLabel_2.Text = '°C';
            app.TempThresholdCLabel = uilabel(app.GridLayout3_Zenith);
            app.TempThresholdCLabel.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel = uilabel(app.GridLayout3_Zenith);
            app.BangBangHeaterControlSwitchLabel.Text = 'Bang-Bang Control';
            app.WLabel_2 = uilabel(app.GridLayout3_Zenith);
            app.WLabel_2.Text = 'W';
            app.HeaterPowerEditFieldLabel = uilabel(app.GridLayout3_Zenith);
            app.HeaterPowerEditFieldLabel.Text = 'Heater Power';
            app.HeaterPowerEditField_Zenith = uieditfield(app.GridLayout3_Zenith, 'numeric');
            app.HeaterPowerEditField_Zenith.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_Zenith = uiswitch(app.GridLayout3_Zenith, 'slider');
            app.BangBangHeaterControlSwitch_Zenith.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_Zenith = uipanel(app.ZenithTab);
            app.EffectiveSurfacePropertiesPanel_Zenith.Title = 'Effective Surface Properties';
            app.GridLayout7 = uigridlayout(app.EffectiveSurfacePropertiesPanel_Zenith);
            app.EffectiveEmissivityLabel_9 = uilabel(app.GridLayout7);
            app.EffectiveEmissivityLabel_9.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_Zenith = uieditfield(app.GridLayout7, 'numeric');
            app.EmissivityEffectiveHotEditField_Zenith.Limits = [0 1];
            app.HotLabel_9 = uilabel(app.GridLayout7);
            app.HotLabel_9.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_Zenith = uieditfield(app.GridLayout7, 'numeric');
            app.EmissivityEffectiveColdEditField_Zenith.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_Zenith = uieditfield(app.GridLayout7, 'numeric');
            app.AbsorptivityEffectiveColdEditField_Zenith.Limits = [0 1];
            app.ColdLabel_10 = uilabel(app.GridLayout7);
            app.ColdLabel_10.Text = 'Cold';
            app.RatioEffectiveColdEditField_Zenith = uieditfield(app.GridLayout7, 'text');
            app.RatioEffectiveColdEditField_Zenith.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel = uilabel(app.GridLayout7);
            app.EffectiveAbsorptivityLabel.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_Zenith = uieditfield(app.GridLayout7, 'numeric');
            app.AbsorptivityEffectiveHotEditField_Zenith.Limits = [0 1];
            app.RatioEditFieldLabel = uilabel(app.GridLayout7);
            app.RatioEditFieldLabel.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_Zenith = uieditfield(app.GridLayout7, 'text');
            app.RatioEffectiveHotEditField_Zenith.CharacterLimits = [0 10];
            app.NadirTab = uitab(app.TabGroup2);
            app.NadirTab.Title = 'Nadir';
            app.SatelliteFacePanel_Nadir = uipanel(app.NadirTab);
            app.SatelliteFacePanel_Nadir.Title = 'Satellite Face';
            app.GridLayout_Nadir = uigridlayout(app.SatelliteFacePanel_Nadir);
            app.InitialTemperatureColdCEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.InitialTemperatureColdCEditField_Nadir.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_Nadir.Value = 20;
            app.HotLabel_4 = uilabel(app.GridLayout_Nadir);
            app.HotLabel_4.Text = 'Hot';
            app.ColdLabel_4 = uilabel(app.GridLayout_Nadir);
            app.ColdLabel_4.Text = 'Cold';
            app.SunlightLabel_2 = uilabel(app.GridLayout_Nadir);
            app.SunlightLabel_2.Text = 'Sunlight';
            app.ShadowLabel_2 = uilabel(app.GridLayout_Nadir);
            app.ShadowLabel_2.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.InternalHeatLoadShadowWEditField_Nadir.Limits = [0 Inf];
            app.kgLabel_4 = uilabel(app.GridLayout_Nadir);
            app.kgLabel_4.Text = 'kg';
            app.m2Label_3 = uilabel(app.GridLayout_Nadir);
            app.m2Label_3.Text = 'm2';
            app.JkgCLabel_2 = uilabel(app.GridLayout_Nadir);
            app.JkgCLabel_2.Text = 'J/kg/°C';
            app.CLabel_3 = uilabel(app.GridLayout_Nadir);
            app.CLabel_3.Text = '°C';
            app.WLabel_3 = uilabel(app.GridLayout_Nadir);
            app.WLabel_3.Text = 'W';
            app.MassLabel_2 = uilabel(app.GridLayout_Nadir);
            app.MassLabel_2.Text = 'Mass';
            app.FaceMasskgEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.FaceMasskgEditField_Nadir.Limits = [0.001 Inf];
            app.FaceMasskgEditField_Nadir.Value = 0.25;
            app.AreaEditFieldLabel_2 = uilabel(app.GridLayout_Nadir);
            app.AreaEditFieldLabel_2.Text = 'Area';
            app.Aream2EditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.Aream2EditField_Nadir.Limits = [1e-06 Inf];
            app.Aream2EditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_Nadir.Value = 0.01;
            app.SpecificHeatEditFieldLabel_2 = uilabel(app.GridLayout_Nadir);
            app.SpecificHeatEditFieldLabel_2.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.SpecificHeatJpkgKEditField_Nadir.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_Nadir.Value = 896;
            app.EmissivityEditFieldLabel_2 = uilabel(app.GridLayout_Nadir);
            app.EmissivityEditFieldLabel_2.Text = 'Emissivity';
            app.EmissivityEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.EmissivityEditField_Nadir.Limits = [0 1];
            app.EmissivityEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_Nadir.Value = 1;
            app.InitialTemperatureLabel_2 = uilabel(app.GridLayout_Nadir);
            app.InitialTemperatureLabel_2.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.InitialTemperatureHotCEditField_Nadir.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_Nadir.Value = 20;
            app.InternalHeatLoadWLabel_2 = uilabel(app.GridLayout_Nadir);
            app.InternalHeatLoadWLabel_2.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.InternalHeatLoadSunlightWEditField_Nadir.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_Nadir.Value = 0.25;
            app.AbsorptivityEditFieldLabel_2 = uilabel(app.GridLayout_Nadir);
            app.AbsorptivityEditFieldLabel_2.Text = 'Absorptivity';
            app.AbsorptivityEditField_Nadir = uieditfield(app.GridLayout_Nadir, 'numeric');
            app.AbsorptivityEditField_Nadir.Limits = [0 1];
            app.AbsorptivityEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_Nadir.Value = 1;
            app.SolarPanel_Nadir = uipanel(app.NadirTab);
            app.SolarPanel_Nadir.Title = 'Solar Panel';
            app.GridLayout2_Nadir = uigridlayout(app.SolarPanel_Nadir);
            app.Label_3 = uilabel(app.GridLayout2_Nadir);
            app.Label_3.Text = '%';
            app.PanelEfficiencyLabel = uilabel(app.GridLayout2_Nadir);
            app.PanelEfficiencyLabel.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_Nadir = uieditfield(app.GridLayout2_Nadir, 'numeric');
            app.SolarPanelEfficiencyEditField_Nadir.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_Nadir.Value = 0.3;
            app.AbsorptivityEditField_2Label_2 = uilabel(app.GridLayout2_Nadir);
            app.AbsorptivityEditField_2Label_2.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_Nadir = uieditfield(app.GridLayout2_Nadir, 'numeric');
            app.SolarPanelAbsorptivityEditField_Nadir.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_Nadir.Value = 0.902;
            app.EmissivityEditField_2Label_2 = uilabel(app.GridLayout2_Nadir);
            app.EmissivityEditField_2Label_2.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_Nadir = uieditfield(app.GridLayout2_Nadir, 'numeric');
            app.SolarPanelEmissivityEditField_Nadir.Limits = [0 1];
            app.SolarPanelEmissivityEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_Nadir.Value = 0.908;
            app.AreaLabel_2 = uilabel(app.GridLayout2_Nadir);
            app.AreaLabel_2.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_Nadir = uieditfield(app.GridLayout2_Nadir, 'numeric');
            app.SolarPanelCoveragePercentEditField_Nadir.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_Nadir.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_Nadir.Value = 90;
            app.SolarPanelMountingButtonGroup_Nadir = uibuttongroup(app.GridLayout2_Nadir);
            app.SolarPanelMountingButtonGroup_Nadir.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_Nadir.Title = 'Mounting';
            app.NoneButton_Nadir = uiradiobutton(app.SolarPanelMountingButtonGroup_Nadir);
            app.NoneButton_Nadir.Text = 'None';
            app.FaceFixedButton_Nadir = uiradiobutton(app.SolarPanelMountingButtonGroup_Nadir);
            app.FaceFixedButton_Nadir.Text = 'Body-Fixed';
            app.FaceFixedButton_Nadir.Value = true;
            app.HeaterPropertiesPanel_Nadir = uipanel(app.NadirTab);
            app.HeaterPropertiesPanel_Nadir.Title = 'Heater Properties';
            app.GridLayout3_Nadir = uigridlayout(app.HeaterPropertiesPanel_Nadir);
            app.TemperatureThresholdHeaterOFFEditField_Nadir = uieditfield(app.GridLayout3_Nadir, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_Nadir.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_Nadir.Value = 10;
            app.BangBangHeaterControlSwitchONLabel_2 = uilabel(app.GridLayout3_Nadir);
            app.BangBangHeaterControlSwitchONLabel_2.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel_2 = uilabel(app.GridLayout3_Nadir);
            app.BangBangHeaterControlSwitchOFFLabel_2.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_Nadir = uieditfield(app.GridLayout3_Nadir, 'numeric');
            app.TemperatureThresholdHeaterONEditField_Nadir.Limits = [-273.15 Inf];
            app.CLabel_4 = uilabel(app.GridLayout3_Nadir);
            app.CLabel_4.Text = '°C';
            app.TempThresholdCLabel_2 = uilabel(app.GridLayout3_Nadir);
            app.TempThresholdCLabel_2.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel_2 = uilabel(app.GridLayout3_Nadir);
            app.BangBangHeaterControlSwitchLabel_2.Text = 'Bang-Bang Control';
            app.WLabel_4 = uilabel(app.GridLayout3_Nadir);
            app.WLabel_4.Text = 'W';
            app.HeaterPowerEditFieldLabel_2 = uilabel(app.GridLayout3_Nadir);
            app.HeaterPowerEditFieldLabel_2.Text = 'Heater Power';
            app.HeaterPowerEditField_Nadir = uieditfield(app.GridLayout3_Nadir, 'numeric');
            app.HeaterPowerEditField_Nadir.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_Nadir = uiswitch(app.GridLayout3_Nadir, 'slider');
            app.BangBangHeaterControlSwitch_Nadir.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_Nadir = uipanel(app.NadirTab);
            app.EffectiveSurfacePropertiesPanel_Nadir.Title = 'Effective Surface Properties';
            app.GridLayout7_2 = uigridlayout(app.EffectiveSurfacePropertiesPanel_Nadir);
            app.EffectiveEmissivityLabel_15 = uilabel(app.GridLayout7_2);
            app.EffectiveEmissivityLabel_15.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_Nadir = uieditfield(app.GridLayout7_2, 'numeric');
            app.EmissivityEffectiveHotEditField_Nadir.Limits = [0 1];
            app.HotLabel_15 = uilabel(app.GridLayout7_2);
            app.HotLabel_15.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_Nadir = uieditfield(app.GridLayout7_2, 'numeric');
            app.EmissivityEffectiveColdEditField_Nadir.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_Nadir = uieditfield(app.GridLayout7_2, 'numeric');
            app.AbsorptivityEffectiveColdEditField_Nadir.Limits = [0 1];
            app.ColdLabel_16 = uilabel(app.GridLayout7_2);
            app.ColdLabel_16.Text = 'Cold';
            app.RatioEffectiveColdEditField_Nadir = uieditfield(app.GridLayout7_2, 'text');
            app.RatioEffectiveColdEditField_Nadir.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel_12 = uilabel(app.GridLayout7_2);
            app.EffectiveAbsorptivityLabel_12.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_Nadir = uieditfield(app.GridLayout7_2, 'numeric');
            app.AbsorptivityEffectiveHotEditField_Nadir.Limits = [0 1];
            app.RatioEditFieldLabel_12 = uilabel(app.GridLayout7_2);
            app.RatioEditFieldLabel_12.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_Nadir = uieditfield(app.GridLayout7_2, 'text');
            app.RatioEffectiveHotEditField_Nadir.CharacterLimits = [0 10];
            app.ForwardTab = uitab(app.TabGroup2);
            app.ForwardTab.Title = 'Forward';
            app.SolarPanel_Forward = uipanel(app.ForwardTab);
            app.SolarPanel_Forward.Title = 'Solar Panel';
            app.GridLayout2_Forward = uigridlayout(app.SolarPanel_Forward);
            app.Label_4 = uilabel(app.GridLayout2_Forward);
            app.Label_4.Text = '%';
            app.PanelEfficiencyLabel_2 = uilabel(app.GridLayout2_Forward);
            app.PanelEfficiencyLabel_2.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_Forward = uieditfield(app.GridLayout2_Forward, 'numeric');
            app.SolarPanelEfficiencyEditField_Forward.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_Forward.Value = 0.3;
            app.AbsorptivityEditField_2Label_3 = uilabel(app.GridLayout2_Forward);
            app.AbsorptivityEditField_2Label_3.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_Forward = uieditfield(app.GridLayout2_Forward, 'numeric');
            app.SolarPanelAbsorptivityEditField_Forward.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_Forward.Value = 0.902;
            app.EmissivityEditField_2Label_3 = uilabel(app.GridLayout2_Forward);
            app.EmissivityEditField_2Label_3.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_Forward = uieditfield(app.GridLayout2_Forward, 'numeric');
            app.SolarPanelEmissivityEditField_Forward.Limits = [0 1];
            app.SolarPanelEmissivityEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_Forward.Value = 0.908;
            app.AreaLabel_3 = uilabel(app.GridLayout2_Forward);
            app.AreaLabel_3.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_Forward = uieditfield(app.GridLayout2_Forward, 'numeric');
            app.SolarPanelCoveragePercentEditField_Forward.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_Forward.Value = 90;
            app.SolarPanelMountingButtonGroup_Forward = uibuttongroup(app.GridLayout2_Forward);
            app.SolarPanelMountingButtonGroup_Forward.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_Forward.Title = 'Mounting';
            app.NoneButton_Forward = uiradiobutton(app.SolarPanelMountingButtonGroup_Forward);
            app.NoneButton_Forward.Text = 'None';
            app.FaceFixedButton_Forward = uiradiobutton(app.SolarPanelMountingButtonGroup_Forward);
            app.FaceFixedButton_Forward.Text = 'Body-Fixed';
            app.FaceFixedButton_Forward.Value = true;
            app.HeaterPropertiesPanel_Forward = uipanel(app.ForwardTab);
            app.HeaterPropertiesPanel_Forward.Title = 'Heater Properties';
            app.GridLayout3_Forward = uigridlayout(app.HeaterPropertiesPanel_Forward);
            app.TemperatureThresholdHeaterOFFEditField_Forward = uieditfield(app.GridLayout3_Forward, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_Forward.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_Forward.Value = 10;
            app.BangBangHeaterControlSwitchONLabel_3 = uilabel(app.GridLayout3_Forward);
            app.BangBangHeaterControlSwitchONLabel_3.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel_3 = uilabel(app.GridLayout3_Forward);
            app.BangBangHeaterControlSwitchOFFLabel_3.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_Forward = uieditfield(app.GridLayout3_Forward, 'numeric');
            app.TemperatureThresholdHeaterONEditField_Forward.Limits = [-273.15 Inf];
            app.CLabel_6 = uilabel(app.GridLayout3_Forward);
            app.CLabel_6.Text = '°C';
            app.TempThresholdCLabel_3 = uilabel(app.GridLayout3_Forward);
            app.TempThresholdCLabel_3.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel_3 = uilabel(app.GridLayout3_Forward);
            app.BangBangHeaterControlSwitchLabel_3.Text = 'Bang-Bang Control';
            app.WLabel_6 = uilabel(app.GridLayout3_Forward);
            app.WLabel_6.Text = 'W';
            app.HeaterPowerEditFieldLabel_3 = uilabel(app.GridLayout3_Forward);
            app.HeaterPowerEditFieldLabel_3.Text = 'Heater Power';
            app.HeaterPowerEditField_Forward = uieditfield(app.GridLayout3_Forward, 'numeric');
            app.HeaterPowerEditField_Forward.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_Forward = uiswitch(app.GridLayout3_Forward, 'slider');
            app.BangBangHeaterControlSwitch_Forward.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_Forward = uipanel(app.ForwardTab);
            app.EffectiveSurfacePropertiesPanel_Forward.Title = 'Effective Surface Properties';
            app.GridLayout7_3 = uigridlayout(app.EffectiveSurfacePropertiesPanel_Forward);
            app.EffectiveEmissivityLabel_16 = uilabel(app.GridLayout7_3);
            app.EffectiveEmissivityLabel_16.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_Forward = uieditfield(app.GridLayout7_3, 'numeric');
            app.EmissivityEffectiveHotEditField_Forward.Limits = [0 1];
            app.HotLabel_16 = uilabel(app.GridLayout7_3);
            app.HotLabel_16.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_Forward = uieditfield(app.GridLayout7_3, 'numeric');
            app.EmissivityEffectiveColdEditField_Forward.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_Forward = uieditfield(app.GridLayout7_3, 'numeric');
            app.AbsorptivityEffectiveColdEditField_Forward.Limits = [0 1];
            app.ColdLabel_17 = uilabel(app.GridLayout7_3);
            app.ColdLabel_17.Text = 'Cold';
            app.RatioEffectiveColdEditField_Forward = uieditfield(app.GridLayout7_3, 'text');
            app.RatioEffectiveColdEditField_Forward.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel_13 = uilabel(app.GridLayout7_3);
            app.EffectiveAbsorptivityLabel_13.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_Forward = uieditfield(app.GridLayout7_3, 'numeric');
            app.AbsorptivityEffectiveHotEditField_Forward.Limits = [0 1];
            app.RatioEditFieldLabel_13 = uilabel(app.GridLayout7_3);
            app.RatioEditFieldLabel_13.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_Forward = uieditfield(app.GridLayout7_3, 'text');
            app.RatioEffectiveHotEditField_Forward.CharacterLimits = [0 10];
            app.SatelliteFacePanel_Forward = uipanel(app.ForwardTab);
            app.SatelliteFacePanel_Forward.Title = 'Satellite Face';
            app.GridLayout_Forward = uigridlayout(app.SatelliteFacePanel_Forward);
            app.InitialTemperatureColdCEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.InitialTemperatureColdCEditField_Forward.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_Forward.Value = 20;
            app.HotLabel_5 = uilabel(app.GridLayout_Forward);
            app.HotLabel_5.Text = 'Hot';
            app.ColdLabel_5 = uilabel(app.GridLayout_Forward);
            app.ColdLabel_5.Text = 'Cold';
            app.SunlightLabel_3 = uilabel(app.GridLayout_Forward);
            app.SunlightLabel_3.Text = 'Sunlight';
            app.ShadowLabel_3 = uilabel(app.GridLayout_Forward);
            app.ShadowLabel_3.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.InternalHeatLoadShadowWEditField_Forward.Limits = [0 Inf];
            app.kgLabel_5 = uilabel(app.GridLayout_Forward);
            app.kgLabel_5.Text = 'kg';
            app.m2Label_5 = uilabel(app.GridLayout_Forward);
            app.m2Label_5.Text = 'm2';
            app.JkgCLabel_3 = uilabel(app.GridLayout_Forward);
            app.JkgCLabel_3.Text = 'J/kg/°C';
            app.CLabel_5 = uilabel(app.GridLayout_Forward);
            app.CLabel_5.Text = '°C';
            app.WLabel_5 = uilabel(app.GridLayout_Forward);
            app.WLabel_5.Text = 'W';
            app.MassLabel_3 = uilabel(app.GridLayout_Forward);
            app.MassLabel_3.Text = 'Mass';
            app.FaceMasskgEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.FaceMasskgEditField_Forward.Limits = [0.001 Inf];
            app.FaceMasskgEditField_Forward.Value = 0.25;
            app.AreaEditFieldLabel_3 = uilabel(app.GridLayout_Forward);
            app.AreaEditFieldLabel_3.Text = 'Area';
            app.Aream2EditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.Aream2EditField_Forward.Limits = [1e-06 Inf];
            app.Aream2EditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_Forward.Value = 0.01;
            app.SpecificHeatEditFieldLabel_3 = uilabel(app.GridLayout_Forward);
            app.SpecificHeatEditFieldLabel_3.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.SpecificHeatJpkgKEditField_Forward.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_Forward.Value = 896;
            app.EmissivityEditFieldLabel_3 = uilabel(app.GridLayout_Forward);
            app.EmissivityEditFieldLabel_3.Text = 'Emissivity';
            app.EmissivityEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.EmissivityEditField_Forward.Limits = [0 1];
            app.EmissivityEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_Forward.Value = 1;
            app.InitialTemperatureLabel_3 = uilabel(app.GridLayout_Forward);
            app.InitialTemperatureLabel_3.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.InitialTemperatureHotCEditField_Forward.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_Forward.Value = 20;
            app.InternalHeatLoadWLabel_3 = uilabel(app.GridLayout_Forward);
            app.InternalHeatLoadWLabel_3.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.InternalHeatLoadSunlightWEditField_Forward.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_Forward.Value = 0.25;
            app.AbsorptivityEditFieldLabel_3 = uilabel(app.GridLayout_Forward);
            app.AbsorptivityEditFieldLabel_3.Text = 'Absorptivity';
            app.AbsorptivityEditField_Forward = uieditfield(app.GridLayout_Forward, 'numeric');
            app.AbsorptivityEditField_Forward.Limits = [0 1];
            app.AbsorptivityEditField_Forward.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_Forward.Value = 1;
            app.AftTab = uitab(app.TabGroup2);
            app.AftTab.Title = 'Aft';
            app.SatelliteFacePanel_Aft = uipanel(app.AftTab);
            app.SatelliteFacePanel_Aft.Title = 'Satellite Face';
            app.GridLayout_Aft = uigridlayout(app.SatelliteFacePanel_Aft);
            app.InitialTemperatureColdCEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.InitialTemperatureColdCEditField_Aft.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_Aft.Value = 20;
            app.HotLabel_6 = uilabel(app.GridLayout_Aft);
            app.HotLabel_6.Text = 'Hot';
            app.ColdLabel_6 = uilabel(app.GridLayout_Aft);
            app.ColdLabel_6.Text = 'Cold';
            app.SunlightLabel_4 = uilabel(app.GridLayout_Aft);
            app.SunlightLabel_4.Text = 'Sunlight';
            app.ShadowLabel_4 = uilabel(app.GridLayout_Aft);
            app.ShadowLabel_4.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.InternalHeatLoadShadowWEditField_Aft.Limits = [0 Inf];
            app.kgLabel_6 = uilabel(app.GridLayout_Aft);
            app.kgLabel_6.Text = 'kg';
            app.m2Label_7 = uilabel(app.GridLayout_Aft);
            app.m2Label_7.Text = 'm2';
            app.JkgCLabel_4 = uilabel(app.GridLayout_Aft);
            app.JkgCLabel_4.Text = 'J/kg/°C';
            app.CLabel_7 = uilabel(app.GridLayout_Aft);
            app.CLabel_7.Text = '°C';
            app.WLabel_7 = uilabel(app.GridLayout_Aft);
            app.WLabel_7.Text = 'W';
            app.MassLabel_4 = uilabel(app.GridLayout_Aft);
            app.MassLabel_4.Text = 'Mass';
            app.FaceMasskgEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.FaceMasskgEditField_Aft.Limits = [0.001 Inf];
            app.FaceMasskgEditField_Aft.Value = 0.25;
            app.AreaEditFieldLabel_4 = uilabel(app.GridLayout_Aft);
            app.AreaEditFieldLabel_4.Text = 'Area';
            app.Aream2EditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.Aream2EditField_Aft.Limits = [1e-06 Inf];
            app.Aream2EditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_Aft.Value = 0.01;
            app.SpecificHeatEditFieldLabel_4 = uilabel(app.GridLayout_Aft);
            app.SpecificHeatEditFieldLabel_4.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.SpecificHeatJpkgKEditField_Aft.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_Aft.Value = 896;
            app.EmissivityEditFieldLabel_4 = uilabel(app.GridLayout_Aft);
            app.EmissivityEditFieldLabel_4.Text = 'Emissivity';
            app.EmissivityEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.EmissivityEditField_Aft.Limits = [0 1];
            app.EmissivityEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_Aft.Value = 1;
            app.InitialTemperatureLabel_4 = uilabel(app.GridLayout_Aft);
            app.InitialTemperatureLabel_4.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.InitialTemperatureHotCEditField_Aft.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_Aft.Value = 20;
            app.InternalHeatLoadWLabel_4 = uilabel(app.GridLayout_Aft);
            app.InternalHeatLoadWLabel_4.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.InternalHeatLoadSunlightWEditField_Aft.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_Aft.Value = 0.25;
            app.AbsorptivityEditFieldLabel_4 = uilabel(app.GridLayout_Aft);
            app.AbsorptivityEditFieldLabel_4.Text = 'Absorptivity';
            app.AbsorptivityEditField_Aft = uieditfield(app.GridLayout_Aft, 'numeric');
            app.AbsorptivityEditField_Aft.Limits = [0 1];
            app.AbsorptivityEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_Aft.Value = 1;
            app.SolarPanel_Aft = uipanel(app.AftTab);
            app.SolarPanel_Aft.Title = 'Solar Panel';
            app.GridLayout2_Aft = uigridlayout(app.SolarPanel_Aft);
            app.Label_2 = uilabel(app.GridLayout2_Aft);
            app.Label_2.Text = '%';
            app.PanelEfficiencyLabel_3 = uilabel(app.GridLayout2_Aft);
            app.PanelEfficiencyLabel_3.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_Aft = uieditfield(app.GridLayout2_Aft, 'numeric');
            app.SolarPanelEfficiencyEditField_Aft.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_Aft.Value = 0.3;
            app.AbsorptivityEditField_2Label_4 = uilabel(app.GridLayout2_Aft);
            app.AbsorptivityEditField_2Label_4.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_Aft = uieditfield(app.GridLayout2_Aft, 'numeric');
            app.SolarPanelAbsorptivityEditField_Aft.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_Aft.Value = 0.902;
            app.EmissivityEditField_2Label_4 = uilabel(app.GridLayout2_Aft);
            app.EmissivityEditField_2Label_4.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_Aft = uieditfield(app.GridLayout2_Aft, 'numeric');
            app.SolarPanelEmissivityEditField_Aft.Limits = [0 1];
            app.SolarPanelEmissivityEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_Aft.Value = 0.908;
            app.AreaLabel_4 = uilabel(app.GridLayout2_Aft);
            app.AreaLabel_4.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_Aft = uieditfield(app.GridLayout2_Aft, 'numeric');
            app.SolarPanelCoveragePercentEditField_Aft.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_Aft.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_Aft.Value = 90;
            app.SolarPanelMountingButtonGroup_Aft = uibuttongroup(app.GridLayout2_Aft);
            app.SolarPanelMountingButtonGroup_Aft.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_Aft.Title = 'Mounting';
            app.NoneButton_Aft = uiradiobutton(app.SolarPanelMountingButtonGroup_Aft);
            app.NoneButton_Aft.Text = 'None';
            app.FaceFixedButton_Aft = uiradiobutton(app.SolarPanelMountingButtonGroup_Aft);
            app.FaceFixedButton_Aft.Text = 'Body-Fixed';
            app.FaceFixedButton_Aft.Value = true;
            app.HeaterPropertiesPanel_Aft = uipanel(app.AftTab);
            app.HeaterPropertiesPanel_Aft.Title = 'Heater Properties';
            app.GridLayout3_Aft = uigridlayout(app.HeaterPropertiesPanel_Aft);
            app.TemperatureThresholdHeaterOFFEditField_Aft = uieditfield(app.GridLayout3_Aft, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_Aft.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_Aft.Value = 10;
            app.BangBangHeaterControlSwitchONLabel_4 = uilabel(app.GridLayout3_Aft);
            app.BangBangHeaterControlSwitchONLabel_4.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel_4 = uilabel(app.GridLayout3_Aft);
            app.BangBangHeaterControlSwitchOFFLabel_4.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_Aft = uieditfield(app.GridLayout3_Aft, 'numeric');
            app.TemperatureThresholdHeaterONEditField_Aft.Limits = [-273.15 Inf];
            app.CLabel_8 = uilabel(app.GridLayout3_Aft);
            app.CLabel_8.Text = '°C';
            app.TempThresholdCLabel_4 = uilabel(app.GridLayout3_Aft);
            app.TempThresholdCLabel_4.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel_4 = uilabel(app.GridLayout3_Aft);
            app.BangBangHeaterControlSwitchLabel_4.Text = 'Bang-Bang Control';
            app.WLabel_8 = uilabel(app.GridLayout3_Aft);
            app.WLabel_8.Text = 'W';
            app.HeaterPowerEditFieldLabel_4 = uilabel(app.GridLayout3_Aft);
            app.HeaterPowerEditFieldLabel_4.Text = 'Heater Power';
            app.HeaterPowerEditField_Aft = uieditfield(app.GridLayout3_Aft, 'numeric');
            app.HeaterPowerEditField_Aft.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_Aft = uiswitch(app.GridLayout3_Aft, 'slider');
            app.BangBangHeaterControlSwitch_Aft.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_Aft = uipanel(app.AftTab);
            app.EffectiveSurfacePropertiesPanel_Aft.Title = 'Effective Surface Properties';
            app.GridLayout7_4 = uigridlayout(app.EffectiveSurfacePropertiesPanel_Aft);
            app.EffectiveEmissivityLabel_17 = uilabel(app.GridLayout7_4);
            app.EffectiveEmissivityLabel_17.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_Aft = uieditfield(app.GridLayout7_4, 'numeric');
            app.EmissivityEffectiveHotEditField_Aft.Limits = [0 1];
            app.HotLabel_17 = uilabel(app.GridLayout7_4);
            app.HotLabel_17.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_Aft = uieditfield(app.GridLayout7_4, 'numeric');
            app.EmissivityEffectiveColdEditField_Aft.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_Aft = uieditfield(app.GridLayout7_4, 'numeric');
            app.AbsorptivityEffectiveColdEditField_Aft.Limits = [0 1];
            app.ColdLabel_18 = uilabel(app.GridLayout7_4);
            app.ColdLabel_18.Text = 'Cold';
            app.RatioEffectiveColdEditField_Aft = uieditfield(app.GridLayout7_4, 'text');
            app.RatioEffectiveColdEditField_Aft.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel_14 = uilabel(app.GridLayout7_4);
            app.EffectiveAbsorptivityLabel_14.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_Aft = uieditfield(app.GridLayout7_4, 'numeric');
            app.AbsorptivityEffectiveHotEditField_Aft.Limits = [0 1];
            app.RatioEditFieldLabel_14 = uilabel(app.GridLayout7_4);
            app.RatioEditFieldLabel_14.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_Aft = uieditfield(app.GridLayout7_4, 'text');
            app.RatioEffectiveHotEditField_Aft.CharacterLimits = [0 10];
            app.NorthTab = uitab(app.TabGroup2);
            app.NorthTab.Title = 'North';
            app.SatelliteFacePanel_North = uipanel(app.NorthTab);
            app.SatelliteFacePanel_North.Title = 'Satellite Face';
            app.GridLayout_North = uigridlayout(app.SatelliteFacePanel_North);
            app.InitialTemperatureColdCEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.InitialTemperatureColdCEditField_North.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_North.Value = 20;
            app.HotLabel_7 = uilabel(app.GridLayout_North);
            app.HotLabel_7.Text = 'Hot';
            app.ColdLabel_7 = uilabel(app.GridLayout_North);
            app.ColdLabel_7.Text = 'Cold';
            app.SunlightLabel_5 = uilabel(app.GridLayout_North);
            app.SunlightLabel_5.Text = 'Sunlight';
            app.ShadowLabel_6 = uilabel(app.GridLayout_North);
            app.ShadowLabel_6.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.InternalHeatLoadShadowWEditField_North.Limits = [0 Inf];

            app.kgLabel_7 = uilabel(app.GridLayout_North);
            app.kgLabel_7.Text = 'kg';
            app.m2Label_9 = uilabel(app.GridLayout_North);
            app.m2Label_9.Text = 'm2';
            app.JkgCLabel_5 = uilabel(app.GridLayout_North);
            app.JkgCLabel_5.Text = 'J/kg/°C';
            app.CLabel_9 = uilabel(app.GridLayout_North);
            app.CLabel_9.Text = '°C';
            app.WLabel_9 = uilabel(app.GridLayout_North);
            app.WLabel_9.Text = 'W';
            app.MassLabel_5 = uilabel(app.GridLayout_North);
            app.MassLabel_5.Text = 'Mass';
            app.FaceMasskgEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.FaceMasskgEditField_North.Limits = [1e-16 Inf];
            app.FaceMasskgEditField_North.Value = 0.25;
            app.AreaEditFieldLabel_5 = uilabel(app.GridLayout_North);
            app.AreaEditFieldLabel_5.Text = 'Area';
            app.Aream2EditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.Aream2EditField_North.Limits = [1e-06 Inf];
            app.Aream2EditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_North.Value = 0.01;
            app.SpecificHeatEditFieldLabel_5 = uilabel(app.GridLayout_North);
            app.SpecificHeatEditFieldLabel_5.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.SpecificHeatJpkgKEditField_North.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_North.Value = 896;
            app.EmissivityEditFieldLabel_5 = uilabel(app.GridLayout_North);
            app.EmissivityEditFieldLabel_5.Text = 'Emissivity';
            app.EmissivityEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.EmissivityEditField_North.Limits = [0 1];
            app.EmissivityEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_North.Value = 1;
            app.InitialTemperatureLabel_5 = uilabel(app.GridLayout_North);
            app.InitialTemperatureLabel_5.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.InitialTemperatureHotCEditField_North.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_North.Value = 20;
            app.InternalHeatLoadWLabel_5 = uilabel(app.GridLayout_North);
            app.InternalHeatLoadWLabel_5.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.InternalHeatLoadSunlightWEditField_North.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_North.Value = 0.25;
            app.AbsorptivityEditFieldLabel_5 = uilabel(app.GridLayout_North);
            app.AbsorptivityEditFieldLabel_5.Text = 'Absorptivity';
            app.AbsorptivityEditField_North = uieditfield(app.GridLayout_North, 'numeric');
            app.AbsorptivityEditField_North.Limits = [0 1];
            app.AbsorptivityEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_North.Value = 1;
            app.SolarPanel_North = uipanel(app.NorthTab);
            app.SolarPanel_North.Title = 'Solar Panel';
            app.GridLayout2_North = uigridlayout(app.SolarPanel_North);
            app.Label_5 = uilabel(app.GridLayout2_North);
            app.Label_5.Text = '%';
            app.PanelEfficiencyLabel_4 = uilabel(app.GridLayout2_North);
            app.PanelEfficiencyLabel_4.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_North = uieditfield(app.GridLayout2_North, 'numeric');
            app.SolarPanelEfficiencyEditField_North.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_North.Value = 0.3;
            app.AbsorptivityEditField_2Label_5 = uilabel(app.GridLayout2_North);
            app.AbsorptivityEditField_2Label_5.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_North = uieditfield(app.GridLayout2_North, 'numeric');
            app.SolarPanelAbsorptivityEditField_North.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_North.Value = 0.902;
            app.EmissivityEditField_2Label_5 = uilabel(app.GridLayout2_North);
            app.EmissivityEditField_2Label_5.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_North = uieditfield(app.GridLayout2_North, 'numeric');
            app.SolarPanelEmissivityEditField_North.Limits = [0 1];
            app.SolarPanelEmissivityEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_North.Value = 0.908;
            app.AreaLabel_5 = uilabel(app.GridLayout2_North);
            app.AreaLabel_5.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_North = uieditfield(app.GridLayout2_North, 'numeric');
            app.SolarPanelCoveragePercentEditField_North.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_North.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_North.Value = 90;
            app.SolarPanelMountingButtonGroup_North = uibuttongroup(app.GridLayout2_North);
            app.SolarPanelMountingButtonGroup_North.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_North.Title = 'Mounting';
            app.NoneButton_North = uiradiobutton(app.SolarPanelMountingButtonGroup_North);
            app.NoneButton_North.Text = 'None';
            app.FaceFixedButton_North = uiradiobutton(app.SolarPanelMountingButtonGroup_North);
            app.FaceFixedButton_North.Text = 'Body-Fixed';
            app.FaceFixedButton_North.Value = true;
            app.HeaterPropertiesPanel_North = uipanel(app.NorthTab);
            app.HeaterPropertiesPanel_North.Title = 'Heater Properties';
            app.GridLayout3_North = uigridlayout(app.HeaterPropertiesPanel_North);
            app.TemperatureThresholdHeaterOFFEditField_North = uieditfield(app.GridLayout3_North, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_North.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_North.Value = 10;
            app.BangBangHeaterControlSwitchONLabel_5 = uilabel(app.GridLayout3_North);
            app.BangBangHeaterControlSwitchONLabel_5.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel_5 = uilabel(app.GridLayout3_North);
            app.BangBangHeaterControlSwitchOFFLabel_5.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_North = uieditfield(app.GridLayout3_North, 'numeric');
            app.TemperatureThresholdHeaterONEditField_North.Limits = [-273.15 Inf];
            app.CLabel_10 = uilabel(app.GridLayout3_North);
            app.CLabel_10.Text = '°C';
            app.TempThresholdCLabel_5 = uilabel(app.GridLayout3_North);
            app.TempThresholdCLabel_5.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel_5 = uilabel(app.GridLayout3_North);
            app.BangBangHeaterControlSwitchLabel_5.Text = 'Bang-Bang Control';
            app.WLabel_10 = uilabel(app.GridLayout3_North);
            app.WLabel_10.Text = 'W';
            app.HeaterPowerEditFieldLabel_5 = uilabel(app.GridLayout3_North);
            app.HeaterPowerEditFieldLabel_5.Text = 'Heater Power';
            app.HeaterPowerEditField_North = uieditfield(app.GridLayout3_North, 'numeric');
            app.HeaterPowerEditField_North.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_North = uiswitch(app.GridLayout3_North, 'slider');
            app.BangBangHeaterControlSwitch_North.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_North = uipanel(app.NorthTab);
            app.EffectiveSurfacePropertiesPanel_North.Title = 'Effective Surface Properties';
            app.GridLayout7_5 = uigridlayout(app.EffectiveSurfacePropertiesPanel_North);
            app.EffectiveEmissivityLabel_18 = uilabel(app.GridLayout7_5);
            app.EffectiveEmissivityLabel_18.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_North = uieditfield(app.GridLayout7_5, 'numeric');
            app.EmissivityEffectiveHotEditField_North.Limits = [0 1];
            app.HotLabel_18 = uilabel(app.GridLayout7_5);
            app.HotLabel_18.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_North = uieditfield(app.GridLayout7_5, 'numeric');
            app.EmissivityEffectiveColdEditField_North.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_North = uieditfield(app.GridLayout7_5, 'numeric');
            app.AbsorptivityEffectiveColdEditField_North.Limits = [0 1];
            app.ColdLabel_19 = uilabel(app.GridLayout7_5);
            app.ColdLabel_19.Text = 'Cold';
            app.RatioEffectiveColdEditField_North = uieditfield(app.GridLayout7_5, 'text');
            app.RatioEffectiveColdEditField_North.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel_15 = uilabel(app.GridLayout7_5);
            app.EffectiveAbsorptivityLabel_15.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_North = uieditfield(app.GridLayout7_5, 'numeric');
            app.AbsorptivityEffectiveHotEditField_North.Limits = [0 1];
            app.RatioEditFieldLabel_15 = uilabel(app.GridLayout7_5);
            app.RatioEditFieldLabel_15.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_North = uieditfield(app.GridLayout7_5, 'text');
            app.RatioEffectiveHotEditField_North.CharacterLimits = [0 10];
            app.SouthTab = uitab(app.TabGroup2);
            app.SouthTab.Title = 'South';
            app.SatelliteFacePanel_South = uipanel(app.SouthTab);
            app.SatelliteFacePanel_South.Title = 'Satellite Face';
            app.GridLayout_South = uigridlayout(app.SatelliteFacePanel_South);
            app.InitialTemperatureColdCEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.InitialTemperatureColdCEditField_South.Limits = [-273.15 Inf];
            app.InitialTemperatureColdCEditField_South.Value = 20;
            app.HotLabel_8 = uilabel(app.GridLayout_South);
            app.HotLabel_8.Text = 'Hot';
            app.ColdLabel_8 = uilabel(app.GridLayout_South);
            app.ColdLabel_8.Text = 'Cold';
            app.SunlightLabel_6 = uilabel(app.GridLayout_South);
            app.SunlightLabel_6.Text = 'Sunlight';
            app.ShadowLabel_5 = uilabel(app.GridLayout_South);
            app.ShadowLabel_5.Text = 'Shadow';
            app.InternalHeatLoadShadowWEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.InternalHeatLoadShadowWEditField_South.Limits = [0 Inf];
            app.kgLabel_8 = uilabel(app.GridLayout_South);
            app.kgLabel_8.Text = 'kg';
            app.m2Label_11 = uilabel(app.GridLayout_South);
            app.m2Label_11.Text = 'm2';
            app.JkgCLabel_6 = uilabel(app.GridLayout_South);
            app.JkgCLabel_6.Text = 'J/kg/°C';
            app.CLabel_11 = uilabel(app.GridLayout_South);
            app.CLabel_11.Text = '°C';
            app.WLabel_11 = uilabel(app.GridLayout_South);
            app.WLabel_11.Text = 'W';
            app.MassLabel_6 = uilabel(app.GridLayout_South);
            app.MassLabel_6.Text = 'Mass';
            app.FaceMasskgEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.FaceMasskgEditField_South.Limits = [0.001 Inf];
            app.FaceMasskgEditField_South.Value = 0.25;
            app.AreaEditFieldLabel_6 = uilabel(app.GridLayout_South);
            app.AreaEditFieldLabel_6.Text = 'Area';
            app.Aream2EditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.Aream2EditField_South.Limits = [1e-06 Inf];
            app.Aream2EditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.Aream2EditField_South.Value = 0.01;
            app.SpecificHeatEditFieldLabel_6 = uilabel(app.GridLayout_South);
            app.SpecificHeatEditFieldLabel_6.Text = 'Specific Heat';
            app.SpecificHeatJpkgKEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.SpecificHeatJpkgKEditField_South.Limits = [0.01 Inf];
            app.SpecificHeatJpkgKEditField_South.Value = 896;
            app.EmissivityEditFieldLabel_6 = uilabel(app.GridLayout_South);
            app.EmissivityEditFieldLabel_6.Text = 'Emissivity';
            app.EmissivityEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.EmissivityEditField_South.Limits = [0 1];
            app.EmissivityEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.EmissivityEditField_South.Value = 1;
            app.InitialTemperatureLabel_6 = uilabel(app.GridLayout_South);
            app.InitialTemperatureLabel_6.Text = 'Initial Temperature';
            app.InitialTemperatureHotCEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.InitialTemperatureHotCEditField_South.Limits = [-273.15 Inf];
            app.InitialTemperatureHotCEditField_South.Value = 20;
            app.InternalHeatLoadWLabel_6 = uilabel(app.GridLayout_South);
            app.InternalHeatLoadWLabel_6.Text = 'Internal Heat Load';
            app.InternalHeatLoadSunlightWEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.InternalHeatLoadSunlightWEditField_South.Limits = [0 Inf];
            app.InternalHeatLoadSunlightWEditField_South.Value = 0.25;
            app.AbsorptivityEditFieldLabel_6 = uilabel(app.GridLayout_South);
            app.AbsorptivityEditFieldLabel_6.Text = 'Absorptivity';
            app.AbsorptivityEditField_South = uieditfield(app.GridLayout_South, 'numeric');
            app.AbsorptivityEditField_South.Limits = [0 1];
            app.AbsorptivityEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.AbsorptivityEditField_South.Value = 1;
            app.SolarPanel_South = uipanel(app.SouthTab);
            app.SolarPanel_South.Title = 'Solar Panel';
            app.GridLayout2_South = uigridlayout(app.SolarPanel_South);
            app.Label_6 = uilabel(app.GridLayout2_South);
            app.Label_6.Text = '%';
            app.PanelEfficiencyLabel_5 = uilabel(app.GridLayout2_South);
            app.PanelEfficiencyLabel_5.Text = 'Panel Efficiency';
            app.SolarPanelEfficiencyEditField_South = uieditfield(app.GridLayout2_South, 'numeric');
            app.SolarPanelEfficiencyEditField_South.Limits = [0 1];
            app.SolarPanelEfficiencyEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEfficiencyEditField_South.Value = 0.3;
            app.AbsorptivityEditField_2Label_6 = uilabel(app.GridLayout2_South);
            app.AbsorptivityEditField_2Label_6.Text = 'Absorptivity';
            app.SolarPanelAbsorptivityEditField_South = uieditfield(app.GridLayout2_South, 'numeric');
            app.SolarPanelAbsorptivityEditField_South.Limits = [0 1];
            app.SolarPanelAbsorptivityEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelAbsorptivityEditField_South.Value = 0.902;
            app.EmissivityEditField_2Label_6 = uilabel(app.GridLayout2_South);
            app.EmissivityEditField_2Label_6.Text = 'Emissivity';
            app.SolarPanelEmissivityEditField_South = uieditfield(app.GridLayout2_South, 'numeric');
            app.SolarPanelEmissivityEditField_South.Limits = [0 1];
            app.SolarPanelEmissivityEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelEmissivityEditField_South.Value = 0.908;
            app.AreaLabel_6 = uilabel(app.GridLayout2_South);
            app.AreaLabel_6.Text = 'Face Coverage';
            app.SolarPanelCoveragePercentEditField_South = uieditfield(app.GridLayout2_South, 'numeric');
            app.SolarPanelCoveragePercentEditField_South.Limits = [0 100];
            app.SolarPanelCoveragePercentEditField_South.ValueChangedFcn = createCallbackFcn(app, @SurfacePropsValueChanged, true);
            app.SolarPanelCoveragePercentEditField_South.Value = 90;
            app.SolarPanelMountingButtonGroup_South = uibuttongroup(app.GridLayout2_South);
            app.SolarPanelMountingButtonGroup_South.SelectionChangedFcn = createCallbackFcn(app, @SolarPanelMountingButtonGroupSelectionChanged, true);
            app.SolarPanelMountingButtonGroup_South.Title = 'Mounting';
            app.NoneButton_South = uiradiobutton(app.SolarPanelMountingButtonGroup_South);
            app.NoneButton_South.Text = 'None';
            app.FaceFixedButton_South = uiradiobutton(app.SolarPanelMountingButtonGroup_South);
            app.FaceFixedButton_South.Text = 'Body-Fixed';
            app.FaceFixedButton_South.Value = true;
            app.HeaterPropertiesPanel_South = uipanel(app.SouthTab);
            app.HeaterPropertiesPanel_South.Title = 'Heater Properties';
            app.GridLayout3_South = uigridlayout(app.HeaterPropertiesPanel_South);
            app.TemperatureThresholdHeaterOFFEditField_South = uieditfield(app.GridLayout3_South, 'numeric');
            app.TemperatureThresholdHeaterOFFEditField_South.Limits = [-273.15 Inf];
            app.TemperatureThresholdHeaterOFFEditField_South.Value = 10;
            app.BangBangHeaterControlSwitchONLabel_6 = uilabel(app.GridLayout3_South);
            app.BangBangHeaterControlSwitchONLabel_6.Text = 'Off Trigger';
            app.BangBangHeaterControlSwitchOFFLabel_6 = uilabel(app.GridLayout3_South);
            app.BangBangHeaterControlSwitchOFFLabel_6.Text = 'On Trigger';
            app.TemperatureThresholdHeaterONEditField_South = uieditfield(app.GridLayout3_South, 'numeric');
            app.TemperatureThresholdHeaterONEditField_South.Limits = [-273.15 Inf];
            app.CLabel_12 = uilabel(app.GridLayout3_South);
            app.CLabel_12.Text = '°C';
            app.TempThresholdCLabel_6 = uilabel(app.GridLayout3_South);
            app.TempThresholdCLabel_6.Text = 'Temperature';
            app.BangBangHeaterControlSwitchLabel_6 = uilabel(app.GridLayout3_South);
            app.BangBangHeaterControlSwitchLabel_6.Text = 'Bang-Bang Control';
            app.WLabel_12 = uilabel(app.GridLayout3_South);
            app.WLabel_12.Text = 'W';
            app.HeaterPowerEditFieldLabel_6 = uilabel(app.GridLayout3_South);
            app.HeaterPowerEditFieldLabel_6.Text = 'Heater Power';
            app.HeaterPowerEditField_South = uieditfield(app.GridLayout3_South, 'numeric');
            app.HeaterPowerEditField_South.Limits = [0 Inf];
            app.BangBangHeaterControlSwitch_South = uiswitch(app.GridLayout3_South, 'slider');
            app.BangBangHeaterControlSwitch_South.ValueChangedFcn = createCallbackFcn(app, @BangBangHeaterControlSwitchValueChanged, true);
            app.EffectiveSurfacePropertiesPanel_South = uipanel(app.SouthTab);
            app.EffectiveSurfacePropertiesPanel_South.Title = 'Effective Surface Properties';
            app.GridLayout7_6 = uigridlayout(app.EffectiveSurfacePropertiesPanel_South);
            app.EffectiveEmissivityLabel_19 = uilabel(app.GridLayout7_6);
            app.EffectiveEmissivityLabel_19.Text = 'Emissivity (ε)';
            app.EmissivityEffectiveHotEditField_South = uieditfield(app.GridLayout7_6, 'numeric');
            app.EmissivityEffectiveHotEditField_South.Limits = [0 1];
            app.HotLabel_19 = uilabel(app.GridLayout7_6);
            app.HotLabel_19.Text = 'Hot';
            app.EmissivityEffectiveColdEditField_South = uieditfield(app.GridLayout7_6, 'numeric');
            app.EmissivityEffectiveColdEditField_South.Limits = [0 1];
            app.AbsorptivityEffectiveColdEditField_South = uieditfield(app.GridLayout7_6, 'numeric');
            app.AbsorptivityEffectiveColdEditField_South.Limits = [0 1];
            app.ColdLabel_20 = uilabel(app.GridLayout7_6);
            app.ColdLabel_20.Text = 'Cold';
            app.RatioEffectiveColdEditField_South = uieditfield(app.GridLayout7_6, 'text');
            app.RatioEffectiveColdEditField_South.CharacterLimits = [0 10];
            app.EffectiveAbsorptivityLabel_16 = uilabel(app.GridLayout7_6);
            app.EffectiveAbsorptivityLabel_16.Text = 'Absorptivity (α)';
            app.AbsorptivityEffectiveHotEditField_South = uieditfield(app.GridLayout7_6, 'numeric');
            app.AbsorptivityEffectiveHotEditField_South.Limits = [0 1];
            app.RatioEditFieldLabel_16 = uilabel(app.GridLayout7_6);
            app.RatioEditFieldLabel_16.Text = 'Ratio α/ε';
            app.RatioEffectiveHotEditField_South = uieditfield(app.GridLayout7_6, 'text');
            app.RatioEffectiveHotEditField_South.CharacterLimits = [0 10];
            app.CopyPropertiesfromZenithFacetoRemainingFacesButton = uibutton(app.GridLayout5, 'push');
            app.CopyPropertiesfromZenithFacetoRemainingFacesButton.ButtonPushedFcn = createCallbackFcn(app, @CopyPropertiesfromZenithFacetoRemainingFacesButtonPushed, true);
            app.CopyPropertiesfromZenithFacetoRemainingFacesButton.Text = 'Copy Properties from Zenith Face to Remaining Faces';
            app.OffDiagonalsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.OffDiagonalsEditField.Limits = [0 Inf];
            app.OffDiagonalsEditField.ValueChangedFcn = createCallbackFcn(app, @FillMatrixOffDiagonals, true);
            app.OffDiagonalsEditField.Value = 0.12;
            app.FillMatrixOffDiagonalsLabel = uilabel(app.GridLayout5);
            app.FillMatrixOffDiagonalsLabel.Text = 'Fill Matrix Off-Diagonals';
            app.HeatFluxesTab = uitab(app.TabGroup);
            app.HeatFluxesTab.Title = 'Heat Fluxes';
            app.GridLayout = uigridlayout(app.HeatFluxesTab);
            app.PlotPanel_TotalHeatFlux_Specific = uipanel(app.GridLayout);
            app.PlotPanel_TotalHeatFlux_Specific.Title = 'Total Heat Flux (Specific)';
            app.PlotPanel_IndividualHeatFlux_Specific = uipanel(app.GridLayout);
            app.PlotPanel_IndividualHeatFlux_Specific.Title = 'Indiviidual Heat Flux (Specific)';
            app.PlotPanel_TotalHeatFlux_Generic = uipanel(app.GridLayout);
            app.PlotPanel_TotalHeatFlux_Generic.Title = 'Total Heat Flux Generic';
            app.TemperaturesTab = uitab(app.TabGroup);
            app.TemperaturesTab.Title = 'Temperatures';
            app.GridLayout8 = uigridlayout(app.TemperaturesTab);
            app.PlotPanel_Temperatures = uipanel(app.GridLayout8);
            app.PlotPanel_Temperatures.Title = 'Temps';
            app.MiniumMaximumSatelliteFaceTemperautresCPanel = uipanel(app.GridLayout8);
            app.MiniumMaximumSatelliteFaceTemperautresCPanel.Title = 'Minium / Maximum Satellite Face Temperautres (°C)';
            app.Table_MinMaxTemp = uitable(app.MiniumMaximumSatelliteFaceTemperautresCPanel);
            app.Table_MinMaxTemp.ColumnName = '';
            app.Table_MinMaxTemp.RowName = {};
            app.Table_MinMaxTemp.ColumnEditable = [true true true true true true true];
            app.SolarPowerTab = uitab(app.TabGroup);
            app.SolarPowerTab.Title = 'Solar Power';
            app.GridLayout4 = uigridlayout(app.SolarPowerTab);
            app.Plot_SolarPanelPower_Specific = uiaxes(app.GridLayout4);
            ylabel(app.Plot_SolarPanelPower_Specific, 'Y')
            zlabel(app.Plot_SolarPanelPower_Specific, 'Z')
            app.PlotPanel_SolarPanelPower_Generic = uipanel(app.GridLayout4);
            app.PlotPanel_SolarPanelPower_Generic.Title = 'Solar Panel Power';
            app.RunSATMOButton = uibutton(app.UIFigure, 'push');
            app.RunSATMOButton.ButtonPushedFcn = createCallbackFcn(app, @RunSATMOButtonValueChanged, true);
            app.RunSATMOButton.Text = 'Run SATMO';
            app.Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SATMO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end